% description: GrassLight 3.1 is copyright © 2024 by Richard C. Zimmerman. All rights reserved worldwide.
classdef GL3_11_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GrassLight_3_11                 matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        LoadNewSetupFileMenu            matlab.ui.container.Menu
        SaveNewSetupFileMenu            matlab.ui.container.Menu
        AboutGrassLightMenu             matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        RunMenu                         matlab.ui.container.Menu
        OptimizeDensityMenu             matlab.ui.container.Menu
        TimeSeriesMenu                  matlab.ui.container.Menu
        PlotMenu                        matlab.ui.container.Menu
        SurfaceIrradianceandReflectanceSpectraMenu  matlab.ui.container.Menu
        SubmarineIrradianceandReflectanceSpectraMenu  matlab.ui.container.Menu
        WaterColumnOpticalPropertiesMenu  matlab.ui.container.Menu
        LeafOpticalPropertiesMenu       matlab.ui.container.Menu
        SubstrateReflectanceSpectrumMenu  matlab.ui.container.Menu
        VerticalBiomassDistributionMenu  matlab.ui.container.Menu
        CanopyProductionPlotsMenu       matlab.ui.container.Menu
        CIEColorSimulationsofCanopyRandRrsMenu  matlab.ui.container.Menu
        ClosePlotsMenu                  matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        SiteDataPanel                   matlab.ui.container.Panel
        LatitudedecEditField            matlab.ui.control.NumericEditField
        LongitudedecEditField           matlab.ui.control.NumericEditField
        DateDatePicker                  matlab.ui.control.DatePicker
        TimeofDay0to24hEditField        matlab.ui.control.NumericEditField
        DepthmEditField                 matlab.ui.control.NumericEditField
        CurrentSpeedcmsEditField        matlab.ui.control.NumericEditField
        SubstrateTypeDropDown           matlab.ui.control.DropDown
        Label_4                         matlab.ui.control.Label
        DateDatePickerLabel             matlab.ui.control.Label
        SubstrateTypeDropDownLabel      matlab.ui.control.Label
        CurrentSpeedcmsEditFieldLabel   matlab.ui.control.Label
        DepthmEditFieldLabel            matlab.ui.control.Label
        TimeofDay0to24hEditFieldLabel   matlab.ui.control.Label
        LongitudedecEditFieldLabel      matlab.ui.control.Label
        LatitudedecLabel                matlab.ui.control.Label
        PlantProductivityandMetabolismPanel  matlab.ui.container.Panel
        Label_49                        matlab.ui.control.Label
        mLabel_3                        matlab.ui.control.Label
        Label_48                        matlab.ui.control.Label
        PumolCmleafsEditField_2         matlab.ui.control.NumericEditField
        PumolCmleafsEditField_2Label    matlab.ui.control.Label
        mLabel_2                        matlab.ui.control.Label
        ELabel_3                        matlab.ui.control.Label
        PPEditField                     matlab.ui.control.NumericEditField
        PPEditFieldLabel                matlab.ui.control.Label
        Label_47                        matlab.ui.control.Label
        satLabel                        matlab.ui.control.Label
        Label_46                        matlab.ui.control.Label
        DailyHRequirementhdEditField    matlab.ui.control.NumericEditField
        DailyHRequirementhdEditFieldLabel  matlab.ui.control.Label
        CanopyAnglefromverticalEditField  matlab.ui.control.NumericEditField
        CanopyAnglefromverticalEditFieldLabel  matlab.ui.control.Label
        DailyPhotoperiodhEditField      matlab.ui.control.NumericEditField
        DailyPhotoperiodhEditFieldLabel  matlab.ui.control.Label
        Label_45                        matlab.ui.control.Label
        Label_43                        matlab.ui.control.Label
        Label_42                        matlab.ui.control.Label
        PumolCmleafsEditField           matlab.ui.control.NumericEditField
        PumolCmleafsLabel               matlab.ui.control.Label
        InstantaneousPumolCmleafsEditField  matlab.ui.control.NumericEditField
        InstantaneousPumolCmleafsEditFieldLabel  matlab.ui.control.Label
        InstantaneousCanopyPPEditFieldLabel  matlab.ui.control.Label
        InstantaneousPumolCshootsEditField  matlab.ui.control.NumericEditField
        InstantaneousPumolCshootsLabel  matlab.ui.control.Label
        InstantaneousCanopyPPEditField  matlab.ui.control.NumericEditField
        InstantaneousLeafPREditField    matlab.ui.control.NumericEditField
        InstantaneousWholePlantPREditField  matlab.ui.control.NumericEditField
        DailyWholePlantPREditField      matlab.ui.control.NumericEditField
        delCEditField                   matlab.ui.control.NumericEditField
        SaveResultsofThisRunButton      matlab.ui.control.Button
        ELabel_2                        matlab.ui.control.Label
        InstantaneousLeafPREditFieldLabel  matlab.ui.control.Label
        Label_37                        matlab.ui.control.Label
        Label_34                        matlab.ui.control.Label
        Label_33                        matlab.ui.control.Label
        ELabel                          matlab.ui.control.Label
        delCEditFieldLabel              matlab.ui.control.Label
        DailyWholePlantPREditField_2Label  matlab.ui.control.Label
        InstantaneousWholePlantPREditField_2Label  matlab.ui.control.Label
        InitialWaterChemistryPanel      matlab.ui.container.Panel
        CO2SYSInputPrametersDropDown    matlab.ui.control.DropDown
        CO2SYSInputPrametersDropDownLabel  matlab.ui.control.Label
        BorateSalinityDropDownLabel     matlab.ui.control.Label
        KHFDissociationConstantDropDownLabel  matlab.ui.control.Label
        Label_32                        matlab.ui.control.Label
        KSODissociationConstantDropDownLabel  matlab.ui.control.Label
        K1K2ConstantsDropDownLabel      matlab.ui.control.Label
        pCOAirSatatDepthEditFieldLabel  matlab.ui.control.Label
        pCOAirSatatSfcEditFieldLabel    matlab.ui.control.Label
        pCOinAirAtmEditFieldLabel       matlab.ui.control.Label
        OAirSatatDepthEditFieldLabel    matlab.ui.control.Label
        TemperatureCEditField           matlab.ui.control.NumericEditField
        SalinitypptorPSS78EditField     matlab.ui.control.NumericEditField
        pHEditField                     matlab.ui.control.NumericEditField
        pHScaleDropDown                 matlab.ui.control.DropDown
        K1K2ConstantsDropDown           matlab.ui.control.DropDown
        KSODissociationConstantDropDown  matlab.ui.control.DropDown
        KHFDissociationConstantDropDown  matlab.ui.control.DropDown
        BorateSalinityDropDown          matlab.ui.control.DropDown
        AllconcentrationsmolKgSWunlessotherwisespecifiedLabel  matlab.ui.control.Label
        AlkalinityEditField             matlab.ui.control.NumericEditField
        OEditField                      matlab.ui.control.NumericEditField
        OAirSatatSfcEditField           matlab.ui.control.NumericEditField
        OAirSatatDepthEditField         matlab.ui.control.NumericEditField
        SiOEditField                    matlab.ui.control.NumericEditField
        POEditField                     matlab.ui.control.NumericEditField
        NHEditField                     matlab.ui.control.NumericEditField
        NOEditField                     matlab.ui.control.NumericEditField
        HSEditField                     matlab.ui.control.NumericEditField
        pCOinAirAtmEditField            matlab.ui.control.NumericEditField
        pCOinWaterAtmEditField          matlab.ui.control.NumericEditField
        pCOAirSatatSfcEditField         matlab.ui.control.NumericEditField
        pCOAirSatatDepthEditField       matlab.ui.control.NumericEditField
        COEditField                     matlab.ui.control.NumericEditField
        COEditFieldLabel                matlab.ui.control.Label
        HCOEditField                    matlab.ui.control.NumericEditField
        COEditField_2                   matlab.ui.control.NumericEditField
        TotalDICEditField               matlab.ui.control.NumericEditField
        pHEditFieldLabel                matlab.ui.control.Label
        Label_31                        matlab.ui.control.Label
        Label_30                        matlab.ui.control.Label
        Label_29                        matlab.ui.control.Label
        Label_28                        matlab.ui.control.Label
        Label_27                        matlab.ui.control.Label
        Label_26                        matlab.ui.control.Label
        Label_24                        matlab.ui.control.Label
        Label_23                        matlab.ui.control.Label
        NHEditFieldLabel                matlab.ui.control.Label
        NOEditFieldLabel                matlab.ui.control.Label
        Label_14                        matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        SiOEditFieldLabel               matlab.ui.control.Label
        Label_17                        matlab.ui.control.Label
        Label_22                        matlab.ui.control.Label
        HSMLabel                        matlab.ui.control.Label
        Label_21                        matlab.ui.control.Label
        Label_20                        matlab.ui.control.Label
        Label_19                        matlab.ui.control.Label
        Label_18                        matlab.ui.control.Label
        Label_16                        matlab.ui.control.Label
        Label_15                        matlab.ui.control.Label
        Label_13                        matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        TotalDICEditFieldLabel          matlab.ui.control.Label
        COMLabel                        matlab.ui.control.Label
        pHScaleDropDownLabel            matlab.ui.control.Label
        AlkalinityEditFieldLabel        matlab.ui.control.Label
        SalinitypptorPSS78Label         matlab.ui.control.Label
        HCOEditFieldLabel               matlab.ui.control.Label
        pCOinWaterAtmEditFieldLabel     matlab.ui.control.Label
        POEditFieldLabel                matlab.ui.control.Label
        OAirSatatSfcEditFieldLabel      matlab.ui.control.Label
        OEditFieldLabel                 matlab.ui.control.Label
        TemperatureCEditFieldLabel      matlab.ui.control.Label
        WaterColumnOpticalConstituentsPanel  matlab.ui.container.Panel
        Image                           matlab.ui.control.Image
        ChlamgmEditField                matlab.ui.control.NumericEditField
        TSMmgLEditField                 matlab.ui.control.NumericEditField
        a440EditField                   matlab.ui.control.NumericEditField
        gLabel                          matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        a440EditFieldLabel              matlab.ui.control.Label
        TSMmgLEditFieldLabel            matlab.ui.control.Label
        ChlamgmEditFieldLabel           matlab.ui.control.Label
        PlantMorphologyOpticalPropertiesPanel  matlab.ui.container.Panel
        PlantTypeDropDown               matlab.ui.control.DropDown
        PlantTypeDropDownLabel          matlab.ui.control.Label
        MaxHeightmEditField             matlab.ui.control.NumericEditField
        ShootLeafAreamLeafshootEditField  matlab.ui.control.NumericEditField
        ShootDensityshootspermEditField  matlab.ui.control.NumericEditField
        LAIEditField                    matlab.ui.control.NumericEditField
        ShootRhizomeRootEditField       matlab.ui.control.NumericEditField
        RhizRootEditField               matlab.ui.control.NumericEditField
        EpiphyteLoadmgcmEditField       matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        ShootDensitypermLabel           matlab.ui.control.Label
        ShootLeafAreamLeafshootLabel    matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        EpiphyteLoadmgcmEditFieldLabel  matlab.ui.control.Label
        RhizRootEditFieldLabel          matlab.ui.control.Label
        ShootRhizomeRootEditFieldLabel  matlab.ui.control.Label
        LAIEditFieldLabel               matlab.ui.control.Label
        MaxHeightmEditFieldLabel        matlab.ui.control.Label
    end

    
    properties (Access = public)
        % Data tables read from inupt files, generated by and shared among functions:

        % Input and output file names
        inpath                  % Path for incoming setup file (excel format)
        infile                  % Name of incoming setup file (excel format)
        info_file               % Name of incoming information file (pdf format)
        info_path               % Path for incoming infornation file (pdf format)
        outpath                 % Path for outgoing setup file (excel format)
        outfile                 % Name of outgoing setup file (excel format)
        new_setup_file          % Path and name for outgoing setup file (excel format)
        new_results_file        % Path and name for outgoing results file (excel format)

        % Header information and numerical data read from setup file
        IOP_Spectra_Header      % IOP Spectra Header information read from setup; used to write header info to new setup file 
        IOP_Spectra             % IOP spectra read from setup file; used to calculate light attenuation in the water column by EdEu_water_Ver3_for_app
        Site_Data_Header        % Site Header data information from setup; used to write header info to new setup file 
        Site_Data               % Site data read from setup file 
        Atm_Const_Header        % Atmospheric constants header information read from setup; used to write header info to new setup file 
        Atm_Const               % Atmospheric constants read from setup file and used by function E0_surface_Ver3_for_app
        Atm_IOPs_Header         % Atmospheric IOPs header information read from setup; used to write header info to new setup file
        Atm_IOPs                % Atmospheric IOPs read from setup file and used by function E0_surface_Ver3_for_app
        IOP_Constants_Header    % Water column IOP constants header information read from setup; used to write header info to new setup file
        IOP_Constants           % Water column IOP constants read from setup file and used by funcion abKd_Spectra to compute water column IOPs and AOPs
        WQ_Params_Header        % Water quality paramaters header information read from setup file; used to write header info to new setup file
        WQ_Params               % Various Water Quality Parameters from setup file
        Plant_Arch_Header       % Plant architecture header information 
        Plant_Arch              % Plant Architecture from setup file
        Photo_Params_Header     % Metabolic Paramaters header information read from setup file; used to write header info to new setup file
        Photo_Params            % Metabolic Rate Parameters from setup file
        Sed_Ref_Header          % Sediment reflectance header information read from setup file; used to write header info to new setup file
        Sed_Ref                 % Sediment reflectance from setup file
        LOPs_Header             % Leaf optical properties header information from setup file; used to write header info to new setup file
        LOPs                    % Leaf Optical Properties from setup file

        % Data calculated by and shared among GL3 functions      
        E0_sfc_spectra                      % Irradiance spectra at the sea surface calculated by function E0_surface_Ver3_for_app
        E0_sfc_PAR_mubar                    % Daylength and sun angle parameters calculated by function E0_surface_Ver3_for_app
        abKd_Spectra                        % IOP and AOP spectra calculated by function abKd_spectra_Ver_3_for_app
        Gas_Sat                             % Gas saturation values calculated by function Gas_Solubilty_Model
        CO2SYS_Data                         % CO2SYS data calculated by function CO2SYS_for_app
        pH_scale_numeric_value_for_CO2sys   %Converts pH scale from character to numeric data before sending to CO2SYS
        Biodis_Data                         % Biomass distribution data calculated by function Biodis
        EdEu_Data                           % In water irradiance data calculated by function EdEu_water_ver3_for_app
        Prod_Data                           % Plant production data calculated by function produce_V3_for_app
        CIE_Data                            % CIE data calculated by function CIE_color_plot for estimating ocean color plots for Rrs, Rsfc and Rcanopy

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.GrassLight_3_11, 'center')     % Center the GUI on startup

            % Load default setup file
            app.infile = 'GL3_Setup_Default.xlsx';
            [app.IOP_Spectra_Header, app.IOP_Spectra, app.Site_Data_Header, app.Site_Data, app.Atm_Const_Header, app.Atm_Const, app.Atm_IOPs_Header, app.Atm_IOPs, app.IOP_Constants_Header, app.IOP_Constants, app.WQ_Params_Header, app.WQ_Params, app.Plant_Arch_Header, app.Plant_Arch, app.Photo_Params_Header, app.Photo_Params, app.Sed_Ref_Header, app.Sed_Ref, app.LOPs_Header, app.LOPs] = read_GL3_input_data_for_app_302(app.inpath, app.infile);
            
            % Get initial gas saturation values
            app.Gas_Sat = Gas_Solubility_Model(app.WQ_Params.Temp, app.WQ_Params.Sal, app.Site_Data.depth, app.WQ_Params.pCO2_Air);

            % Populate Site Data Display

            app.LatitudedecEditField.Value = app.Site_Data.XLAT;
            app.LongitudedecEditField.Value = app.Site_Data.XLON;
            app.DateDatePicker.Value = datetime('1-Jan-2024')+app.Site_Data.JD-1;
            app.TimeofDay0to24hEditField.Value = app.Site_Data.HR;
            app.DepthmEditField.Value = app.Site_Data.depth;
            app.CurrentSpeedcmsEditField.Value = app.Site_Data.curvel;

            if app.Site_Data.sed_type == "Dark_Carbonate"
                app.SubstrateTypeDropDown.Value = "Dark Carbonate";
            elseif app.Site_Data.sed_type == "Bright_Carbonate"
                app.SubstrateTypeDropDown.Value = "Bright Carbonate";
            elseif app.Site_Data.sed_type == "Silica_Sand"
                app.SubstrateTypeDropDown.Value = "Silica Sand";
            else 
                app.SubstrateTypeDropDown.Value = "Silica Mud";
            end
            
            col = 1;
            if app.Site_Data.lop == "zostera"
                app.PlantTypeDropDown.Value = "Zostera";
                row = 1;
            elseif app.Site_Data.lop == "thalassia"
                app.PlantTypev.Value = "Thalassia";
                row = 2;
            elseif app.Site_Data.lop == "syringodium"
                app.PlantTypeDropDown.Value = "Syringodium";
                row = 3;
            elseif app.Site_Data.lop == "phyllospadix"
                app.PlantTypeDropDown.Value = "Phyllospadix";
                row = 4;
            elseif app.Site_Data.lop == "posidonia"
                app.PlantTypeDropDownValue = "Posidonia";
                row = 5;
            else 
                app.PlantTypeDropDown.Value = "Ruppia";
                row = 6;
            end

            % Calculate the vertical biomass distribution
            [app.Biodis_Data, app.Plant_Arch] = biodis_v301_for_app(app.Site_Data,app.Plant_Arch, app.PlantTypeDropDown);

            % Update the plant morphology parameters
            app.MaxHeightmEditField.Value = app.Plant_Arch.maxht(row, col);
            app.ShootLeafAreamLeafshootEditField.Value = app.Plant_Arch.areash(row,col);
            app.ShootDensityshootspermEditField.Value = app.Plant_Arch.density(row,col);
            app.LAIEditField.Value = app.Biodis_Data.laitot;                          % Get total LAI
            app.ShootRhizomeRootEditField.Value = app.Plant_Arch.shoot_frac(row,col)/(app.Plant_Arch.rhiz_frac(row,col)+app.Plant_Arch.root_frac(row,col));
            app.RhizRootEditField.Value = app.Plant_Arch.rhiz_frac(row,col)/app.Plant_Arch.root_frac(row,col);
            app.EpiphyteLoadmgcmEditField.Value = app.Plant_Arch.epi(row,col);

            % Populate Water Column Optical Constituents Display
            app.ChlamgmEditField.Value = app.WQ_Params.CHL_A;
            app.TSMmgLEditField.Value = app.WQ_Params.TSM;
            app.a440EditField.Value = app.WQ_Params.AG_440;

            % Populate Initial Water Chemistry Parameters

            %Select CO2SYS Input Parameters
            if (app.WQ_Params.PAR1TYPE == 1 && app.WQ_Params.PAR2TYPE == 2) || (app.WQ_Params.PAR1TYPE == 2 && app.WQ_Params.PAR2TYPE == 1)
                app.CO2SYSInputPrametersDropDown.Value = "Alk & Total DIC";
            elseif (app.WQ_Params.PAR1TYPE == 1 && app.WQ_Params.PAR2TYPE == 3) || (app.WQ_PAarams.PAR1TYPE == 3 && app.WQ_Params.PAR2TYPE == 1)
                app.CO2SYSInputPrametersDropDown.Value = "Alk & pH";
            elseif (app.WQ_Params.PAR1TYPE == 1 && app.WQ_Params.PAR2TYPE == 3) || (app.WQ_Params.PAR1TYPE == 4 && app.WQ_Params.PAR2TYPE == 1)
                app.CO2SYSInputPrametersDropDown.Value = "Alk & pCO2";
            elseif (app.WQ_Params.PAR1TYPE == 2 && app.WQ_Params.PAR2TYPE == 3) || (app.WQ_Params.PAR1TYPE == 3 && app.WQ_Params.PAR2TYPE == 2)
                app.CO2SYSInputPrametersDropDown.Value = "Total DIC & pH";
            else
                app.CO2SYSInputPrametersDropDown.Value = "Total DIC & pCO2";
            end

            % Populate temperature and salinity fields
            app.TemperatureCEditField.Value = app.WQ_Params.Temp;
            app.SalinitypptorPSS78EditField.Value = app.WQ_Params.Sal;

            % Populate pH value and pH scale fields
            app.pHEditField.Value = app.WQ_Params.pH;
            app.pHScaleDropDown.Value = app.WQ_Params.scale;
            if app.pHScaleDropDown.Value == "Total"
                app.pH_scale_numeric_value_for_CO2sys = 1;
            else 
                if app.pHScaleDropDown.Value == "Seawater"
                app.pH_scale_numeric_value_for_CO2sys = 2;
                else 
                    if app.pHScaleDropDown.Value == "Free"
                        app.pH_scale_numeric_value_for_CO2sys = 3;
                    else 
                        app.pH_scale_numeric_value_for_CO2sys = 4;
                    end
                end
            end
                    
            % K1 and K2 dissociation constants
            if app.WQ_Params.K1K2 == 1
                app.K1K2ConstantsDropDown.Value = "Roy 1993";
            elseif app.WQ_Params.K1K2 == 2
                app.K1K2ConstantsDropDown.Value = "Goyet & Poisson 1989";
            elseif app.WQ_Params.K1K2 == 3
                app.K1K2ConstantsDropDown.Value = "Hansson 1973 refit by Dickson & Millero 1987";
            elseif app.WQ_Params.K1K2 == 4
                app.K1K2ConstantsDropDown.Value = "Mehrbach et al. 1973 refit by Dickson & Millero 1987";
            elseif app.WQ_Params.K1K2 == 5
                app.K1K2ConstantsDropDown.Value = "Hansson & Mehrbach refit by Dickson & Millero 1987";
            elseif app.WQ_Params.K1K2 == 6
                app.K1K2ConstantsDropDown.Value = "GEOSECS NBS Constants, K1, K2 from Mehrbach et al. 1973";
            elseif app.WQ_Params.K1K2 == 7
                app.K1K2ConstantsDropDown.Value = "Peng et al. NBS constants; K1 K2 from Mehrbach et al. 1973";
            elseif app.WQ_Params.K1K2 == 8
                app.K1K2ConstantsDropDown.Value = "Freshwater (Sal = 0); K1 K2 from Millero 1979";
            elseif app.WQ_Params.K1K2 == 9
                app.K1K2ConstantsDropDown.Value = "Cai and Wang 1998";
            elseif app.WQ_Params.K1K2 == 10
                app.K1K2ConstantsDropDown.Value = "Lueker et al 2000";
            elseif app.WQ_Params.K1K2 == 11
                app.K1K2ConstantsDropDown.Value = "Mojica Prieto & Millero 2002";
            elseif app.WQ_Params.K1K2 == 12
                app.K1K2ConstantsDropDown.Value = "Millero et al. 2002";
            elseif app.WQ_Params.K1K2 == 13
                app.K1K2ConstantsDropDown.Value = "Millero et al. 2006";
            elseif app.WQ_Params.K1K2 == 14
                app.K1K2ConstantsDropDown.Value = "Millero 2010";
            elseif app.WQ_Params.K1K2 == 15
                app.K1K2ConstantsDropDown.Value = "Waters et al. 2014";
            elseif app.WQ_Params.K1K2 == 16
                app.K1K2ConstantsDropDown.Value = "Sulpis et al 2020";
            elseif app.WQ_Params.K1K2 == 17
                app.K1K2ConstantsDropDown.Value = "Schockman & Byrne 2021";
            end

            % KSO4 dissociation constants
            if app.WQ_Params.KHSO4 == 1
                app.KSODissociationConstantDropDown.Value = "Dickson (PREFERRED)";
            elseif app.WQ_Params.KHSO4 == 2
                app.KSODissociationConstantDropDown.Value = "Khoo";
            elseif app.WQ_Params.KHSO4 == 3
                app.KSODissociationConstantDropDown.Value = "Waters & Millero";
            end

            % KHF dissociation constants
            if app.WQ_Params.KHF == 1
                app.KHFDissociationConstantDropDown.Value = "Dickson & Riley 1979";
            elseif app.WQ_Params.KHF == 2
                app.KHFDissociationConstantDropDown.Value = "Perez & Fraga (PREFERRED)";
            end

            % Borate Salinity Relationship
            if app.WQ_Params.Borate == 1
                app.BorateSalinityDropDown.Value = "Uppstrom 1979";
            elseif app.WQ_Params.KHF == 2
                app.BorateSalinityDropDown.Value = "Lee 2010";
            end

            app.AlkalinityEditField.Value = app.WQ_Params.Alk;
            app.OEditField.Value = app.WQ_Params.O2_uM;
            app.OAirSatatSfcEditField.Value = app.WQ_Params.O2_percent;
            app.OAirSatatDepthEditField.Value = 100*(app.WQ_Params.O2_uM/app.Gas_Sat.O2_Depth);
            app.SiOEditField.Value = app.WQ_Params.SiO4;
            app.POEditField.Value = app.WQ_Params.PO4;
            app.NHEditField.Value = app.WQ_Params.NH4;
            app.NOEditField .Value= app.WQ_Params.NO3;
            app.HSEditField.Value = app.WQ_Params.H2S;
            app.pCOinAirAtmEditField.Value = app.WQ_Params.pCO2_Air;
            app.pCOinWaterAtmEditField.Value = app.WQ_Params.pCO2_Water;
            app.pCOAirSatatSfcEditField.Value = 100 * app.WQ_Params.pCO2_Air/app.WQ_Params.pCO2_Water;
            app.pCOAirSatatDepthEditField.Value = 100 * app.WQ_Params.pCO2_Air/app.WQ_Params.pCO2_Water;
            app.COEditField.Value = app.WQ_Params.CO2;
            app.HCOEditField.Value= app.WQ_Params.HCO3;
            app.COEditField_2.Value = app.WQ_Params.CO3;
            app.TotalDICEditField.Value = app.WQ_Params.TCO2;
           
            % Get initial irradiances (in air) and water column abKd spectra
            [app.E0_sfc_spectra, app.E0_sfc_PAR_mubar] = E0_surface_Ver3_for_app(app.Site_Data, app.Atm_Const, app.Atm_IOPs);

            % Compute water column abKd spectra
            app.abKd_Spectra = abKd_Spectra_Ver1_for_app(app.WQ_Params, app.IOP_Constants, app.IOP_Spectra, app.E0_sfc_PAR_mubar);

            % Compute water column irradiances
            app.EdEu_Data = EdEu_water_v3_for_app(app.Site_Data, app.IOP_Spectra, app.WQ_Params, app.Plant_Arch, app.Sed_Ref, app.LOPs, app.E0_sfc_spectra, app.E0_sfc_PAR_mubar, app.abKd_Spectra, app.Biodis_Data, app.PlantTypeDropDown, app.SubstrateTypeDropDown);
           
            % Compute instantaneous and daily metabolic rates
            app.Prod_Data = produce_v301_for_app(app.WQ_Params, app.Site_Data, app.Plant_Arch, app.Photo_Params, app.Biodis_Data, app.EdEu_Data, app.E0_sfc_PAR_mubar, app.PlantTypeDropDown);

            % Populate the Plant Productivity and Metabolism Panel
            app.CanopyAnglefromverticalEditField.Value = app.Plant_Arch.beta0(row,col);
            app.DailyPhotoperiodhEditField.Value = app.E0_sfc_PAR_mubar.daylen;
            app.PumolCmleafsEditField_2.Value = app.Prod_Data.Pm;
            app.PumolCmleafsEditField.Value = app.Prod_Data.PE;
            app.PPEditField.Value = app.Prod_Data.PE / app.Prod_Data.Pm;
            app.InstantaneousPumolCmleafsEditField.Value = app.Prod_Data.Mean_Biomass_normalized_P_vs_E;
            app.InstantaneousPumolCshootsEditField.Value = app.Prod_Data.P_now_per_shoot;
            app.InstantaneousCanopyPPEditField.Value = app.Prod_Data.P_now_over_PE;
            app.InstantaneousLeafPREditField.Value = app.Prod_Data.Instantaneous_Leaf_P_to_R;
            app.InstantaneousWholePlantPREditField.Value = app.Prod_Data.Instantaneous_Shoot_P_to_R;
            app.DailyWholePlantPREditField.Value = app.Prod_Data.Daily_P_to_R;
            app.delCEditField.Value = app.Prod_Data.del13C_new;
            app.DailyHRequirementhdEditField.Value = app.Prod_Data.Daily_Hsat_Requirement;

        end

        % Menu selected function: LoadNewSetupFileMenu
        function LoadNewSetupFileMenuSelected(app, event)
            [app.infile, app.inpath] = uigetfile('*.xlsx','Select Setup File:');
            [app.IOP_Spectra_Header, app.IOP_Spectra, app.Site_Data_Header, app.Site_Data, app.Atm_Const_Header, app.Atm_Const, app.Atm_IOPs_Header, app.Atm_IOPs, app.IOP_Constants_Header, app.IOP_Constants, app.WQ_Params_Header, app.WQ_Params, app.Plant_Arch_Header, app.Plant_Arch, app.Photo_Params_Header, app.Photo_Params, app.Sed_Ref_Header, app.Sed_Ref, app.LOPs_Header, app.LOPs] = read_GL3_input_data_for_app_302(app.inpath, app.infile);

            % Get initial gas saturation values
            app.Gas_Sat = Gas_Solubility_Model(app.WQ_Params.Temp, app.WQ_Params.Sal, app.Site_Data.depth, app.WQ_Params.pCO2_Air);

            % Populate Site Data Display

            app.LatitudedecEditField.Value = app.Site_Data.XLAT;
            app.LongitudedecEditField.Value = app.Site_Data.XLON;
            app.DateDatePicker.Value = datetime('1-Jan-2024')+app.Site_Data.JD-1;
            app.TimeofDay0to24hEditField.Value = app.Site_Data.HR;
            app.DepthmEditField.Value = app.Site_Data.depth;
            app.CurrentSpeedcmsEditField.Value = app.Site_Data.curvel;

            if app.Site_Data.sed_type == "Dark_Carbonate"
                app.SubstrateTypeDropDown.Value = "Dark Carbonate";
            elseif app.Site_Data.sed_type == "Bright_Carbonate"
                app.SubstrateTypeDropDown.Value = "Bright Carbonate";
            elseif app.Site_Data.sed_type == "Silica_Sand"
                app.SubstrateTypeDropDown.Value = "Silica Sand";
            else 
                app.SubstrateTypeDropDown.Value = "Silica Mud";
            end
            
            col = 1;
            if app.Site_Data.lop == "zostera"
                app.PlantTypeDropDown.Value = "Zostera";
                row = 1;
            elseif app.Site_Data.lop == "thalassia"
                app.PlantTypeDropDown.Value = "Thalassia";
                row = 2;
            elseif app.Site_Data.lop == "syringodium"
                app.PlantTypeDropDown.Value = "Syringodium";
                row = 3;
            elseif app.Site_Data.lop == "phyllospadix"
                app.PlantTypeDropDown.Value = "Phyllospadix";
                row = 4;
            elseif app.Site_Data.lop == "posidonia"
                app.PlantTypeDropDownValue = "Posidonia";
                row = 5;
            else 
                app.PlantTypeDropDown.Value = "Ruppia";
                row = 6;
            end

            % Calculate the vertical biomass distribution
            [app.Biodis_Data, app.Plant_Arch] = biodis_v301_for_app(app.Site_Data,app.Plant_Arch, app.PlantTypeDropDown);

            % Update the plant morphology parameters
            app.MaxHeightmEditField.Value = app.Plant_Arch.maxht(row, col);
            app.ShootLeafAreamLeafshootEditField.Value = app.Plant_Arch.areash(row,col);
            app.ShootDensityshootspermEditField.Value = app.Plant_Arch.density(row,col);
            app.LAIEditField.Value = app.Biodis_Data.laitot;                          % Get total LAI
            app.ShootRhizomeRootEditField.Value = app.Plant_Arch.shoot_frac(row,col)/(app.Plant_Arch.rhiz_frac(row,col)+app.Plant_Arch.root_frac(row,col));
            app.RhizRootEditField.Value = app.Plant_Arch.rhiz_frac(row,col)/app.Plant_Arch.root_frac(row,col);
            app.EpiphyteLoadmgcmEditField.Value = app.Plant_Arch.epi(row,col);

           % Populate Water Column Optical Constituents Display
            app.ChlamgmEditField.Value = app.WQ_Params.CHL_A;
            app.TSMmgLEditField.Value = app.WQ_Params.TSM;
            app.a440EditField.Value = app.WQ_Params.AG_440;

            % Populate Initial Water Chemistry Parameters

            %Select CO2SYS Input Parameters
            if (app.WQ_Params.PAR1TYPE == 1 && app.WQ_Params.PAR2TYPE == 2) || (app.WQ_Params.PAR1TYPE == 2 && app.WQ_Params.PAR2TYPE == 1)
                app.CO2SYSInputPrametersDropDown.Value = "Alk & Total DIC";
            elseif (app.WQ_Params.PAR1TYPE == 1 && app.WQ_Params.PAR2TYPE == 3) || (app.WQ_Params.PAR1TYPE == 3 && app.WQ_Params.PAR2TYPE == 1)
                app.CO2SYSInputPrametersDropDown.Value = "Alk & pH";
            elseif (app.WQ_Params.PAR1TYPE == 1 && app.WQ_Params.PAR2TYPE == 4) || (app.WQ_Params.PAR1TYPE == 4 && app.WQ_Params.PAR2TYPE == 1)
                app.CO2SYSInputPrametersDropDown.Value = "Alk & pCO2";
            elseif (app.WQ_Params.PAR1TYPE == 2 && app.WQ_Params.PAR2TYPE == 3) || (app.WQ_Params.PAR1TYPE == 3 && app.WQ_Params.PAR2TYPE == 2)
                app.CO2SYSInputPrametersDropDown.Value = "Total DIC & pH";
            else
                app.CO2SYSInputPrametersDropDown.Value = "Total DIC & pCO2";
            end

            % Populate temperature and salinity fields
            app.TemperatureCEditField.Value = app.WQ_Params.Temp;
            app.SalinitypptorPSS78EditField.Value = app.WQ_Params.Sal;

            % Populate pH value and pH scale fields
            app.pHEditField.Value = app.WQ_Params.pH;
            app.pHScaleDropDown.Value = app.WQ_Params.scale;
            if app.pHScaleDropDown.Value == "Total"
                app.pH_scale_numeric_value_for_CO2sys = 1;
            else 
                if app.pHScaleDropDown.Value == "Seawater"
                app.pH_scale_numeric_value_for_CO2sys = 2;
                else 
                    if app.pHScaleDropDown.Value == "Free"
                        app.pH_scale_numeric_value_for_CO2sys = 3;
                    else 
                        app.pH_scale_numeric_value_for_CO2sys = 4;
                    end
                end
            end
                    
            % K1 and K2 dissociation constants
            if app.WQ_Params.K1K2 == 1
                app.K1K2ConstantsDropDown.Value = "Roy 1993";
            elseif app.WQ_Params.K1K2 == 2
                app.K1K2ConstantsDropDown.Value = "Goyet & Poisson 1989";
            elseif app.WQ_Params.K1K2 == 3
                app.K1K2ConstantsDropDown.Value = "Hansson 1973 refit by Dickson & Millero 1987";
            elseif app.WQ_Params.K1K2 == 4
                app.K1K2ConstantsDropDown.Value = "Mehrbach et al. 1973 refit by Dickson & Millero 1987";
            elseif app.WQ_Params.K1K2 == 5
                app.K1K2ConstantsDropDown.Value = "Hansson & Mehrbach refit by Dickson & Millero 1987";
            elseif app.WQ_Params.K1K2 == 6
                app.K1K2ConstantsDropDown.Value = "GEOSECS NBS Constants, K1, K2 from Mehrbach et al. 1973";
            elseif app.WQ_Params.K1K2 == 7
                app.K1K2ConstantsDropDown.Value = "Peng et al. NBS constants; K1 K2 from Mehrbach et al. 1973";
            elseif app.WQ_Params.K1K2 == 8
                app.K1K2ConstantsDropDown.Value = "Freshwater (Sal = 0); K1 K2 from Millero 1979";
            elseif app.WQ_Params.K1K2 == 9
                app.K1K2ConstantsDropDown.Value = "Cai and Wang 1998";
            elseif app.WQ_Params.K1K2 == 10
                app.K1K2ConstantsDropDown.Value = "Lueker et al 2000";
            elseif app.WQ_Params.K1K2 == 11
                app.K1K2ConstantsDropDown.Value = "Mojica Prieto & Millero 2002";
            elseif app.WQ_Params.K1K2 == 12
                app.K1K2ConstantsDropDown.Value = "Millero et al. 2002";
            elseif app.WQ_Params.K1K2 == 13
                app.K1K2ConstantsDropDown.Value = "Millero et al. 2006";
            elseif app.WQ_Params.K1K2 == 14
                app.K1K2ConstantsDropDown.Value = "Millero 2010";
            elseif app.WQ_Params.K1K2 == 15
                app.K1K2ConstantsDropDown.Value = "Waters et al. 2014";
            elseif app.WQ_Params.K1K2 == 16
                app.K1K2ConstantsDropDown.Value = "Sulpis et al 2020";
            elseif app.WQ_Params.K1K2 == 17
                app.K1K2ConstantsDropDown.Value = "Schockman & Byrne 2021";
            end

            % KSO4 dissociation constants
            if app.WQ_Params.KHSO4 == 1
                app.KSODissociationConstantDropDown.Value = "Dickson (PREFERRED)";
            elseif app.WQ_Params.KHSO4 == 2
                app.KSODissociationConstantDropDown.Value = "Khoo";
            elseif app.WQ_Params.KHSO4 == 3
                app.KSODissociationConstantDropDown.Value = "Waters & Millero";
            end

            % KHF dissociation constants
            if app.WQ_Params.KHF == 1
                app.KHFDissociationConstantDropDown.Value = "Dickson & Riley 1979";
            elseif app.WQ_Params.KHF == 2
                app.KHFDissociationConstantDropDown.Value = "Perez & Fraga (PREFERRED)";
            end

            % Borate Salinity Relationship
            if app.WQ_Params.Borate == 1
                app.BorateSalinityDropDown.Value = "Uppstrom 1979";
            elseif app.WQ_Params.KHF == 2
                app.BorateSalinityDropDown.Value = "Lee 2010";
            end

            app.AlkalinityEditField.Value = app.WQ_Params.Alk;
            app.OEditField.Value = app.WQ_Params.O2_uM;
            app.OAirSatatSfcEditField.Value = app.WQ_Params.O2_percent;
            app.OAirSatatDepthEditField.Value = 100*(app.WQ_Params.O2_uM/app.Gas_Sat.O2_Depth);
            app.SiOEditField.Value = app.WQ_Params.SiO4;
            app.POEditField.Value = app.WQ_Params.PO4;
            app.NHEditField.Value = app.WQ_Params.NH4;
            app.NOEditField .Value= app.WQ_Params.NO3;
            app.HSEditField.Value = app.WQ_Params.H2S;
            app.pCOinAirAtmEditField.Value = app.WQ_Params.pCO2_Air;
            app.pCOinWaterAtmEditField.Value = app.WQ_Params.pCO2_Water;
            app.pCOAirSatatSfcEditField.Value = 100 * app.WQ_Params.pCO2_Air/app.WQ_Params.pCO2_Water;
            app.pCOAirSatatDepthEditField.Value = 100 * app.WQ_Params.pCO2_Air/app.WQ_Params.pCO2_Water;
            app.COEditField.Value = app.WQ_Params.CO2;
            app.HCOEditField.Value= app.WQ_Params.HCO3;
            app.COEditField_2.Value = app.WQ_Params.CO3;
            app.TotalDICEditField.Value = app.WQ_Params.TCO2;

            % Get initial irradiances (in air) and water column abKd spectra
            [app.E0_sfc_spectra, app.E0_sfc_PAR_mubar] = E0_surface_Ver3_for_app(app.Site_Data, app.Atm_Const, app.Atm_IOPs);

            % Compute water column abKd spectra
            app.abKd_Spectra = abKd_Spectra_Ver1_for_app(app.WQ_Params, app.IOP_Constants, app.IOP_Spectra, app.E0_sfc_PAR_mubar);

            % Compute water column irradiances
            app.EdEu_Data = EdEu_water_v3_for_app(app.Site_Data, app.IOP_Spectra, app.WQ_Params, app.Plant_Arch, app.Sed_Ref, app.LOPs, app.E0_sfc_spectra, app.E0_sfc_PAR_mubar, app.abKd_Spectra, app.Biodis_Data, app.PlantTypeDropDown, app.SubstrateTypeDropDown);
           
            % Compute instantaneous and daily metabolic rates
            app.Prod_Data = produce_v301_for_app(app.WQ_Params, app.Site_Data, app.Plant_Arch, app.Photo_Params, app.Biodis_Data, app.EdEu_Data, app.E0_sfc_PAR_mubar, app.PlantTypeDropDown);
                             
            % Populate the Plant Productivity and Metabolism Panel
            app.CanopyAnglefromverticalEditField.Value = app.Plant_Arch.beta0(row,col);
            app.DailyPhotoperiodhEditField.Value = app.E0_sfc_PAR_mubar.daylen;
            app.PumolCmleafsEditField_2.Value = app.Prod_Data.Pm;
                       
            app.PumolCmleafsEditField.Value = app.Prod_Data.PE;
            if app.Prod_Data.PE || app.Prod_Data.Pm
                app.PPEditField.Value = app.Prod_Data.PE / app.Prod_Data.Pm;
            end
            app.InstantaneousPumolCmleafsEditField.Value = app.Prod_Data.Mean_Biomass_normalized_P_vs_E;
            app.InstantaneousPumolCshootsEditField.Value = app.Prod_Data.P_now_per_shoot;
            app.InstantaneousCanopyPPEditField.Value = app.Prod_Data.P_now_over_PE;
            app.InstantaneousLeafPREditField.Value = app.Prod_Data.Instantaneous_Leaf_P_to_R;
            app.InstantaneousWholePlantPREditField.Value = app.Prod_Data.Instantaneous_Shoot_P_to_R;
            app.DailyWholePlantPREditField.Value = app.Prod_Data.Daily_P_to_R;
            app.delCEditField.Value = app.Prod_Data.del13C_new;
            app.DailyHRequirementhdEditField.Value = app.Prod_Data.Daily_Hsat_Requirement;
                        
        end

        % Menu selected function: SaveNewSetupFileMenu
        function SaveNewSetupFileMenuSelected(app, event)

            % Get the new setup file name
            [app.outfile, app.outpath] = uiputfile('*.xlsx','Save Setup File in Excel format:');
            app.new_setup_file = [app.outpath app.outfile];
%             chk = isfile(app.new_setup_file);
%             if chk == 1
%               prompt = {'Enter new file name'};
%               dlgtitle = 'File already exists';
%               fieldsize = [1 50];
%               answer = inputdlg(prompt,dlgtitle,fieldsize)
%               app.new_setup_file = [app.outpath answer]
%             end
            % Write the Site Data to the setup file
            writetable(app.Site_Data_Header, app.new_setup_file, 'WriteVariableNames', 0, 'Sheet','Site Data', 'Range', 'A1:G9'); 
            %writetable(app.Site_Data, app.new_setup_file, 'Sheet','Site Data', 'Range', 'A11:H12'); 
            writetable(app.Site_Data, app.new_setup_file, 'Sheet','Site Data', 'Range', 'A11');
            
            % Write the IOP Spectra to the setup file
            writetable(app.IOP_Spectra_Header, app.new_setup_file, 'WriteVariableNames', 0, 'Sheet','IOP Spectra', 'Range', 'A1:E9'); 
            writetable(app.IOP_Spectra, app.new_setup_file, 'Sheet','IOP Spectra', 'Range', 'A12:D313');

            % Write IOP Constants to the setup file
            writetable(app.IOP_Constants_Header, app.new_setup_file, 'WriteVariableNames', 0, 'Sheet','IOP Constants', 'Range', 'A1:J11'); 
            writetable(app.IOP_Constants, app.new_setup_file, 'Sheet','IOP Constants', 'Range', 'A12:H13');

            % Write Water Quality Parameters to the setup file
            writetable(app.WQ_Params_Header, app.new_setup_file, 'WriteVariableNames', 0,  'Sheet','Water Quality Parameters', 'Range', 'A1:F27'); 
            writetable(app.WQ_Params, app.new_setup_file, 'Sheet','Water Quality Parameters', 'Range', 'A30:AA31');

            % Write Atmospheric Optical Properties to the setup file
            writetable(app.Atm_IOPs_Header, app.new_setup_file, 'WriteVariableNames', 0, 'Sheet','Atmospheric Optical Properties', 'Range', 'A1:C7'); 
            writetable(app.Atm_IOPs, app.new_setup_file, 'Sheet','Atmospheric Optical Properties', 'Range', 'A11:E312');

            % Write Atmospheric Constants
            writetable(app.Atm_Const_Header, app.new_setup_file, 'WriteVariableNames', 0, 'Sheet','Atmospheric Constants', 'Range', 'A1:B10'); 
            writetable(app.Atm_Const, app.new_setup_file, 'Sheet','Atmospheric Constants', 'Range', 'A12:H13');

            % Write Leaf Optical Properties
            writetable(app.LOPs_Header, app.new_setup_file, 'WriteVariableNames', 0, 'Sheet','Leaf Optical Properties', 'Range', 'A1:A6'); 
            writetable(app.LOPs, app.new_setup_file, 'Sheet','Leaf Optical Properties', 'Range', 'A10:M311');

            % Write Metabolic Parameters
            writetable(app.Photo_Params_Header, app.new_setup_file, 'WriteVariableNames', 0, 'Sheet','Metabolic Parameters', 'Range', 'A1:B16'); 
            writetable(app.Photo_Params, app.new_setup_file, 'Sheet','Metabolic Parameters', 'Range', 'A20:L26');

            % Write Plant Architecture
            writetable(app.Plant_Arch_Header, app.new_setup_file, 'WriteVariableNames', 0, 'Sheet','Plant Architecture', 'Range', 'A1:B15'); 
            writetable(app.Plant_Arch, app.new_setup_file, 'Sheet','Plant Architecture', 'Range', 'A17:M23');

            % Write Sediment Reflectance Spectra
            writetable(app.Sed_Ref_Header, app.new_setup_file, 'WriteVariableNames', 0, 'Sheet','Sediment Reflectances', 'Range', 'A1:B8'); 
            writetable(app.Sed_Ref, app.new_setup_file, 'Sheet','Sediment Reflectances', 'Range', 'A10:E311');


        end

        % Menu selected function: AboutGrassLightMenu
        function AboutGrassLightMenuSelected(app, event)
            app.info_file = 'GrassLight_Users_Guide_Ver_3.11.pdf';
            chk = isfile (app.info_file);
            if chk == 1
                winopen(app.info_file)
            else
                [app.info_file, app.info_path] = uigetfile('*.pdf','Select Info File:');
                winopen(app.info_file)
%                 fig = uifigure;
%                 uialert(fig, 'File not found', 'File Error');
            end
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            close all
            delete(app)
        end

        % Menu selected function: OptimizeDensityMenu
        function OptimizeDensityMenuSelected(app, event)

            % Identify the plant species for new shoot leaf area  
            col = 1;
            if app.PlantTypeDropDown.Value == "Zostera"
                row = 1;
            elseif app.PlantTypeDropDown.Value == "Thalassia"
                row = 2;
            elseif app.PlantTypeDropDown.Value == "Syringodium"
                row = 3;
            elseif app.PlantTypeDropDown.Value == "Phyllospadix"
                row = 4;
            elseif app.PlantTypeDropDown.Value == "Posidonia"
                row = 5;
            else 
                row = 6;        %Ruppia
            end
            
            % Make sure there are some shoots if initial density is 0
            if app.Plant_Arch.density(row,col) < 1
                app.Plant_Arch.density(row, col) = 100;
                    % Call Biodis function to get new LAI
                [app.Biodis_Data, app.Plant_Arch] = biodis_v301_for_app(app.Site_Data,app.Plant_Arch, app.PlantTypeDropDown);
                    % Compute new submarine light distribution
                app.EdEu_Data = EdEu_water_v3_for_app(app.Site_Data, app.IOP_Spectra, app.WQ_Params, app.Plant_Arch, app.Sed_Ref, app.LOPs, app.E0_sfc_spectra, app.E0_sfc_PAR_mubar, app.abKd_Spectra, app.Biodis_Data, app.PlantTypeDropDown, app.SubstrateTypeDropDown);
                    % Compute new canopy production estimates
                app.Prod_Data = produce_v301_for_app(app.WQ_Params, app.Site_Data, app.Plant_Arch, app.Photo_Params, app.Biodis_Data, app.EdEu_Data, app.E0_sfc_PAR_mubar, app.PlantTypeDropDown);
            end

            % Make sure time of day is noon
            if app.Site_Data.HR ~= 12
                app.Site_Data.HR = 12;
                app.TimeofDay0to24hEditField.Value = app.Site_Data.HR;
                    % Compute new surface irradanc   
                [app.E0_sfc_spectra, app.E0_sfc_PAR_mubar] = E0_surface_Ver3_for_app(app.Site_Data, app.Atm_Const, app.Atm_IOPs);
                    % Compute new submarine light distribution
                app.EdEu_Data = EdEu_water_v3_for_app(app.Site_Data, app.IOP_Spectra, app.WQ_Params, app.Plant_Arch, app.Sed_Ref, app.LOPs, app.E0_sfc_spectra, app.E0_sfc_PAR_mubar, app.abKd_Spectra, app.Biodis_Data, app.PlantTypeDropDown, app.SubstrateTypeDropDown);
                    % Compute new canopy production estimates
                app.Prod_Data = produce_v301_for_app(app.WQ_Params, app.Site_Data, app.Plant_Arch, app.Photo_Params, app.Biodis_Data, app.EdEu_Data, app.E0_sfc_PAR_mubar, app.PlantTypeDropDown);
                f = msgbox("Time of day changed to 12:00");
                pause(3)
                close(f)
            end
                    
            % Evaluate Daily P:R
            p2r_test = abs(app.Prod_Data.Daily_P_to_R - 1);
            if p2r_test <= 0.0001
                f = msgbox("Daily P:R = 1");
                return
            end
            % f = msgbox("Optimizing Density...please wait");           % Notify user optimization in progress
            num_iter = 0;                   % Set iterations counter to 1
            while p2r_test > 0.0001      %Daily P:R <> 1
                f = msgbox(sprintf("Optimizing Density...P:R = "+ num2str(app.Prod_Data.Daily_P_to_R)+" LAI = "+ num2str(app.Biodis_Data.laitot)));           % Notify user optimization in progress
                    % Change plant density
                app.Plant_Arch.density(row,col) = app.Plant_Arch.density(row,col)*app.Prod_Data.Daily_P_to_R; 
                % app.Plant_Arch.density(row,col)     % debug stmt
                    % If shoot density < 1 then set plant density to 0 and stop optimizing
                if app.Plant_Arch.density(row,col) < 1                  % If plant density drops below 1 shoot/m2, stop and set everything to 0
                    app.Plant_Arch.density(row,col) = 0;
                        % Call Biodis function to get new LAI
                    [app.Biodis_Data, app.Plant_Arch] = biodis_v301_for_app(app.Site_Data,app.Plant_Arch, app.PlantTypeDropDown);
                        % Compute new submarine light distribution
                    app.EdEu_Data = EdEu_water_v3_for_app(app.Site_Data, app.IOP_Spectra, app.WQ_Params, app.Plant_Arch, app.Sed_Ref, app.LOPs, app.E0_sfc_spectra, app.E0_sfc_PAR_mubar, app.abKd_Spectra, app.Biodis_Data, app.PlantTypeDropDown, app.SubstrateTypeDropDown);
                        % Compute instantaneous and daily metabolic rates
                    app.Prod_Data = produce_v301_for_app(app.WQ_Params, app.Site_Data, app.Plant_Arch, app.Photo_Params, app.Biodis_Data, app.EdEu_Data, app.E0_sfc_PAR_mubar, app.PlantTypeDropDown);
                     % Update Shoot Density edit field
                    app.ShootDensityshootspermEditField.Value = app.Plant_Arch.density(row,col);
                        % Update LAI edit field
                    app.LAIEditField.Value = app.Biodis_Data.laitot;  
                        % Update the Plant Productivity and Metabolism Panel
                    app.PumolCmleafsEditField_2.Value = app.Prod_Data.Pm;
                    app.PumolCmleafsEditField.Value = app.Prod_Data.PE;
                    if app.Prod_Data.Pm > 0 || app.Prod_Data.PE > 0
                        app.PPEditField.Value = app.Prod_Data.PE / app.Prod_Data.Pm;
                    end
                    app.InstantaneousPumolCmleafsEditField.Value = app.Prod_Data.Mean_Biomass_normalized_P_vs_E;
                    app.InstantaneousPumolCshootsEditField.Value = app.Prod_Data.P_now_per_shoot;
                    app.InstantaneousCanopyPPEditField.Value = app.Prod_Data.P_now_over_PE;
                    app.InstantaneousLeafPREditField.Value = app.Prod_Data.Instantaneous_Leaf_P_to_R;
                    app.InstantaneousWholePlantPREditField.Value = app.Prod_Data.Instantaneous_Shoot_P_to_R;
                    app.DailyWholePlantPREditField.Value = app.Prod_Data.Daily_P_to_R;
                    app.delCEditField.Value = app.Prod_Data.del13C_new;
                    close(f)                            % Close original optimization message                            
                    f = msgbox("Light enviromnent cannot support plants, density set to 0"); % Notify user that optimization task is complete   
                    %pause(3)
                    return
                end
                    % Otherwise, continue optimizing plant density
                    % Call Biodis function to get new LAI
                [app.Biodis_Data, app.Plant_Arch] = biodis_v301_for_app(app.Site_Data,app.Plant_Arch, app.PlantTypeDropDown);
                    % Compute new submarine light distribution
                app.EdEu_Data = EdEu_water_v3_for_app(app.Site_Data, app.IOP_Spectra, app.WQ_Params, app.Plant_Arch, app.Sed_Ref, app.LOPs, app.E0_sfc_spectra, app.E0_sfc_PAR_mubar, app.abKd_Spectra, app.Biodis_Data, app.PlantTypeDropDown, app.SubstrateTypeDropDown);
                    % Compute instantaneous and daily metabolic rates
                app.Prod_Data = produce_v301_for_app(app.WQ_Params, app.Site_Data, app.Plant_Arch, app.Photo_Params, app.Biodis_Data, app.EdEu_Data, app.E0_sfc_PAR_mubar, app.PlantTypeDropDown);
                    % Re-evaluate daily P:R
                p2r_test = abs(app.Prod_Data.Daily_P_to_R - 1);
                close(f)
                num_iter = num_iter + 1;
                if num_iter == 1000
                    f = msgbox("1000 iterations performed without converging precisely to P:R = 1");
                    return
                end
            end
                    % Update Shoot Density edit field
                app.ShootDensityshootspermEditField.Value = app.Plant_Arch.density(row,col);
                    % Update LAI edit field
                app.LAIEditField.Value = app.Biodis_Data.laitot;  
                    % Update the Plant Productivity and Metabolism Panel
                app.PumolCmleafsEditField_2.Value = app.Prod_Data.Pm;
                app.PumolCmleafsEditField.Value = app.Prod_Data.PE;
                app.PPEditField.Value = app.Prod_Data.PE / app.Prod_Data.Pm;
                app.InstantaneousPumolCmleafsEditField.Value = app.Prod_Data.Mean_Biomass_normalized_P_vs_E;
                app.InstantaneousPumolCshootsEditField.Value = app.Prod_Data.P_now_per_shoot;
                app.InstantaneousCanopyPPEditField.Value = app.Prod_Data.P_now_over_PE;
                app.InstantaneousLeafPREditField.Value = app.Prod_Data.Instantaneous_Leaf_P_to_R;
                app.InstantaneousWholePlantPREditField.Value = app.Prod_Data.Instantaneous_Shoot_P_to_R;
                app.DailyWholePlantPREditField.Value = app.Prod_Data.Daily_P_to_R;
                app.delCEditField.Value = app.Prod_Data.del13C_new;
                app.DailyHRequirementhdEditField.Value = app.Prod_Data.Daily_Hsat_Requirement;
                %close(f)                            % Close original optimization message                            
                f = msgbox("Daily P:R = 1");        % Notify user that optimization task is complete
                
        end

        % Menu selected function: TimeSeriesMenu
        function TimeSeriesMenuSelected(app, event)
            f = msgbox("Time Series Feature Not Yet Implemented");

        end

        % Menu selected function: 
        % SurfaceIrradianceandReflectanceSpectraMenu
        function SurfaceIrradianceandReflectanceSpectraMenuSelected(app, event)
            plot_title = ({ ...
                [string(app.PlantTypeDropDown.Value) + " " + string(app.DateDatePicker.Value)+ " " + string(app.Site_Data.HR) + " hrs"] ...
                ['LAI = ',num2str(app.Biodis_Data.laitot), '  Water Depth =',num2str(app.Site_Data.depth), ' m  Canopy Ht =',num2str(app.Biodis_Data.canopy_ht),' m']...
                ...
                });

            figure('Name','Surface Irradiance & Reflectance Spectra');

            sgtitle(plot_title)

            subplot(3,1,1)
            subtitle('Downwelling Irradiances in Air')
            plot(app.IOP_Spectra.WL, app.E0_sfc_spectra.ED0_air, 'k','LineWidth', 1)
            hold on
            plot(app.IOP_Spectra.WL, app.E0_sfc_spectra.ED0_water, 'b','LineWidth', 1)
            plot(app.IOP_Spectra.WL, app.E0_sfc_spectra.EDD_air, 'm','LineWidth', 1)
            plot(app.IOP_Spectra.WL, app.E0_sfc_spectra.EDS_air, 'c','LineWidth', 1)
            xlabel('Wavelength (nm)')
            ylabel('Irradiance (W m^{-2})')
            legend('Total in air','Total in water','Direct in air','Sky in air')
            if max(app.E0_sfc_spectra.ED0_air) > 0
                ylim([0 1.2*max(app.E0_sfc_spectra.ED0_air)])
            else
                ylim([0 inf])
            end

            subplot(3,1,2)
            subtitle('Water-Leaving Radiance')
            plot(app.IOP_Spectra.WL, app.EdEu_Data.LU0_air, 'k','LineWidth', 1)
            xlabel('Wavelength (nm)')
            ylabel('\itL\rm_w (W m^{-2} sr^{-1})')
            if max(app.EdEu_Data.LU0_air) > 0
                ylim([0 1.2*max(app.EdEu_Data.LU0_air)])
            else
                ylim([0 inf])
            end

            subplot(3,1,3)
            subtitle('Remote Sensint Reflectance (Sr^{-1})')
            plot(app.IOP_Spectra.WL, app.EdEu_Data.Rrs, 'k','LineWidth', 1)
            xlabel('Wavelength (nm)')
            ylabel('\itR\rm_{rs} (sr^{-1})')
            if max(app.EdEu_Data.Rrs) > 0
                ylim([0 1.2*max(app.EdEu_Data.Rrs)])
            else
                ylim([0 inf])
            end
            
        end

        % Menu selected function: 
        % SubmarineIrradianceandReflectanceSpectraMenu
        function SubmarineIrradianceandReflectanceSpectraMenuSelected(app, event)
            plot_title = ({ ...
                [string(app.PlantTypeDropDown.Value) + " " + string(app.DateDatePicker.Value)+ " " + string(app.Site_Data.HR) + " hrs"] ...
                ['LAI = ',num2str(app.Biodis_Data.laitot), '  Water Depth =',num2str(app.Site_Data.depth), ' m  Canopy Ht =',num2str(app.Biodis_Data.canopy_ht),' m']...
                });

            figure('Name','Submarine Irradiance & Reflectance Spectra');
            
            sgtitle(plot_title)
            subplot(5,1,1)
            surf(app.IOP_Spectra.WL,app.Site_Data.depth - app.Biodis_Data.ht, app.EdEu_Data.edw,'edgecolor','none')
            set(gca,'YDir','reverse')
            xlabel('Wavelength (nm)')
            ylabel('Depth (m)')
            zlabel({'\itE\rm_d', 'W m^{-2} nm^{-1}'})
            zlim([0 inf])
            
            subplot(5,1,2)
            surf(app.Site_Data.depth - app.Biodis_Data.ht, app.IOP_Spectra.WL, transpose(app.EdEu_Data.euw),'edgecolor','none')
            ylabel('Wavelength (nm)')
            xlabel ('Depth (m)')
            zlabel({'\itE\rm_u', 'W m^{-2} nm^{-1}'})
            zlim([0 inf])
            
            subplot(5,1,3)
            surf(app.Site_Data.depth - app.Biodis_Data.ht, app.IOP_Spectra.WL, transpose(app.EdEu_Data.can_refl),'edgecolor','none')
            ylabel('Wavelength (nm)')
            xlabel('Depth (m)')
            zlabel('Reflectance (rel)')
            
            subplot(5,1,4)
            plot(app.EdEu_Data.eow, app.Site_Data.depth - app.Biodis_Data.ht, 'k','LineWidth', 1)
            set(gca,'YDir','reverse')
            xlabel('PAR (\muMoles m^{-2} s^{-1})')
            ylabel('Depth (m)')
            ylim([0 app.Site_Data.depth]);
            if max(app.EdEu_Data.eow) > 0
                xlim([0 max(app.EdEu_Data.eow)])
            else
                xlim([0 inf])
            end
           
            subplot(5,1,5)
            plot(app.Prod_Data.piz_per_leaf, app.Site_Data.depth - app.Biodis_Data.ht, 'k','LineWidth', 1)
            set(gca,'YDir','reverse')
            xlabel('\Pi(z) (\muMoles m^{-2} leaf s^{-1})')
            ylabel('Depth (m)')
            ylim([0 app.Site_Data.depth])
            if max(app.EdEu_Data.eow) > 0
                xlim([0 max(app.EdEu_Data.eow)])
            else
                xlim([0 inf])
            end

        end

        % Menu selected function: WaterColumnOpticalPropertiesMenu
        function WaterColumnOpticalPropertiesMenuSelected(app, event)
            plot_title = ({ ...
                [string(app.DateDatePicker.Value)+ " " + string(app.Site_Data.HR) + " hrs"] ...
                ["Chl \ita\rm = "+string(app.ChlamgmEditField.Value)+" mg m^{-3} TSM = "+string(app.TSMmgLEditField.Value)+" mg L^{-1} \ita\rm_g(440) = "+ string(app.a440EditField.Value) + " m^{-1}"]...                 ...
                });

            figure('Name','Water Column Optical Properties')

            sgtitle(plot_title)
            subplot(3,1,1)
            plot(app.abKd_Spectra.WL, app.IOP_Spectra.AW + app.abKd_Spectra.AG + app.abKd_Spectra.ANAP + app.abKd_Spectra.ACHL, 'k','LineWidth', 1)
            hold on
            plot(app.abKd_Spectra.WL, app.IOP_Spectra.AW + app.abKd_Spectra.AG + app.abKd_Spectra.ANAP, 'b','LineWidth', 1)       % Pure water+gilvin+NAP
            plot(app.abKd_Spectra.WL, app.IOP_Spectra.AW + app.abKd_Spectra.AG, 'm','LineWidth', 1)       % Pure water + gilvin
            plot(app.abKd_Spectra.WL, app.IOP_Spectra.AW, 'c','LineWidth', 1)       % Pure water asborption
            xlabel('Wavelength (nm)')
            ylabel('\ita\rm (m^{-1})')
            legend('\ita\rm_{Total}','\ita\rm_{(w+g+NAP)}','\ita\rm_{(w+g)}','\ita\rm_w')

            subplot(3,1,2)
            plot(app.abKd_Spectra.WL, app.abKd_Spectra.BP, 'k','LineWidth', 1)   % Particulate Scattering
            hold on
            xlabel('Wavelength (nm)')
            ylabel('\itb\rm_p (m^{-1})')
            yyaxis right;
            plot(app.abKd_Spectra.WL, app.abKd_Spectra.BB, 'b','LineWidth', 1)       %Particulate Backscattering
            ax = gca;
            ax.YColor = 'b';
            ylabel('\itb\rm_b (m^{-1})', 'Color','b') 
            legend('\itb\rm_p','\itb\rm_b')

             
            subplot(3,1,3)
            plot(app.abKd_Spectra.WL, app.abKd_Spectra.Kd, 'k','LineWidth', 1)
            hold on
            plot(app.abKd_Spectra.WL, app.abKd_Spectra.Kd_aw + app.abKd_Spectra.Kd_ag + app.abKd_Spectra.Kd_anap, 'b','LineWidth', 1)
            plot(app.abKd_Spectra.WL, app.abKd_Spectra.Kd_aw + app.abKd_Spectra.Kd_ag, 'm','LineWidth', 1)
            plot(app.abKd_Spectra.WL, app.abKd_Spectra.Kd_aw, 'c','LineWidth', 1)
            xlabel('Wavelength (nm)')
            ylabel('\itK\rm_d (m^{-1})')
            legend('\itK\rm_{d}','\itK\rm_{d(w+g+NAP)}','\itK\rm_{d(w+g)}','\itK\rm_{d(w)}')
            title('b_b/b = ' + string(app.IOP_Constants.BB2B))

        end

        % Menu selected function: LeafOpticalPropertiesMenu
        function LeafOpticalPropertiesMenuSelected(app, event)
            if app.PlantTypeDropDown.Value == "Zostera"
                a_leaf = app.LOPs.a_zostera;
                r_leaf = app.LOPs.r_zostera;
            elseif app.PlantTypeDropDown.Value == "Thalassia"
                a_leaf = app.LOPs.a_thalassia;
                r_leaf = app.LOPs.r_thalassia;
            elseif app.PlantTypeDropDown.Value == "Syringodium"
                a_leaf = app.LOPs.a_syringodium;
                r_leaf = app.LOPs.r_syringodium;
            elseif app.PlantTypeDropDown.Value == "Phyllospadix"
                a_leaf = app.LOPs.a_phyllospadix;
                r_leaf = app.LOPs.r_phyllospadix;
            elseif app.PlantTypeDropDown.Value == "Posidonia"
                a_leaf = app.LOPs.a_posidonia;
                r_leaf = app.LOPs.r_posidonia;
             else 
                a_leaf = app.LOPs.a_ruppia;
                r_leaf = app.LOPs.r_ruppia;
            end

            figure('Name','Leaf Optical Properties');
            plot_title = app.PlantTypeDropDown.Value;
            sgtitle(plot_title)
            
            subplot(2,1,1)
            plot(app.LOPs.WL,a_leaf, 'k','LineWidth', 1)
            xlabel('Wavelength (nm)')
            ylabel('Leaf Absorption Coefficient (leaf^{-1})')

            subplot(2,1,2)
            plot(app.LOPs.WL,r_leaf, 'k','LineWidth', 1)
            xlabel('Wavelength (nm)')
            ylabel('Leaf Reflactance (rel)')

        end

        % Menu selected function: SubstrateReflectanceSpectrumMenu
        function SubstrateReflectanceSpectrumMenuSelected(app, event)

            if app.SubstrateTypeDropDown.Value == "Bright Carbonate"
                sed_ref = app.Sed_Ref.Bright_Carbonate;
            elseif app.SubstrateTypeDropDown.Value == "Dark Carbonate"
                sed_ref = app.Sed_Ref.Dark_Carbonate;
            elseif app.SubstrateTypeDropDown.Value == "Silica Sand"
                sed_ref = app.Sed_Ref.Silica_Sand;
            else 
                sed_ref = app.Sed_Ref.Silica_Mud;
            end

            figure('Name', 'Sediment Reflectance Spectrum');
            plot(app.IOP_Spectra.WL,sed_ref, 'k','LineWidth', 1)
            xlabel('Wavelength (nm)')
            ylabel('Sediment Reflectance (rel)')
            ylim([0 0.6])
            title(app.SubstrateTypeDropDown.Value)

        end

        % Menu selected function: VerticalBiomassDistributionMenu
        function VerticalBiomassDistributionMenuSelected(app, event)
               plot_title = ({ ...
                [string(app.PlantTypeDropDown.Value) + " " + string(app.DateDatePicker.Value)+ " " + string(app.Site_Data.HR) + " hrs"] ...
                ['  Water Depth = ',num2str(app.Site_Data.depth), ' m  Canopy Ht =',num2str(app.Biodis_Data.canopy_ht),' m  LAI = ',num2str(app.Biodis_Data.laitot), ]...
                ["Current Speed = " + num2str(app.Site_Data.curvel) + " m s^{-1}    Horizontal LAI = "+ num2str(app.Biodis_Data.sumlap)]...
                });
                      
            figure('Name', 'Vertical Biomass Distribution')
            sgtitle(plot_title)

            subplot(3,1,1)
            plot(app.Biodis_Data.rel_biomas,app.Biodis_Data.ht, 'k','LineWidth', 1);
            xlabel('Biomass (rel)')
            ylabel('Canopy Ht (m)')
            xlim([0 inf])

            subplot(3,1,2)
            plot(app.Biodis_Data.lai,app.Biodis_Data.ht, 'k','LineWidth', 1);
            xlabel({'LAI','(m^2 leaf m^{-2} seafloor)'})
            ylabel('Canopy Ht (m)')
            xlim([0 inf])

            subplot(3,1,3)
            plot(app.Biodis_Data.lap,app.Biodis_Data.ht, 'k','LineWidth', 1);
            xlabel({'Horizontally Projected LAI';'(m^2 leaf m^{-2} seafloor)'})
            ylabel('Canopy Ht (m)')
            xlim([0 inf])

        end

        % Menu selected function: CanopyProductionPlotsMenu
        function CanopyProductionPlotsMenuSelected(app, event)
                plot_title = ({ ...
                [string(app.PlantTypeDropDown.Value) + " " + string(app.DateDatePicker.Value)+ " " + string(app.Site_Data.HR) + " hrs"] ...
                ['  Water Depth = ',num2str(app.Site_Data.depth), ' m  Canopy Ht =',num2str(app.Biodis_Data.canopy_ht),' m  LAI = ',num2str(app.Biodis_Data.laitot), ]...
                ["Current Speed = " + num2str(app.Site_Data.curvel) + " m s^{-1}    Horizontal LAI = "+ num2str(app.Biodis_Data.sumlap)]...
                });
            
            figure ('Name', 'Canopy Production')
            sgtitle(plot_title)

            m = 3;  % Define # of rows for multiple plots
            n = 1;  % Define number of columns for multiple plots
            o = 1;  % Define first subplot

            subplot(m,n,o)
            plot(app.EdEu_Data.eow, app.Prod_Data.Biomass_normalized_P_vs_E, 'k', 'LineWidth', 1)
            xlabel({'Incident E(z)'; '(\mumol m^{-2} s^{-1})'})
            ylabel({'Biomass normalized \itP\rm'; '(\mumol C m^{-2} leaf s^{-1})'})
            xlim([0 inf])
            ylim([0 1.2*max(app.Prod_Data.Biomass_normalized_P_vs_E)])
            o = o + 1;
            
            subplot(m,n,o)
            plot3(app.Prod_Data.piz_per_leaf, app.Prod_Data.Biomass_normalized_P_vs_E, app.Biodis_Data.ht, 'k', 'LineWidth', 1)
            xlabel({'\Pi(z)'; '(\mumol m^{-2} leaf s^{-1})'})
            ylabel({'Biomass normalized \itP\rm'; '(\mumol C m^{-2} leaf s^{-1})'})
            zlabel('Height (m)')
            xlim([0 inf])
            ylim([0 inf])
            o = o + 1;

            if app.Site_Data.HR == 12
                subplot(m,n,o)
                plot(app.Prod_Data.Daily_Biomass_normalized_P, app.Biodis_Data.ht, 'k', 'LineWidth', 1)
                xlabel('Daily P (\mumol C m^{-2} leaf day^{-1})')
                ylabel('Height (m)')
                xlim([0 inf])
                ylim([0 inf])
            end

        end

        % Menu selected function: CIEColorSimulationsofCanopyRandRrsMenu
        function CIEColorSimulationsofCanopyRandRrsMenuSelected(app, event)
            app.CIE_Data = CIE_color_plot(app.EdEu_Data, app.Biodis_Data);
             plot_title = ({ ...
                [string(app.PlantTypeDropDown.Value) + " " + string(app.DateDatePicker.Value)+ " " + string(app.Site_Data.HR) + " hrs"] ...
                ['  Water Depth = ',num2str(app.Site_Data.depth), ' m  Canopy Ht =',num2str(app.Biodis_Data.canopy_ht),' m  LAI = ',num2str(app.Biodis_Data.laitot), ]...
                ["Chl \ita\rm = " + num2str(app.WQ_Params.CHL_A) + " mg m^{-3}   TSM =" + num2str(app.WQ_Params.TSM) + " mg L^{-1} \ita\rm_g(440) = " + num2str(app.WQ_Params.AG_440) + " m^{-1}"]...
                });
            
            figure ('Name', 'CIE Ocean Color Simulation')
            sgtitle(plot_title)
            %sgtitle(file)
            subplot(2,1,1)
            xdisp = [0 1 1 0];      % set the display area for fill (x direction)
            ydisp = [0 0 1 1];      % set the display area for fill (y direction)
            fill(xdisp,ydisp,app.CIE_Data.matRGB_Rrs)
            % fill(xdisp,ydisp,c)   % make the field black
            xlim([0 1]);
            ylim([0 1]);
            set(gca,'xtick',[])
            set(gca,'xticklabel',[])
            set(gca,'ytick',[])
            set(gca,'yticklabel',[])
            title ('\itR\rm_{rs} estimated as RGB')
            
            % subplot(3,1,2)          % Commented out because R and Rrs give the same results
            % xdisp = [0 1 1 0];      % set the display area for fill (x direction)
            % ydisp = [0 0 1 1];      % set the display area for fill (y direction)
            % fill(xdisp,ydisp,app.CIE_Data.matRGB_Rsfc)
            % % fill(xdisp,ydisp,c)   % make the field black
            % xlim([0 1]);
            % ylim([0 1]);
            % set(gca,'xtick',[])
            % set(gca,'xticklabel',[])
            % set(gca,'ytick',[])
            % set(gca,'yticklabel',[])
            % title ('Surface \itR\rm estimated as RGB')
            
            subplot(2,1,2)
            xdisp = [0 1 1 0];      % set the display area for fill (x direction)
            ydisp = [0 0 1 1];      % set the display area for fill (y direction)
            fill(xdisp,ydisp,app.CIE_Data.matRGB_Rcanopy)
            % fill(xdisp,ydisp,c)   % make the field black
            xlim([0 1]);
            ylim([0 1]);
            set(gca,'xtick',[])
            set(gca,'xticklabel',[])
            set(gca,'ytick',[])
            set(gca,'yticklabel',[])
            title ('Canopy \itR\rm estimated as RGB')


        end

        % Menu selected function: ClosePlotsMenu
        function ClosePlotsMenuSelected(app, event)
            close all
        end

        % Value changed function: LatitudedecEditField
        function LatitudedecEditFieldValueChanged(app, event)
           app.Site_Data.XLAT = app.LatitudedecEditField.Value;
           [app.E0_sfc_spectra, app.E0_sfc_PAR_mubar] = E0_surface_Ver3_for_app(app.Site_Data, app.Atm_Const, app.Atm_IOPs);
           app.EdEu_Data = EdEu_water_v3_for_app(app.Site_Data, app.IOP_Spectra, app.WQ_Params, app.Plant_Arch, app.Sed_Ref, app.LOPs, app.E0_sfc_spectra, app.E0_sfc_PAR_mubar, app.abKd_Spectra, app.Biodis_Data, app.PlantTypeDropDown, app.SubstrateTypeDropDown);
           
           % Compute instantaneous and daily metabolic rates
            app.Prod_Data = produce_v301_for_app(app.WQ_Params, app.Site_Data, app.Plant_Arch, app.Photo_Params, app.Biodis_Data, app.EdEu_Data, app.E0_sfc_PAR_mubar, app.PlantTypeDropDown);

            % Populate the Plant Productivity and Metabolism Panel
            app.DailyPhotoperiodhEditField.Value = app.E0_sfc_PAR_mubar.daylen;
            app.PumolCmleafsEditField_2.Value = app.Prod_Data.Pm;
            app.PumolCmleafsEditField.Value = app.Prod_Data.PE;
            app.PPEditField.Value = app.Prod_Data.PE / app.Prod_Data.Pm;
            app.InstantaneousPumolCmleafsEditField.Value = app.Prod_Data.Mean_Biomass_normalized_P_vs_E;
            app.InstantaneousPumolCshootsEditField.Value = app.Prod_Data.P_now_per_shoot;
            app.InstantaneousCanopyPPEditField.Value = app.Prod_Data.P_now_over_PE;
            app.InstantaneousLeafPREditField.Value = app.Prod_Data.Instantaneous_Leaf_P_to_R;
            app.InstantaneousWholePlantPREditField.Value = app.Prod_Data.Instantaneous_Shoot_P_to_R;
            app.DailyWholePlantPREditField.Value = app.Prod_Data.Daily_P_to_R;
            app.delCEditField.Value = app.Prod_Data.del13C_new;
            app.DailyHRequirementhdEditField.Value = app.Prod_Data.Daily_Hsat_Requirement;

        end

        % Value changed function: LongitudedecEditField
        function LongitudedecEditFieldValueChanged(app, event)
            app.Site_Data.XLON = app.LongitudedecEditField.Value;
            [app.E0_sfc_spectra, app.E0_sfc_PAR_mubar] = E0_surface_Ver3_for_app(app.Site_Data, app.Atm_Const, app.Atm_IOPs);
            app.EdEu_Data = EdEu_water_v3_for_app(app.Site_Data, app.IOP_Spectra, app.WQ_Params, app.Plant_Arch, app.Sed_Ref, app.LOPs, app.E0_sfc_spectra, app.E0_sfc_PAR_mubar, app.abKd_Spectra, app.Biodis_Data, app.PlantTypeDropDown, app.SubstrateTypeDropDown);
           
            % Compute instantaneous and daily metabolic rates
            app.Prod_Data = produce_v301_for_app(app.WQ_Params, app.Site_Data, app.Plant_Arch, app.Photo_Params, app.Biodis_Data, app.EdEu_Data, app.E0_sfc_PAR_mubar, app.PlantTypeDropDown);

            % Populate the Plant Productivity and Metabolism Panel
            app.DailyPhotoperiodhEditField.Value = app.E0_sfc_PAR_mubar.daylen;
            app.PumolCmleafsEditField_2.Value = app.Prod_Data.Pm;
            app.PumolCmleafsEditField.Value = app.Prod_Data.PE;
            app.PPEditField.Value = app.Prod_Data.PE / app.Prod_Data.Pm;
            app.InstantaneousPumolCmleafsEditField.Value = app.Prod_Data.Mean_Biomass_normalized_P_vs_E;
            app.InstantaneousPumolCshootsEditField.Value = app.Prod_Data.P_now_per_shoot;
            app.InstantaneousCanopyPPEditField.Value = app.Prod_Data.P_now_over_PE;
            app.InstantaneousLeafPREditField.Value = app.Prod_Data.Instantaneous_Leaf_P_to_R;
            app.InstantaneousWholePlantPREditField.Value = app.Prod_Data.Instantaneous_Shoot_P_to_R;
            app.DailyWholePlantPREditField.Value = app.Prod_Data.Daily_P_to_R;
            app.delCEditField.Value = app.Prod_Data.del13C_new;
            app.DailyHRequirementhdEditField.Value = app.Prod_Data.Daily_Hsat_Requirement;

        end

        % Value changed function: DateDatePicker
        function DateDatePickerValueChanged(app, event)
            app.Site_Data.JD = day(app.DateDatePicker.Value,'dayofyear');
           [app.E0_sfc_spectra, app.E0_sfc_PAR_mubar] = E0_surface_Ver3_for_app(app.Site_Data, app.Atm_Const, app.Atm_IOPs);
           app.EdEu_Data = EdEu_water_v3_for_app(app.Site_Data, app.IOP_Spectra, app.WQ_Params, app.Plant_Arch, app.Sed_Ref, app.LOPs, app.E0_sfc_spectra, app.E0_sfc_PAR_mubar, app.abKd_Spectra, app.Biodis_Data, app.PlantTypeDropDown, app.SubstrateTypeDropDown);
           
           % Compute instantaneous and daily metabolic rates
            app.Prod_Data = produce_v301_for_app(app.WQ_Params, app.Site_Data, app.Plant_Arch, app.Photo_Params, app.Biodis_Data, app.EdEu_Data, app.E0_sfc_PAR_mubar, app.PlantTypeDropDown);

            % Populate the Plant Productivity and Metabolism Panel
            app.DailyPhotoperiodhEditField.Value = app.E0_sfc_PAR_mubar.daylen;
            app.PumolCmleafsEditField_2.Value = app.Prod_Data.Pm;
            app.PumolCmleafsEditField.Value = app.Prod_Data.PE;
            app.PPEditField.Value = app.Prod_Data.PE / app.Prod_Data.Pm;
            app.InstantaneousPumolCmleafsEditField.Value = app.Prod_Data.Mean_Biomass_normalized_P_vs_E;
            app.InstantaneousPumolCshootsEditField.Value = app.Prod_Data.P_now_per_shoot;
            app.InstantaneousCanopyPPEditField.Value = app.Prod_Data.P_now_over_PE;
            app.InstantaneousLeafPREditField.Value = app.Prod_Data.Instantaneous_Leaf_P_to_R;
            app.InstantaneousWholePlantPREditField.Value = app.Prod_Data.Instantaneous_Shoot_P_to_R;
            app.DailyWholePlantPREditField.Value = app.Prod_Data.Daily_P_to_R;
            app.delCEditField.Value = app.Prod_Data.del13C_new;
            app.DailyHRequirementhdEditField.Value = app.Prod_Data.Daily_Hsat_Requirement;

        end

        % Value changed function: TimeofDay0to24hEditField
        function TimeofDay0to24hEditFieldValueChanged(app, event)
            app.Site_Data.HR = app.TimeofDay0to24hEditField.Value;
            [app.E0_sfc_spectra, app.E0_sfc_PAR_mubar] = E0_surface_Ver3_for_app(app.Site_Data, app.Atm_Const, app.Atm_IOPs);
            app.EdEu_Data = EdEu_water_v3_for_app(app.Site_Data, app.IOP_Spectra, app.WQ_Params, app.Plant_Arch, app.Sed_Ref, app.LOPs, app.E0_sfc_spectra, app.E0_sfc_PAR_mubar, app.abKd_Spectra, app.Biodis_Data, app.PlantTypeDropDown, app.SubstrateTypeDropDown);
           
           % Compute instantaneous and daily metabolic rates
            app.Prod_Data = produce_v301_for_app(app.WQ_Params, app.Site_Data, app.Plant_Arch, app.Photo_Params, app.Biodis_Data, app.EdEu_Data, app.E0_sfc_PAR_mubar, app.PlantTypeDropDown);

            % Populate the Plant Productivity and Metabolism Panel
            app.PumolCmleafsEditField_2.Value = app.Prod_Data.Pm;
            app.PumolCmleafsEditField.Value = app.Prod_Data.PE;
            app.PPEditField.Value = app.Prod_Data.PE / app.Prod_Data.Pm;
            app.InstantaneousPumolCmleafsEditField.Value = app.Prod_Data.Mean_Biomass_normalized_P_vs_E;
            app.InstantaneousPumolCshootsEditField.Value = app.Prod_Data.P_now_per_shoot;
            app.InstantaneousCanopyPPEditField.Value = app.Prod_Data.P_now_over_PE;
            app.InstantaneousLeafPREditField.Value = app.Prod_Data.Instantaneous_Leaf_P_to_R;
            app.InstantaneousWholePlantPREditField.Value = app.Prod_Data.Instantaneous_Shoot_P_to_R;
            app.DailyWholePlantPREditField.Value = app.Prod_Data.Daily_P_to_R;
            app.delCEditField.Value = app.Prod_Data.del13C_new;
            app.DailyHRequirementhdEditField.Value = app.Prod_Data.Daily_Hsat_Requirement;
     

        end

        % Value changed function: DepthmEditField
        function DepthmEditFieldValueChanged(app, event)
            if app.DepthmEditField.Value < 0.1
                app.DepthmEditField.Value = 0.1;
            end
            app.Site_Data.depth = app.DepthmEditField.Value;

            col = 1;
            if app.PlantTypeDropDown.Value == "Zostera"
                app.Site_Data.lop = 'zostera';
                row = 1;
            elseif app.PlantTypeDropDown.Value == "Thalassia"
                app.Site_Data.lop = 'thalassia';
                row = 2;
            elseif app.PlantTypeDropDown.Value == "Syringodium"
                app.Site_Data.op = 'syringodium';
                row = 3;
            elseif app.PlantTypeDropDown.Value == "Phyllospadix"
                app.Site_Data.lop = 'phyllospadix';
                row = 4;
            elseif app.PlantTypeDropDown.Value == "Posidonia"
                app.Site_Data.lop = 'posidonia';
                row = 5;
            else
                app.Site_Data.lop = 'ruppia';
                row = 6;
            end 

            % Update DIC values

            [Param1, Param2] = Get_CO2sys_Drivers(app.WQ_Params);   
            app.CO2SYS_Data = CO2SYS_for_app(Param1,Param2,app.WQ_Params.PAR1TYPE,app.WQ_Params.PAR2TYPE,app.WQ_Params.Sal,app.WQ_Params.Temp,app.WQ_Params.Temp,0,app.Site_Data.depth,app.WQ_Params.SiO4,app.WQ_Params.PO4,app.WQ_Params.NH4,app.WQ_Params.H2S,app.WQ_Params.pH_Scale,app.WQ_Params.K1K2,app.WQ_Params.KHSO4,app.WQ_Params.KHF,app.WQ_Params.Borate);
            app.pHEditField.Value = app.CO2SYS_Data.pHout;
            app.WQ_Params.pH = app.CO2SYS_Data.pHout;
            app.pCOinWaterAtmEditField.Value = app.CO2SYS_Data.pCO2out;
            app.WQ_Params.pCO2_Water = app.CO2SYS_Data.pCO2out;
            app.COEditField.Value = app.CO2SYS_Data.CO2out;
            app.WQ_Params.CO2 = app.CO2SYS_Data.CO2out;
            app.HCOEditField.Value = app.CO2SYS_Data.HCO3out;
            app.WQ_Params.HCO3 = app.CO2SYS_Data.HCO3out;
            app.COEditField_2.Value = app.CO2SYS_Data.CO3out;
            app.WQ_Params.CO3 = app.CO2SYS_Data.CO3out;
            app.TotalDICEditField.Value = app.CO2SYS_Data.TCO2;
            app.WQ_Params.TCO2 = app.CO2SYS_Data.TCO2;

            % Update gas saturation values
            app.Gas_Sat = Gas_Solubility_Model(app.WQ_Params.Temp, app.WQ_Params.Sal, app.Site_Data.depth, app.WQ_Params.pCO2_Air);
            app.Gas_Sat.O2_Depth = app.Gas_Sat.O2_1atm * (app.Site_Data.depth/10+1);
            app.OAirSatatDepthEditField.Value = 100 * app.OEditField.Value/app.Gas_Sat.O2_Depth;
            app.pCOAirSatatSfcEditField.Value = 100 * app.pCOinWaterAtmEditField.Value/app.Gas_Sat.pCO2_1atm;
            app.pCOAirSatatDepthEditField.Value = 100 * app.pCOinWaterAtmEditField.Value/app.Gas_Sat.pCO2_Depth;

            % Update biomass distribution data
            [app.Biodis_Data, app.Plant_Arch] = biodis_v301_for_app(app.Site_Data, app.Plant_Arch, app.PlantTypeDropDown);         %Call Biodis function to get new LAI
            app.EdEu_Data = EdEu_water_v3_for_app(app.Site_Data, app.IOP_Spectra, app.WQ_Params, app.Plant_Arch, app.Sed_Ref, app.LOPs, app.E0_sfc_spectra, app.E0_sfc_PAR_mubar, app.abKd_Spectra, app.Biodis_Data, app.PlantTypeDropDown, app.SubstrateTypeDropDown);

            % Compute instantaneous and daily metabolic rates
            app.Prod_Data = produce_v301_for_app(app.WQ_Params, app.Site_Data, app.Plant_Arch, app.Photo_Params, app.Biodis_Data, app.EdEu_Data, app.E0_sfc_PAR_mubar, app.PlantTypeDropDown);

            % Populate the Plant Productivity and Metabolism Panel
            app.PumolCmleafsEditField_2.Value = app.Prod_Data.Pm;
            app.PumolCmleafsEditField.Value = app.Prod_Data.PE;
            if app.Prod_Data.PE > 0 || app.Prod_Data.Pm > 0
                app.PPEditField.Value = app.Prod_Data.PE / app.Prod_Data.Pm;
            end
            app.InstantaneousPumolCmleafsEditField.Value = app.Prod_Data.Mean_Biomass_normalized_P_vs_E;
            app.InstantaneousPumolCshootsEditField.Value = app.Prod_Data.P_now_per_shoot;
            app.InstantaneousCanopyPPEditField.Value = app.Prod_Data.P_now_over_PE;
            app.InstantaneousLeafPREditField.Value = app.Prod_Data.Instantaneous_Leaf_P_to_R;
            app.InstantaneousWholePlantPREditField.Value = app.Prod_Data.Instantaneous_Shoot_P_to_R;
            app.DailyWholePlantPREditField.Value = app.Prod_Data.Daily_P_to_R;
            app.delCEditField.Value = app.Prod_Data.del13C_new;
            app.DailyHRequirementhdEditField.Value = app.Prod_Data.Daily_Hsat_Requirement;

        end

        % Value changed function: CurrentSpeedcmsEditField
        function CurrentSpeedcmsEditFieldValueChanged(app, event)
            app.Site_Data.curvel = app.CurrentSpeedcmsEditField.Value;

            col = 1;
            if app.PlantTypeDropDown.Value == "Zostera"
                app.Site_Data.lop = 'zostera';
                row = 1;
            elseif app.PlantTypeDropDown.Value == "Thalassia"
                app.Site_Data.lop = 'thalassia';
                row = 2;
            elseif app.PlantTypeDropDown.Value == "Syringodium"
                app.Site_Data.op = 'syringodium';
                row = 3;
            elseif app.PlantTypeDropDown.Value == "Phyllospadix"
                app.Site_Data.lop = 'phyllospadix';
                row = 4;
            elseif app.PlantTypeDropDown.Value == "Posidonia"
                app.Site_Data.lop = 'posidonia';
                row = 5;
            else
                app.Site_Data.lop = 'ruppia';
                row = 6;
            end 

            % Compute the new biomass distribution
            [app.Biodis_Data, app.Plant_Arch] = biodis_v301_for_app(app.Site_Data, app.Plant_Arch, app.PlantTypeDropDown); 
            
            % Compute the new irradiance distributions
            app.EdEu_Data = EdEu_water_v3_for_app(app.Site_Data, app.IOP_Spectra, app.WQ_Params, app.Plant_Arch, app.Sed_Ref, app.LOPs, app.E0_sfc_spectra, app.E0_sfc_PAR_mubar, app.abKd_Spectra, app.Biodis_Data, app.PlantTypeDropDown, app.SubstrateTypeDropDown);
           
            % Compute instantaneous and daily metabolic rates
            app.Prod_Data = produce_v301_for_app(app.WQ_Params, app.Site_Data, app.Plant_Arch, app.Photo_Params, app.Biodis_Data, app.EdEu_Data, app.E0_sfc_PAR_mubar, app.PlantTypeDropDown);

            % Populate the Plant Productivity and Metabolism Panel
            app.CanopyAnglefromverticalEditField.Value = app.Plant_Arch.beta0(row,col);
            app.PumolCmleafsEditField_2.Value = app.Prod_Data.Pm;
            app.PumolCmleafsEditField.Value = app.Prod_Data.PE;
            app.PPEditField.Value = app.Prod_Data.PE / app.Prod_Data.Pm;
            app.InstantaneousPumolCmleafsEditField.Value = app.Prod_Data.Mean_Biomass_normalized_P_vs_E;
            app.InstantaneousPumolCshootsEditField.Value = app.Prod_Data.P_now_per_shoot;
            app.InstantaneousCanopyPPEditField.Value = app.Prod_Data.P_now_over_PE;
            app.InstantaneousLeafPREditField.Value = app.Prod_Data.Instantaneous_Leaf_P_to_R;
            app.InstantaneousWholePlantPREditField.Value = app.Prod_Data.Instantaneous_Shoot_P_to_R;
            app.DailyWholePlantPREditField.Value = app.Prod_Data.Daily_P_to_R;
            app.delCEditField.Value = app.Prod_Data.del13C_new;
            app.DailyHRequirementhdEditField.Value = app.Prod_Data.Daily_Hsat_Requirement;

        end

        % Value changed function: SubstrateTypeDropDown
        function SubstrateTypeDropDownValueChanged(app, event)
            if app.SubstrateTypeDropDown.Value == "Dark Carbonate"
                app.Site_Data.sed_type = 'Dark_Carbonate';
            elseif app.SubstrateTypeDropDown.Value == "Bright Carbonate"
                app.Site_Data.sed_type = 'Bright_Carbonate';
            elseif app.SubstrateTypeDropDown.Value == "Silica Sand"
                app.Site_Data.sed_type = 'Silica_Sand';
            else 
                app.Site_Data.sed_type = 'Silica_Mud';
            end
            app.EdEu_Data = EdEu_water_v3_for_app(app.Site_Data, app.IOP_Spectra, app.WQ_Params, app.Plant_Arch, app.Sed_Ref, app.LOPs, app.E0_sfc_spectra, app.E0_sfc_PAR_mubar, app.abKd_Spectra, app.Biodis_Data, app.PlantTypeDropDown, app.SubstrateTypeDropDown);
           
            % Compute instantaneous and daily metabolic rates
            app.Prod_Data = produce_v301_for_app(app.WQ_Params, app.Site_Data, app.Plant_Arch, app.Photo_Params, app.Biodis_Data, app.EdEu_Data, app.E0_sfc_PAR_mubar, app.PlantTypeDropDown);

            % Populate the Plant Productivity and Metabolism Panel
            app.PumolCmleafsEditField_2.Value = app.Prod_Data.Pm;
            app.PumolCmleafsEditField.Value = app.Prod_Data.PE;
            app.PPEditField.Value = app.Prod_Data.PE / app.Prod_Data.Pm;
            app.InstantaneousPumolCmleafsEditField.Value = app.Prod_Data.Mean_Biomass_normalized_P_vs_E;
            app.InstantaneousPumolCshootsEditField.Value = app.Prod_Data.P_now_per_shoot;
            app.InstantaneousCanopyPPEditField.Value = app.Prod_Data.P_now_over_PE;
            app.InstantaneousLeafPREditField.Value = app.Prod_Data.Instantaneous_Leaf_P_to_R;
            app.InstantaneousWholePlantPREditField.Value = app.Prod_Data.Instantaneous_Shoot_P_to_R;
            app.DailyWholePlantPREditField.Value = app.Prod_Data.Daily_P_to_R;
            app.delCEditField.Value = app.Prod_Data.del13C_new;
            app.DailyHRequirementhdEditField.Value = app.Prod_Data.Daily_Hsat_Requirement;
 

        end

        % Value changed function: PlantTypeDropDown
        function PlantTypeDropDownValueChanged(app, event)
            col = 1;
            if app.PlantTypeDropDown.Value == "Zostera"
                app.Site_Data.lop = 'zostera';
                row = 1;
            elseif app.PlantTypeDropDown.Value == "Thalassia"
                app.Site_Data.lop = 'thalassia';
                row = 2;
            elseif app.PlantTypeDropDown.Value == "Syringodium"
                app.Site_Data.op = 'syringodium';
                row = 3;
            elseif app.PlantTypeDropDown.Value == "Phyllospadix"
                app.Site_Data.lop = 'phyllospadix';
                row = 4;
            elseif app.PlantTypeDropDown.Value == "Posidonia"
                app.Site_Data.lop = 'posidonia';
                row = 5;
            else
                app.Site_Data.lop = 'ruppia';
                row = 6;
            end 
            % Calculate submerged plant biomass distribution
            [app.Biodis_Data, app.Plant_Arch] = biodis_v301_for_app(app.Site_Data, app.Plant_Arch, app.PlantTypeDropDown);

            % Update the plant morphology parameters
            app.MaxHeightmEditField.Value = app.Plant_Arch.maxht(row, col);
            app.ShootLeafAreamLeafshootEditField.Value = app.Plant_Arch.areash(row,col);
            app.ShootDensityshootspermEditField.Value = app.Plant_Arch.density(row,col);
            app.LAIEditField.Value = app.Biodis_Data.laitot;                          % Get total LAI
            app.ShootRhizomeRootEditField.Value = app.Plant_Arch.shoot_frac(row,col)/(app.Plant_Arch.rhiz_frac(row,col)+app.Plant_Arch.root_frac(row,col));
            app.RhizRootEditField.Value = app.Plant_Arch.rhiz_frac(row,col)/app.Plant_Arch.root_frac(row,col);
            app.EpiphyteLoadmgcmEditField.Value = app.Plant_Arch.epi(row,col);

            % Calculate submarine irradiances and Pi(z)
            app.EdEu_Data = EdEu_water_v3_for_app(app.Site_Data, app.IOP_Spectra, app.WQ_Params, app.Plant_Arch, app.Sed_Ref, app.LOPs, app.E0_sfc_spectra, app.E0_sfc_PAR_mubar, app.abKd_Spectra, app.Biodis_Data, app.PlantTypeDropDown, app.SubstrateTypeDropDown);
           
            % Compute instantaneous and daily metabolic rates
            app.Prod_Data = produce_v301_for_app(app.WQ_Params, app.Site_Data, app.Plant_Arch, app.Photo_Params, app.Biodis_Data, app.EdEu_Data, app.E0_sfc_PAR_mubar, app.PlantTypeDropDown);

            % Populate the Plant Productivity and Metabolism Panel
            app.PumolCmleafsEditField_2.Value = app.Prod_Data.Pm;
            app.PumolCmleafsEditField.Value = app.Prod_Data.PE;
            if app.Prod_Data.PE > 0 || app.Prod_Data.Pm > 0
                app.PPEditField.Value = app.Prod_Data.PE / app.Prod_Data.Pm;
            end
            app.InstantaneousPumolCmleafsEditField.Value = app.Prod_Data.Mean_Biomass_normalized_P_vs_E;
            app.InstantaneousPumolCshootsEditField.Value = app.Prod_Data.P_now_per_shoot;
            app.InstantaneousCanopyPPEditField.Value = app.Prod_Data.P_now_over_PE;
            app.InstantaneousLeafPREditField.Value = app.Prod_Data.Instantaneous_Leaf_P_to_R;
            app.InstantaneousWholePlantPREditField.Value = app.Prod_Data.Instantaneous_Shoot_P_to_R;
            app.DailyWholePlantPREditField.Value = app.Prod_Data.Daily_P_to_R;
            app.delCEditField.Value = app.Prod_Data.del13C_new;
            app.DailyHRequirementhdEditField.Value = app.Prod_Data.Daily_Hsat_Requirement;

        end

        % Value changed function: MaxHeightmEditField
        function MaxHeightmEditFieldValueChanged(app, event)
            % Identify the plant species for new maxht  
            col = 1;
            if app.PlantTypeDropDown.Value == "Zostera"
                row = 1;
            elseif app.PlantTypeDropDown.Value == "Thalassia"
                row = 2;
            elseif app.PlantTypeDropDown.Value == "Syringodium"
                row = 3;
            elseif app.PlantTypeDropDown.Value == "Phyllospadix"
                row = 4;
            elseif app.PlantTypeDropDown.Value == "Posidonia"
                row = 5;
            else 
                row = 6;        %Ruppia
            end
        
            app.Plant_Arch.maxht(row, col) = app.MaxHeightmEditField.Value;                           % New maxht value in Plant_Arch matrix
            
            [app.Biodis_Data, app.Plant_Arch] = biodis_v301_for_app(app.Site_Data, app.Plant_Arch, app.PlantTypeDropDown);      % New biomass distribution
            app.ShootLeafAreamLeafshootEditField.Value = app.Plant_Arch.areash(row, col);                       % New Leaf Area per shoot
            app.LAIEditField.Value = app.Biodis_Data.sumlai;                                          % New total LAI

            % New in-water irradiance spectra
            app.EdEu_Data = EdEu_water_v3_for_app(app.Site_Data, app.IOP_Spectra, app.WQ_Params, app.Plant_Arch, app.Sed_Ref, app.LOPs, app.E0_sfc_spectra, app.E0_sfc_PAR_mubar, app.abKd_Spectra, app.Biodis_Data, app.PlantTypeDropDown, app.SubstrateTypeDropDown);
           
            % New instantaneous and daily metabolic rates
            app.Prod_Data = produce_v301_for_app(app.WQ_Params, app.Site_Data, app.Plant_Arch, app.Photo_Params, app.Biodis_Data, app.EdEu_Data, app.E0_sfc_PAR_mubar, app.PlantTypeDropDown);

            % New Plant Productivity and Metabolism values in display panel
            app.PumolCmleafsEditField_2.Value = app.Prod_Data.Pm;
            app.PumolCmleafsEditField.Value = app.Prod_Data.PE;
            app.PPEditField.Value = app.Prod_Data.PE / app.Prod_Data.Pm;
            app.InstantaneousPumolCmleafsEditField.Value = app.Prod_Data.Mean_Biomass_normalized_P_vs_E;
            app.InstantaneousPumolCshootsEditField.Value = app.Prod_Data.P_now_per_shoot;
            app.InstantaneousCanopyPPEditField.Value = app.Prod_Data.P_now_over_PE;
            app.InstantaneousLeafPREditField.Value = app.Prod_Data.Instantaneous_Leaf_P_to_R;
            app.InstantaneousWholePlantPREditField.Value = app.Prod_Data.Instantaneous_Shoot_P_to_R;
            app.DailyWholePlantPREditField.Value = app.Prod_Data.Daily_P_to_R;
            app.delCEditField.Value = app.Prod_Data.del13C_new;
            app.DailyHRequirementhdEditField.Value = app.Prod_Data.Daily_Hsat_Requirement;
                        
        end

        % Value changed function: ShootLeafAreamLeafshootEditField
        function ShootLeafAreamLeafshootEditFieldValueChanged(app, event)
            % Identify the plant species for new shoot leaf area  
            col = 1;
            if app.PlantTypeDropDown.Value == "Zostera"
                row = 1;
            elseif app.PlantTypeDropDown.Value == "Thalassia"
                row = 2;
            elseif app.PlantTypeDropDown.Value == "Syringodium"
                row = 3;
            elseif app.PlantTypeDropDown.Value == "Phyllospadix"
                row = 4;
            elseif app.PlantTypeDropDown.Value == "Posidonia"
                row = 5;
            else 
                row = 6;        %Ruppia
            end
            
            app.Plant_Arch.areash(row, col) = app.ShootLeafAreamLeafshootEditField.Value;               % New areash value in Plant_Arch matrix
            [app.Biodis_Data, app.Plant_Arch] = biodis_v301_for_app(app.Site_Data,app.Plant_Arch, app.PlantTypeDropDown);        % New biomass distribution          
            app.LAIEditField.Value = app.Biodis_Data.laitot;                                            % New total LAI

            % New in-water irradiance spectra
            app.EdEu_Data = EdEu_water_v3_for_app(app.Site_Data, app.IOP_Spectra, app.WQ_Params, app.Plant_Arch, app.Sed_Ref, app.LOPs, app.E0_sfc_spectra, app.E0_sfc_PAR_mubar, app.abKd_Spectra, app.Biodis_Data, app.PlantTypeDropDown, app.SubstrateTypeDropDown); % Update irradiance spectra
           
            % New instantaneous and daily metabolic rates
            app.Prod_Data = produce_v301_for_app(app.WQ_Params, app.Site_Data, app.Plant_Arch, app.Photo_Params, app.Biodis_Data, app.EdEu_Data, app.E0_sfc_PAR_mubar, app.PlantTypeDropDown);

            % New Plant Productivity and Metabolism values in display panel
            app.PumolCmleafsEditField_2.Value = app.Prod_Data.Pm;
            app.PumolCmleafsEditField.Value = app.Prod_Data.PE;
            app.PPEditField.Value = app.Prod_Data.PE / app.Prod_Data.Pm;
            app.InstantaneousPumolCmleafsEditField.Value = app.Prod_Data.Mean_Biomass_normalized_P_vs_E;
            app.InstantaneousPumolCshootsEditField.Value = app.Prod_Data.P_now_per_shoot;
            app.InstantaneousCanopyPPEditField.Value = app.Prod_Data.P_now_over_PE;
            app.InstantaneousLeafPREditField.Value = app.Prod_Data.Instantaneous_Leaf_P_to_R;
            app.InstantaneousWholePlantPREditField.Value = app.Prod_Data.Instantaneous_Shoot_P_to_R;
            app.DailyWholePlantPREditField.Value = app.Prod_Data.Daily_P_to_R;
            app.delCEditField.Value = app.Prod_Data.del13C_new;
            app.DailyHRequirementhdEditField.Value = app.Prod_Data.Daily_Hsat_Requirement;

        end

        % Value changed function: ShootDensityshootspermEditField
        function ShootDensityshootspermEditFieldValueChanged(app, event)
                        % Identify the plant species for new shoot leaf area  
            col = 1;
            if app.PlantTypeDropDown.Value == "Zostera"
                row = 1;
            elseif app.PlantTypeDropDown.Value == "Thalassia"
                row = 2;
            elseif app.PlantTypeDropDown.Value == "Syringodium"
                row = 3;
            elseif app.PlantTypeDropDown.Value == "Phyllospadix"
                row = 4;
            elseif app.PlantTypeDropDown.Value == "Posidonia"
                row = 5;
            else 
                row = 6;        %Ruppia
            end
            
            app.Plant_Arch.density(row,col) = app.ShootDensityshootspermEditField.Value;
            [app.Biodis_Data, app.Plant_Arch] = biodis_v301_for_app(app.Site_Data,app.Plant_Arch, app.PlantTypeDropDown);         %Call Biodis function to get new LAI
            app.LAIEditField.Value = app.Biodis_Data.laitot;                          % Get total LAI
            app.EdEu_Data = EdEu_water_v3_for_app(app.Site_Data, app.IOP_Spectra, app.WQ_Params, app.Plant_Arch, app.Sed_Ref, app.LOPs, app.E0_sfc_spectra, app.E0_sfc_PAR_mubar, app.abKd_Spectra, app.Biodis_Data, app.PlantTypeDropDown, app.SubstrateTypeDropDown);
           
            % Compute instantaneous and daily metabolic rates
            app.Prod_Data = produce_v301_for_app(app.WQ_Params, app.Site_Data, app.Plant_Arch, app.Photo_Params, app.Biodis_Data, app.EdEu_Data, app.E0_sfc_PAR_mubar, app.PlantTypeDropDown);

            % Populate the Plant Productivity and Metabolism Panel
            app.PumolCmleafsEditField_2.Value = app.Prod_Data.Pm;
            app.PumolCmleafsEditField.Value = app.Prod_Data.PE;
            app.PPEditField.Value = app.Prod_Data.PE / app.Prod_Data.Pm;
            app.InstantaneousPumolCmleafsEditField.Value = app.Prod_Data.Mean_Biomass_normalized_P_vs_E;
            app.InstantaneousPumolCshootsEditField.Value = app.Prod_Data.P_now_per_shoot;
            app.InstantaneousCanopyPPEditField.Value = app.Prod_Data.P_now_over_PE;
            app.InstantaneousLeafPREditField.Value = app.Prod_Data.Instantaneous_Leaf_P_to_R;
            app.InstantaneousWholePlantPREditField.Value = app.Prod_Data.Instantaneous_Shoot_P_to_R;
            app.DailyWholePlantPREditField.Value = app.Prod_Data.Daily_P_to_R;
            app.delCEditField.Value = app.Prod_Data.del13C_new;
            app.DailyHRequirementhdEditField.Value = app.Prod_Data.Daily_Hsat_Requirement;
 
        end

        % Value changed function: LAIEditField
        function LAIEditFieldValueChanged(app, event)
                        % Identify the plant species for new shoot leaf area  
            col = 1;
            if app.PlantTypeDropDown.Value == "Zostera"
                row = 1;
            elseif app.PlantTypeDropDown.Value == "Thalassia"
                row = 2;
            elseif app.PlantTypeDropDown.Value == "Syringodium"
                row = 3;
            elseif app.PlantTypeDropDown.Value == "Phyllospadix"
                row = 4;
            elseif app.PlantTypeDropDown.Value == "Posidonia"
                row = 5;
            else 
                row = 6;        %Ruppia
            end
            
            app.ShootDensityshootspermEditField.Value = app.LAIEditField.Value/app.Plant_Arch.areash(row,col);
            app.Plant_Arch.density(row,col) = app.ShootDensityshootspermEditField.Value;
            [app.Biodis_Data, app.Plant_Arch] = biodis_v301_for_app(app.Site_Data,app.Plant_Arch, app.PlantTypeDropDown);         %Call Biodis function to get new LAI
            app.LAIEditField.Value = app.Biodis_Data.laitot;                          % Get total LAI
            app.EdEu_Data = EdEu_water_v3_for_app(app.Site_Data, app.IOP_Spectra, app.WQ_Params, app.Plant_Arch, app.Sed_Ref, app.LOPs, app.E0_sfc_spectra, app.E0_sfc_PAR_mubar, app.abKd_Spectra, app.Biodis_Data, app.PlantTypeDropDown, app.SubstrateTypeDropDown);
           
            % Compute instantaneous and daily metabolic rates
            app.Prod_Data = produce_v301_for_app(app.WQ_Params, app.Site_Data, app.Plant_Arch, app.Photo_Params, app.Biodis_Data, app.EdEu_Data, app.E0_sfc_PAR_mubar, app.PlantTypeDropDown);

            % Populate the Plant Productivity and Metabolism Panel
            app.PumolCmleafsEditField_2.Value = app.Prod_Data.Pm;
            app.PumolCmleafsEditField.Value = app.Prod_Data.PE;
            app.PPEditField.Value = app.Prod_Data.PE / app.Prod_Data.Pm;
            app.InstantaneousPumolCmleafsEditField.Value = app.Prod_Data.Mean_Biomass_normalized_P_vs_E;
            app.InstantaneousPumolCshootsEditField.Value = app.Prod_Data.P_now_per_shoot;
            app.InstantaneousCanopyPPEditField.Value = app.Prod_Data.P_now_over_PE;
            app.InstantaneousLeafPREditField.Value = app.Prod_Data.Instantaneous_Leaf_P_to_R;
            app.InstantaneousWholePlantPREditField.Value = app.Prod_Data.Instantaneous_Shoot_P_to_R;
            app.DailyWholePlantPREditField.Value = app.Prod_Data.Daily_P_to_R;
            app.delCEditField.Value = app.Prod_Data.del13C_new;
            app.DailyHRequirementhdEditField.Value = app.Prod_Data.Daily_Hsat_Requirement;


        end

        % Value changed function: ShootRhizomeRootEditField
        function ShootRhizomeRootEditFieldValueChanged(app, event)
                        % Identify the plant species for new shoot leaf area  
            col = 1;
            if app.PlantTypeDropDown.Value == "Zostera"
                row = 1;
            elseif app.PlantTypeDropDown.Value == "Thalassia"
                row = 2;
            elseif app.PlantTypeDropDown.Value == "Syringodium"
                row = 3;
            elseif app.PlantTypeDropDown.Value == "Phyllospadix"
                row = 4;
            elseif app.PlantTypeDropDown.Value == "Posidonia"
                row = 5;
            else 
                row = 6;        %Ruppia
            end
            
            app.Plant_Arch.shoot_frac(row,col) = app.ShootRhizomeRootEditField.Value/(1+app.ShootRhizomeRootEditField.Value);
            app.Plant_Arch.rhiz_frac(row,col) = (1-app.Plant_Arch.shoot_frac(row,col))*app.RhizRootEditField.Value/(app.RhizRootEditField.Value+1);
            app.Plant_Arch.root_frac(row,col) = 1-app.Plant_Arch.shoot_frac(row,col) - app.Plant_Arch.rhiz_frac(row,col);

            % Compute instantaneous and daily metabolic rates
            app.Prod_Data = produce_v301_for_app(app.WQ_Params, app.Site_Data, app.Plant_Arch, app.Photo_Params, app.Biodis_Data, app.EdEu_Data, app.E0_sfc_PAR_mubar, app.PlantTypeDropDown);

            % Populate the Plant Productivity and Metabolism Panel
            app.PumolCmleafsEditField_2.Value = app.Prod_Data.Pm;
            app.PumolCmleafsEditField.Value = app.Prod_Data.PE;
            app.PPEditField.Value = app.Prod_Data.PE / app.Prod_Data.Pm;
            app.InstantaneousPumolCmleafsEditField.Value = app.Prod_Data.Mean_Biomass_normalized_P_vs_E;
            app.InstantaneousPumolCshootsEditField.Value = app.Prod_Data.P_now_per_shoot;
            app.InstantaneousCanopyPPEditField.Value = app.Prod_Data.P_now_over_PE;
            app.InstantaneousLeafPREditField.Value = app.Prod_Data.Instantaneous_Leaf_P_to_R;
            app.InstantaneousWholePlantPREditField.Value = app.Prod_Data.Instantaneous_Shoot_P_to_R;
            app.DailyWholePlantPREditField.Value = app.Prod_Data.Daily_P_to_R;
            app.delCEditField.Value = app.Prod_Data.del13C_new;
            app.DailyHRequirementhdEditField.Value = app.Prod_Data.Daily_Hsat_Requirement;

        end

        % Value changed function: RhizRootEditField
        function RhizRootEditFieldValueChanged(app, event)
                        % Identify the plant species for new shoot leaf area  
            col = 1;
            if app.PlantTypeDropDown.Value == "Zostera"
                row = 1;
            elseif app.PlantTypeDropDown.Value == "Thalassia"
                row = 2;
            elseif app.PlantTypeDropDown.Value == "Syringodium"
                row = 3;
            elseif app.PlantTypeDropDown.Value == "Phyllospadix"
                row = 4;
            elseif app.PlantTypeDropDown.Value == "Posidonia"
                row = 5;
            else 
                row = 6;        %Ruppia
            end
            
            app.Plant_Arch.rhiz_frac(row,col) =  (1-app.Plant_Arch.shoot_frac(row,col)) * app.RhizRootEditField.Value/(1+app.RhizRootEditField.Value);
            app.Plant_Arch.root_frac(row,col) = 1-app.Plant_Arch.shoot_frac(row,col) - app.Plant_Arch.rhiz_frac(row,col);

            % Compute instantaneous and daily metabolic rates
            app.Prod_Data = produce_v301_for_app(app.WQ_Params, app.Site_Data, app.Plant_Arch, app.Photo_Params, app.Biodis_Data, app.EdEu_Data, app.E0_sfc_PAR_mubar, app.PlantTypeDropDown);

            % Populate the Plant Productivity and Metabolism Panel
            app.PumolCmleafsEditField_2.Value = app.Prod_Data.Pm;
            app.PumolCmleafsEditField.Value = app.Prod_Data.PE;
            app.PPEditField.Value = app.Prod_Data.PE / app.Prod_Data.Pm;
            app.InstantaneousPumolCmleafsEditField.Value = app.Prod_Data.Mean_Biomass_normalized_P_vs_E;
            app.InstantaneousPumolCshootsEditField.Value = app.Prod_Data.P_now_per_shoot;
            app.InstantaneousCanopyPPEditField.Value = app.Prod_Data.P_now_over_PE;
            app.InstantaneousLeafPREditField.Value = app.Prod_Data.Instantaneous_Leaf_P_to_R;
            app.InstantaneousWholePlantPREditField.Value = app.Prod_Data.Instantaneous_Shoot_P_to_R;
            app.DailyWholePlantPREditField.Value = app.Prod_Data.Daily_P_to_R;
            app.delCEditField.Value = app.Prod_Data.del13C_new;
            app.DailyHRequirementhdEditField.Value = app.Prod_Data.Daily_Hsat_Requirement;

            
        end

        % Value changed function: EpiphyteLoadmgcmEditField
        function EpiphyteLoadmgcmEditFieldValueChanged(app, event)
            % Identify the plant species for new epiphyte load  
            col = 1;
            if app.PlantTypeDropDown.Value == "Zostera"
                row = 1;
            elseif app.PlantTypeDropDown.Value == "Thalassia"
                row = 2;
            elseif app.PlantTypeDropDown.Value == "Syringodium"
                row = 3;
            elseif app.PlantTypeDropDown.Value == "Phyllospadix"
                row = 4;
            elseif app.PlantTypeDropDown.Value == "Posidonia"
                row = 5;
            else 
                row = 6;        %Ruppia
            end
            
            % Update epiphyte load value
            app.Plant_Arch.epi(row,col) = app.EpiphyteLoadmgcmEditField.Value;

            % Update submarine light distribution and Pi(z) absorbed by the leaf canopy
            app.EdEu_Data = EdEu_water_v3_for_app(app.Site_Data, app.IOP_Spectra, app.WQ_Params, app.Plant_Arch, app.Sed_Ref, app.LOPs, app.E0_sfc_spectra, app.E0_sfc_PAR_mubar, app.abKd_Spectra, app.Biodis_Data, app.PlantTypeDropDown, app.SubstrateTypeDropDown);
           
            % Compute instantaneous and daily metabolic rates
            app.Prod_Data = produce_v301_for_app(app.WQ_Params, app.Site_Data, app.Plant_Arch, app.Photo_Params, app.Biodis_Data, app.EdEu_Data, app.E0_sfc_PAR_mubar, app.PlantTypeDropDown);

            % Populate the Plant Productivity and Metabolism Panel
            app.PumolCmleafsEditField_2.Value = app.Prod_Data.Pm;
            app.PumolCmleafsEditField.Value = app.Prod_Data.PE;
            app.PPEditField.Value = app.Prod_Data.PE / app.Prod_Data.Pm;
            app.InstantaneousPumolCmleafsEditField.Value = app.Prod_Data.Mean_Biomass_normalized_P_vs_E;
            app.InstantaneousPumolCshootsEditField.Value = app.Prod_Data.P_now_per_shoot;
            app.InstantaneousCanopyPPEditField.Value = app.Prod_Data.P_now_over_PE;
            app.InstantaneousLeafPREditField.Value = app.Prod_Data.Instantaneous_Leaf_P_to_R;
            app.InstantaneousWholePlantPREditField.Value = app.Prod_Data.Instantaneous_Shoot_P_to_R;
            app.DailyWholePlantPREditField.Value = app.Prod_Data.Daily_P_to_R;
            app.delCEditField.Value = app.Prod_Data.del13C_new;
            app.DailyHRequirementhdEditField.Value = app.Prod_Data.Daily_Hsat_Requirement;
            
        end

        % Value changed function: ChlamgmEditField
        function ChlamgmEditFieldValueChanged(app, event)
            app.WQ_Params.CHL_A = app.ChlamgmEditField.Value;
            app.abKd_Spectra = abKd_Spectra_Ver1_for_app(app.WQ_Params, app.IOP_Constants, app.IOP_Spectra, app.E0_sfc_PAR_mubar);
            app.EdEu_Data = EdEu_water_v3_for_app(app.Site_Data, app.IOP_Spectra, app.WQ_Params, app.Plant_Arch, app.Sed_Ref, app.LOPs, app.E0_sfc_spectra, app.E0_sfc_PAR_mubar, app.abKd_Spectra, app.Biodis_Data, app.PlantTypeDropDown, app.SubstrateTypeDropDown);
           
            % Compute instantaneous and daily metabolic rates
            app.Prod_Data = produce_v301_for_app(app.WQ_Params, app.Site_Data, app.Plant_Arch, app.Photo_Params, app.Biodis_Data, app.EdEu_Data, app.E0_sfc_PAR_mubar, app.PlantTypeDropDown);

            % Populate the Plant Productivity and Metabolism Panel
            app.PumolCmleafsEditField_2.Value = app.Prod_Data.Pm;
            app.PumolCmleafsEditField.Value = app.Prod_Data.PE;
            app.PPEditField.Value = app.Prod_Data.PE / app.Prod_Data.Pm;
            app.InstantaneousPumolCmleafsEditField.Value = app.Prod_Data.Mean_Biomass_normalized_P_vs_E;
            app.InstantaneousPumolCshootsEditField.Value = app.Prod_Data.P_now_per_shoot;
            app.InstantaneousCanopyPPEditField.Value = app.Prod_Data.P_now_over_PE;
            app.InstantaneousLeafPREditField.Value = app.Prod_Data.Instantaneous_Leaf_P_to_R;
            app.InstantaneousWholePlantPREditField.Value = app.Prod_Data.Instantaneous_Shoot_P_to_R;
            app.DailyWholePlantPREditField.Value = app.Prod_Data.Daily_P_to_R;
            app.delCEditField.Value = app.Prod_Data.del13C_new;
            app.DailyHRequirementhdEditField.Value = app.Prod_Data.Daily_Hsat_Requirement;

        end

        % Value changed function: TSMmgLEditField
        function TSMmgLEditFieldValueChanged(app, event)
            app.WQ_Params.TSM = app.TSMmgLEditField.Value;
            app.abKd_Spectra = abKd_Spectra_Ver1_for_app(app.WQ_Params, app.IOP_Constants, app.IOP_Spectra, app.E0_sfc_PAR_mubar);
            app.EdEu_Data = EdEu_water_v3_for_app(app.Site_Data, app.IOP_Spectra, app.WQ_Params, app.Plant_Arch, app.Sed_Ref, app.LOPs, app.E0_sfc_spectra, app.E0_sfc_PAR_mubar, app.abKd_Spectra, app.Biodis_Data, app.PlantTypeDropDown, app.SubstrateTypeDropDown);
           
            % Compute instantaneous and daily metabolic rates
            app.Prod_Data = produce_v301_for_app(app.WQ_Params, app.Site_Data, app.Plant_Arch, app.Photo_Params, app.Biodis_Data, app.EdEu_Data, app.E0_sfc_PAR_mubar, app.PlantTypeDropDown);

            % Populate the Plant Productivity and Metabolism Panel
            app.PumolCmleafsEditField_2.Value = app.Prod_Data.Pm;
            app.PumolCmleafsEditField.Value = app.Prod_Data.PE;
            app.PPEditField.Value = app.Prod_Data.PE / app.Prod_Data.Pm;
            app.InstantaneousPumolCmleafsEditField.Value = app.Prod_Data.Mean_Biomass_normalized_P_vs_E;
            app.InstantaneousPumolCshootsEditField.Value = app.Prod_Data.P_now_per_shoot;
            app.InstantaneousCanopyPPEditField.Value = app.Prod_Data.P_now_over_PE;
            app.InstantaneousLeafPREditField.Value = app.Prod_Data.Instantaneous_Leaf_P_to_R;
            app.InstantaneousWholePlantPREditField.Value = app.Prod_Data.Instantaneous_Shoot_P_to_R;
            app.DailyWholePlantPREditField.Value = app.Prod_Data.Daily_P_to_R;
            app.delCEditField.Value = app.Prod_Data.del13C_new;
            app.DailyHRequirementhdEditField.Value = app.Prod_Data.Daily_Hsat_Requirement;

        end

        % Value changed function: a440EditField
        function a440EditFieldValueChanged(app, event)
            app.WQ_Params.AG_440 = app.a440EditField.Value;
            app.abKd_Spectra = abKd_Spectra_Ver1_for_app(app.WQ_Params, app.IOP_Constants, app.IOP_Spectra, app.E0_sfc_PAR_mubar);
            app.EdEu_Data = EdEu_water_v3_for_app(app.Site_Data, app.IOP_Spectra, app.WQ_Params, app.Plant_Arch, app.Sed_Ref, app.LOPs, app.E0_sfc_spectra, app.E0_sfc_PAR_mubar, app.abKd_Spectra, app.Biodis_Data, app.PlantTypeDropDown, app.SubstrateTypeDropDown);
           
            % Compute instantaneous and daily metabolic rates
            app.Prod_Data = produce_v301_for_app(app.WQ_Params, app.Site_Data, app.Plant_Arch, app.Photo_Params, app.Biodis_Data, app.EdEu_Data, app.E0_sfc_PAR_mubar, app.PlantTypeDropDown);

            % Populate the Plant Productivity and Metabolism Panel
            app.PumolCmleafsEditField_2.Value = app.Prod_Data.Pm;
            app.PumolCmleafsEditField.Value = app.Prod_Data.PE;
            app.PPEditField.Value = app.Prod_Data.PE / app.Prod_Data.Pm;
            app.InstantaneousPumolCmleafsEditField.Value = app.Prod_Data.Mean_Biomass_normalized_P_vs_E;
            app.InstantaneousPumolCshootsEditField.Value = app.Prod_Data.P_now_per_shoot;
            app.InstantaneousCanopyPPEditField.Value = app.Prod_Data.P_now_over_PE;
            app.InstantaneousLeafPREditField.Value = app.Prod_Data.Instantaneous_Leaf_P_to_R;
            app.InstantaneousWholePlantPREditField.Value = app.Prod_Data.Instantaneous_Shoot_P_to_R;
            app.DailyWholePlantPREditField.Value = app.Prod_Data.Daily_P_to_R;
            app.delCEditField.Value = app.Prod_Data.del13C_new;
            app.DailyHRequirementhdEditField.Value = app.Prod_Data.Daily_Hsat_Requirement;

        end

        % Value changed function: TemperatureCEditField
        function TemperatureCEditFieldValueChanged(app, event)
            app.WQ_Params.Temp = app.TemperatureCEditField.Value;

               % Update DIC values from CO2sys
            [Param1, Param2] = Get_CO2sys_Drivers(app.WQ_Params);   
            app.CO2SYS_Data = CO2SYS_for_app(Param1,Param2,app.WQ_Params.PAR1TYPE,app.WQ_Params.PAR2TYPE,app.WQ_Params.Sal,app.WQ_Params.Temp,app.WQ_Params.Temp,0,app.Site_Data.depth,app.WQ_Params.SiO4,app.WQ_Params.PO4,app.WQ_Params.NH4,app.WQ_Params.H2S,app.WQ_Params.pH_Scale,app.WQ_Params.K1K2,app.WQ_Params.KHSO4,app.WQ_Params.KHF,app.WQ_Params.Borate);
            app.pHEditField.Value = app.CO2SYS_Data.pHout;
            app.WQ_Params.pH = app.CO2SYS_Data.pHout;
            app.pCOinWaterAtmEditField.Value = app.CO2SYS_Data.pCO2out;
            app.WQ_Params.pCO2_Water = app.CO2SYS_Data.pCO2out;
            app.COEditField.Value = app.CO2SYS_Data.CO2out;
            app.WQ_Params.CO2 = app.CO2SYS_Data.CO2out;
            app.HCOEditField.Value = app.CO2SYS_Data.HCO3out;
            app.WQ_Params.HCO3 = app.CO2SYS_Data.HCO3out;
            app.COEditField_2.Value = app.CO2SYS_Data.CO3out;
            app.WQ_Params.CO3 = app.CO2SYS_Data.CO3out;
            app.TotalDICEditField.Value = app.CO2SYS_Data.TCO2;
            app.WQ_Params.TCO2 = app.CO2SYS_Data.TCO2;

            % Update gas saturation values
            app.Gas_Sat = Gas_Solubility_Model(app.WQ_Params.Temp, app.WQ_Params.Sal, app.Site_Data.depth, app.WQ_Params.pCO2_Air);
            app.Gas_Sat.O2_Depth = app.Gas_Sat.O2_1atm * (app.Site_Data.depth/10+1);
            app.OAirSatatDepthEditField.Value = 100 * app.OEditField.Value/app.Gas_Sat.O2_Depth;
            app.pCOAirSatatSfcEditField.Value = 100 * app.pCOinWaterAtmEditField.Value/app.Gas_Sat.pCO2_1atm;
            app.pCOAirSatatDepthEditField.Value = 100 * app.pCOinWaterAtmEditField.Value/app.Gas_Sat.pCO2_Depth;

            % Compute instantaneous and daily metabolic rates
            app.Prod_Data = produce_v301_for_app(app.WQ_Params, app.Site_Data, app.Plant_Arch, app.Photo_Params, app.Biodis_Data, app.EdEu_Data, app.E0_sfc_PAR_mubar, app.PlantTypeDropDown);

            % Populate the Plant Productivity and Metabolism Panel
            app.PumolCmleafsEditField_2.Value = app.Prod_Data.Pm;
            app.PumolCmleafsEditField.Value = app.Prod_Data.PE;
            app.PPEditField.Value = app.Prod_Data.PE / app.Prod_Data.Pm;
            app.InstantaneousPumolCmleafsEditField.Value = app.Prod_Data.Mean_Biomass_normalized_P_vs_E;
            app.InstantaneousPumolCshootsEditField.Value = app.Prod_Data.P_now_per_shoot;
            app.InstantaneousCanopyPPEditField.Value = app.Prod_Data.P_now_over_PE;
            app.InstantaneousLeafPREditField.Value = app.Prod_Data.Instantaneous_Leaf_P_to_R;
            app.InstantaneousWholePlantPREditField.Value = app.Prod_Data.Instantaneous_Shoot_P_to_R;
            app.DailyWholePlantPREditField.Value = app.Prod_Data.Daily_P_to_R;
            app.delCEditField.Value = app.Prod_Data.del13C_new;
            app.DailyHRequirementhdEditField.Value = app.Prod_Data.Daily_Hsat_Requirement;


        end

        % Value changed function: SalinitypptorPSS78EditField
        function SalinitypptorPSS78EditFieldValueChanged(app, event)
            app.WQ_Params.Sal = app.SalinitypptorPSS78EditField.Value;
            app.WQ_Params.Alk = 2710 * app.WQ_Params.Sal / 32.6;    % Adjust alkalinity to match salinity
            app.AlkalinityEditField.Value = app.WQ_Params.Alk;

               % Update DIC values from CO2sys
            [Param1, Param2] = Get_CO2sys_Drivers(app.WQ_Params);   
            app.CO2SYS_Data = CO2SYS_for_app(Param1,Param2,app.WQ_Params.PAR1TYPE,app.WQ_Params.PAR2TYPE,app.WQ_Params.Sal,app.WQ_Params.Temp,app.WQ_Params.Temp,0,app.Site_Data.depth,app.WQ_Params.SiO4,app.WQ_Params.PO4,app.WQ_Params.NH4,app.WQ_Params.H2S,app.WQ_Params.pH_Scale,app.WQ_Params.K1K2,app.WQ_Params.KHSO4,app.WQ_Params.KHF,app.WQ_Params.Borate);
            app.pHEditField.Value = app.CO2SYS_Data.pHout;
            app.WQ_Params.pH = app.CO2SYS_Data.pHout;
            app.pCOinWaterAtmEditField.Value = app.CO2SYS_Data.pCO2out;
            app.WQ_Params.pCO2_Water = app.CO2SYS_Data.pCO2out;
            app.COEditField.Value = app.CO2SYS_Data.CO2out;
            app.WQ_Params.CO2 = app.CO2SYS_Data.CO2out;
            app.HCOEditField.Value = app.CO2SYS_Data.HCO3out;
            app.WQ_Params.HCO3 = app.CO2SYS_Data.HCO3out;
            app.COEditField_2.Value = app.CO2SYS_Data.CO3out;
            app.WQ_Params.CO3 = app.CO2SYS_Data.CO3out;
            app.TotalDICEditField.Value = app.CO2SYS_Data.TCO2;
            app.WQ_Params.TCO2 = app.CO2SYS_Data.TCO2;

            % Update gas saturation values
            app.Gas_Sat = Gas_Solubility_Model(app.WQ_Params.Temp, app.WQ_Params.Sal, app.Site_Data.depth, app.WQ_Params.pCO2_Air);
            app.Gas_Sat.O2_Depth = app.Gas_Sat.O2_1atm * (app.Site_Data.depth/10+1);
            app.OAirSatatDepthEditField.Value = 100 * app.OEditField.Value/app.Gas_Sat.O2_Depth;
            app.pCOAirSatatSfcEditField.Value = 100 * app.pCOinWaterAtmEditField.Value/app.Gas_Sat.pCO2_1atm;
            app.pCOAirSatatDepthEditField.Value = 100 * app.pCOinWaterAtmEditField.Value/app.Gas_Sat.pCO2_Depth;

            % Compute instantaneous and daily metabolic rates
            app.Prod_Data = produce_v301_for_app(app.WQ_Params, app.Site_Data, app.Plant_Arch, app.Photo_Params, app.Biodis_Data, app.EdEu_Data, app.E0_sfc_PAR_mubar, app.PlantTypeDropDown);

            % Populate the Plant Productivity and Metabolism Panel
            app.PumolCmleafsEditField_2.Value = app.Prod_Data.Pm;
            app.PumolCmleafsEditField.Value = app.Prod_Data.PE;
            app.PPEditField.Value = app.Prod_Data.PE / app.Prod_Data.Pm;
            app.InstantaneousPumolCmleafsEditField.Value = app.Prod_Data.Mean_Biomass_normalized_P_vs_E;
            app.InstantaneousPumolCshootsEditField.Value = app.Prod_Data.P_now_per_shoot;
            app.InstantaneousCanopyPPEditField.Value = app.Prod_Data.P_now_over_PE;
            app.InstantaneousLeafPREditField.Value = app.Prod_Data.Instantaneous_Leaf_P_to_R;
            app.InstantaneousWholePlantPREditField.Value = app.Prod_Data.Instantaneous_Shoot_P_to_R;
            app.DailyWholePlantPREditField.Value = app.Prod_Data.Daily_P_to_R;
            app.delCEditField.Value = app.Prod_Data.del13C_new;
            app.DailyHRequirementhdEditField.Value = app.Prod_Data.Daily_Hsat_Requirement;


        end

        % Value changed function: CO2SYSInputPrametersDropDown
        function CO2SYSInputPrametersDropDownValueChanged(app, event)
            if app.CO2SYSInputPrametersDropDown.Value == "Alk & Total DIC"
                app.WQ_Params.PAR1TYPE = 1;
                app.WQ_Params.PAR2TYPE = 2;
            elseif app.CO2SYSInputPrametersDropDown.Value == "Alk & pH"
                app.WQ_Params.PAR1TYPE = 1;
                app.WQ_Params.PAR2TYPE = 3;
            elseif app.CO2SYSInputPrametersDropDown.Value == "Alk & pCO2"
                app.WQ_Params.PAR1TYPE = 1;
                app.WQ_Params.PAR2TYPE = 4;
            elseif app.CO2SYSInputPrametersDropDown.Value == "Total DIC & pH"
                app.WQ_Params.PAR1TYPE = 2;
                app.WQ_Params.PAR2TYPE = 3;
            elseif app.CO2SYSInputPrametersDropDown.Value == "Total DIC & pCO2"
                app.WQ_Params.PAR1TYPE = 2;
                app.WQ_Params.PAR2TYPE = 4;
            else
                app.WQ_Params.PAR1TYPE = 3;
                app.WQ_Params.PAR2TYPE = 4;
            end

            % Update DIC values from CO2sys
            [Param1, Param2] = Get_CO2sys_Drivers(app.WQ_Params);   
            app.CO2SYS_Data = CO2SYS_for_app(Param1,Param2,app.WQ_Params.PAR1TYPE,app.WQ_Params.PAR2TYPE,app.WQ_Params.Sal,app.WQ_Params.Temp,app.WQ_Params.Temp,0,app.Site_Data.depth,app.WQ_Params.SiO4,app.WQ_Params.PO4,app.WQ_Params.NH4,app.WQ_Params.H2S,app.WQ_Params.pH_Scale,app.WQ_Params.K1K2,app.WQ_Params.KHSO4,app.WQ_Params.KHF,app.WQ_Params.Borate);
            app.pHEditField.Value = app.CO2SYS_Data.pHin;
            app.pCOinWaterAtmEditField.Value = app.CO2SYS_Data.pCO2out;
            app.WQ_Params.pCO2_Water = app.CO2SYS_Data.pCO2out;
            app.COEditField.Value = app.CO2SYS_Data.CO2out;
            app.WQ_Params.CO2 = app.CO2SYS_Data.CO2out;
            app.HCOEditField.Value = app.CO2SYS_Data.HCO3out;
            app.WQ_Params.HCO3 = app.CO2SYS_Data.HCO3out;
            app.COEditField_2.Value = app.CO2SYS_Data.CO3out;
            app.WQ_Params.CO3 = app.CO2SYS_Data.CO3out;
            app.TotalDICEditField.Value = app.CO2SYS_Data.TCO2;
            app.WQ_Params.TCO2 = app.CO2SYS_Data.TCO2;

            % Update gas saturation values
            app.Gas_Sat = Gas_Solubility_Model(app.WQ_Params.Temp, app.WQ_Params.Sal, app.Site_Data.depth, app.WQ_Params.pCO2_Air);
            app.Gas_Sat.O2_Depth = app.Gas_Sat.O2_1atm * (app.Site_Data.depth/10+1);
            app.OAirSatatDepthEditField.Value = 100 * app.OEditField.Value/app.Gas_Sat.O2_Depth;
            app.pCOAirSatatSfcEditField.Value = 100 * app.pCOinWaterAtmEditField.Value/app.Gas_Sat.pCO2_1atm;
            app.pCOAirSatatDepthEditField.Value = 100 * app.pCOinWaterAtmEditField.Value/app.Gas_Sat.pCO2_Depth;

            % Compute instantaneous and daily metabolic rates
            app.Prod_Data = produce_v301_for_app(app.WQ_Params, app.Site_Data, app.Plant_Arch, app.Photo_Params, app.Biodis_Data, app.EdEu_Data, app.E0_sfc_PAR_mubar, app.PlantTypeDropDown);

            % Populate the Plant Productivity and Metabolism Panel
            app.PumolCmleafsEditField_2.Value = app.Prod_Data.Pm;
            app.PumolCmleafsEditField.Value = app.Prod_Data.PE;
            app.PPEditField.Value = app.Prod_Data.PE / app.Prod_Data.Pm;
            app.InstantaneousPumolCmleafsEditField.Value = app.Prod_Data.Mean_Biomass_normalized_P_vs_E;
            app.InstantaneousPumolCshootsEditField.Value = app.Prod_Data.P_now_per_shoot;
            app.InstantaneousCanopyPPEditField.Value = app.Prod_Data.P_now_over_PE;
            app.InstantaneousLeafPREditField.Value = app.Prod_Data.Instantaneous_Leaf_P_to_R;
            app.InstantaneousWholePlantPREditField.Value = app.Prod_Data.Instantaneous_Shoot_P_to_R;
            app.DailyWholePlantPREditField.Value = app.Prod_Data.Daily_P_to_R;
            app.delCEditField.Value = app.Prod_Data.del13C_new;
            app.DailyHRequirementhdEditField.Value = app.Prod_Data.Daily_Hsat_Requirement;
            
        end

        % Value changed function: pHEditField
        function pHEditFieldValueChanged(app, event)
            app.WQ_Params.pH = app.pHEditField.Value;

               % Update DIC values from CO2sys
            [Param1, Param2] = Get_CO2sys_Drivers(app.WQ_Params);   
            app.CO2SYS_Data = CO2SYS_for_app(Param1,Param2,app.WQ_Params.PAR1TYPE,app.WQ_Params.PAR2TYPE,app.WQ_Params.Sal,app.WQ_Params.Temp,app.WQ_Params.Temp,0,app.Site_Data.depth,app.WQ_Params.SiO4,app.WQ_Params.PO4,app.WQ_Params.NH4,app.WQ_Params.H2S,app.WQ_Params.pH_Scale,app.WQ_Params.K1K2,app.WQ_Params.KHSO4,app.WQ_Params.KHF,app.WQ_Params.Borate);
            app.pHEditField.Value = app.CO2SYS_Data.pHin;
            app.pCOinWaterAtmEditField.Value = app.CO2SYS_Data.pCO2out;
            app.WQ_Params.pCO2_Water = app.CO2SYS_Data.pCO2out;
            app.COEditField.Value = app.CO2SYS_Data.CO2out;
            app.WQ_Params.CO2 = app.CO2SYS_Data.CO2out;
            app.HCOEditField.Value = app.CO2SYS_Data.HCO3out;
            app.WQ_Params.HCO3 = app.CO2SYS_Data.HCO3out;
            app.COEditField_2.Value = app.CO2SYS_Data.CO3out;
            app.WQ_Params.CO3 = app.CO2SYS_Data.CO3out;
            app.TotalDICEditField.Value = app.CO2SYS_Data.TCO2;
            app.WQ_Params.TCO2 = app.CO2SYS_Data.TCO2;

            % Update gas saturation values
            app.Gas_Sat = Gas_Solubility_Model(app.WQ_Params.Temp, app.WQ_Params.Sal, app.Site_Data.depth, app.WQ_Params.pCO2_Air);
            app.Gas_Sat.O2_Depth = app.Gas_Sat.O2_1atm * (app.Site_Data.depth/10+1);
            app.OAirSatatDepthEditField.Value = 100 * app.OEditField.Value/app.Gas_Sat.O2_Depth;
            app.pCOAirSatatSfcEditField.Value = 100 * app.pCOinWaterAtmEditField.Value/app.Gas_Sat.pCO2_1atm;
            app.pCOAirSatatDepthEditField.Value = 100 * app.pCOinWaterAtmEditField.Value/app.Gas_Sat.pCO2_Depth;

            % Compute instantaneous and daily metabolic rates
            app.Prod_Data = produce_v301_for_app(app.WQ_Params, app.Site_Data, app.Plant_Arch, app.Photo_Params, app.Biodis_Data, app.EdEu_Data, app.E0_sfc_PAR_mubar, app.PlantTypeDropDown);

            % Populate the Plant Productivity and Metabolism Panel
            app.PumolCmleafsEditField_2.Value = app.Prod_Data.Pm;
            app.PumolCmleafsEditField.Value = app.Prod_Data.PE;
            app.PPEditField.Value = app.Prod_Data.PE / app.Prod_Data.Pm;
            app.InstantaneousPumolCmleafsEditField.Value = app.Prod_Data.Mean_Biomass_normalized_P_vs_E;
            app.InstantaneousPumolCshootsEditField.Value = app.Prod_Data.P_now_per_shoot;
            app.InstantaneousCanopyPPEditField.Value = app.Prod_Data.P_now_over_PE;
            app.InstantaneousLeafPREditField.Value = app.Prod_Data.Instantaneous_Leaf_P_to_R;
            app.InstantaneousWholePlantPREditField.Value = app.Prod_Data.Instantaneous_Shoot_P_to_R;
            app.DailyWholePlantPREditField.Value = app.Prod_Data.Daily_P_to_R;
            app.delCEditField.Value = app.Prod_Data.del13C_new;
            app.DailyHRequirementhdEditField.Value = app.Prod_Data.Daily_Hsat_Requirement;

            
        end

        % Value changed function: pHScaleDropDown
        function pHScaleDropDownValueChanged(app, event)
            app.WQ_Params.scale = app.pHScaleDropDown.Value;
            if app.pHScaleDropDown.Value == "Total"
                app.WQ_Params.pH_Scale = 1;
            elseif app.pHScaleDropDown.Value == "Seawater"
                app.WQ_Params.pH_Scale = 2;
            elseif app.pHScaleDropDown.Value == "Free"
                app.WQ_Params.pH_Scale = 3;
            else 
                app.WQ_Params.pH_Scale = 4;
            end
           
               % Update DIC values
            [Param1, Param2] = Get_CO2sys_Drivers(app.WQ_Params);   
            app.CO2SYS_Data = CO2SYS_for_app(Param1,Param2,app.WQ_Params.PAR1TYPE,app.WQ_Params.PAR2TYPE,app.WQ_Params.Sal,app.WQ_Params.Temp,app.WQ_Params.Temp,0,app.Site_Data.depth,app.WQ_Params.SiO4,app.WQ_Params.PO4,app.WQ_Params.NH4,app.WQ_Params.H2S,app.WQ_Params.pH_Scale,app.WQ_Params.K1K2,app.WQ_Params.KHSO4,app.WQ_Params.KHF,app.WQ_Params.Borate);
            app.pCOinWaterAtmEditField.Value = app.CO2SYS_Data.pCO2out;
            app.WQ_Params.pCO2_Water = app.CO2SYS_Data.pCO2out;
            app.COEditField.Value = app.CO2SYS_Data.CO2out;
            app.WQ_Params.CO2 = app.CO2SYS_Data.CO2out;
            app.HCOEditField.Value = app.CO2SYS_Data.HCO3out;
            app.WQ_Params.HCO3 = app.CO2SYS_Data.HCO3out;
            app.COEditField_2.Value = app.CO2SYS_Data.CO3out;
            app.WQ_Params.CO3 = app.CO2SYS_Data.CO3out;
            app.TotalDICEditField.Value = app.CO2SYS_Data.TCO2;
            app.WQ_Params.TCO2 = app.CO2SYS_Data.TCO2;

            % Update gas saturation values
            app.Gas_Sat = Gas_Solubility_Model(app.WQ_Params.Temp, app.WQ_Params.Sal, app.Site_Data.depth, app.WQ_Params.pCO2_Air);
            app.Gas_Sat.O2_Depth = app.Gas_Sat.O2_1atm * (app.Site_Data.depth/10+1);
            app.OAirSatatDepthEditField.Value = 100 * app.OEditField.Value/app.Gas_Sat.O2_Depth;
            app.pCOAirSatatSfcEditField.Value = 100 * app.pCOinWaterAtmEditField.Value/app.Gas_Sat.pCO2_1atm;
            app.pCOAirSatatDepthEditField.Value = 100 * app.pCOinWaterAtmEditField.Value/app.Gas_Sat.pCO2_Depth;

            % Compute instantaneous and daily metabolic rates
            app.Prod_Data = produce_v301_for_app(app.WQ_Params, app.Site_Data, app.Plant_Arch, app.Photo_Params, app.Biodis_Data, app.EdEu_Data, app.E0_sfc_PAR_mubar, app.PlantTypeDropDown);

            % Populate the Plant Productivity and Metabolism Panel
            app.PumolCmleafsEditField_2.Value = app.Prod_Data.Pm;
            app.PumolCmleafsEditField.Value = app.Prod_Data.PE;
            app.PPEditField.Value = app.Prod_Data.PE / app.Prod_Data.Pm;
            app.InstantaneousPumolCmleafsEditField.Value = app.Prod_Data.Mean_Biomass_normalized_P_vs_E;
            app.InstantaneousPumolCshootsEditField.Value = app.Prod_Data.P_now_per_shoot;
            app.InstantaneousCanopyPPEditField.Value = app.Prod_Data.P_now_over_PE;
            app.InstantaneousLeafPREditField.Value = app.Prod_Data.Instantaneous_Leaf_P_to_R;
            app.InstantaneousWholePlantPREditField.Value = app.Prod_Data.Instantaneous_Shoot_P_to_R;
            app.DailyWholePlantPREditField.Value = app.Prod_Data.Daily_P_to_R;
            app.delCEditField.Value = app.Prod_Data.del13C_new;
            app.DailyHRequirementhdEditField.Value = app.Prod_Data.Daily_Hsat_Requirement;
                     
        end

        % Value changed function: K1K2ConstantsDropDown
        function K1K2ConstantsDropDownValueChanged(app, event)
           
            % Get new K1K1 constants parameter for CO2SYS
            if app.K1K2ConstantsDropDown.Value == "Roy 1993"
                app.WQ_Params.K1K2 = 1;
            elseif app.K1K2ConstantsDropDown.Value == "Goyet & Poisson 1989"
                app.WQ_Params.K1K2 = 2;
            elseif app.K1K2ConstantsDropDown.Value == "Hansson 1973 refit by Dickson & Millero 1987"
                app.WQ_Params.K1K2 = 3;
            elseif app.K1K2ConstantsDropDown.Value == "Mehrbach et al. 1973 refit by Dickson & Millero 1987"
                app.WQ_Params.K1K2 = 4;
            elseif app.K1K2ConstantsDropDown.Value == "Hansson & Mehrbach refit by Dickson & Millero 1987"
                app.WQ_Params.K1K2 = 5;
            elseif app.K1K2ConstantsDropDown.Value == "GEOSECS NBS Constants, K1, K2 from Mehrbach et al. 1973"
                app.WQ_Params.K1K2 = 6;
            elseif app.K1K2ConstantsDropDown.Value == "Peng et al. NBS constants; K1 K2 from Mehrbach et al. 1973"
                app.WQ_Params.K1K2 = 7;
            elseif app.K1K2ConstantsDropDown.Value == "Freshwater (Sal = 0); K1 K2 from Millero 1979"
                app.WQ_Params.K1K2 = 8;
            elseif app.K1K2ConstantsDropDown.Value == "Cai and Wang 1998"
                app.WQ_Params.K1K2 = 9;
            elseif app.K1K2ConstantsDropDown.Value == "Lueker et al 2000"
                app.WQ_Params.K1K2 = 10;
            elseif app.K1K2ConstantsDropDown.Value == "Mojica Prieto & Millero 2002"
                app.WQ_Params.K1K2 = 11; 
            elseif app.K1K2ConstantsDropDown.Value == "Millero et al. 2002"
                app.WQ_Params.K1K2 = 12; 
            elseif app.K1K2ConstantsDropDown.Value == "Millero et al. 2006"
                app.WQ_Params.K1K2 = 13; 
            elseif app.K1K2ConstantsDropDown.Value == "Millero 2010"
                app.WQ_Params.K1K2 = 14; 
            elseif app.K1K2ConstantsDropDown.Value == "Waters et al. 2014"
                app.WQ_Params.K1K2 = 15; 
            elseif app.K1K2ConstantsDropDown.Value == "Sulpis et al 2020"
                app.WQ_Params.K1K2 = 16; 
            elseif app.K1K2ConstantsDropDown.Value == "Schockman & Byrne 2021"
                app.WQ_Params.K1K2 = 17; 
            end

            % Update DIC values from CO2SYS
            [Param1, Param2] = Get_CO2sys_Drivers(app.WQ_Params);   
            app.CO2SYS_Data = CO2SYS_for_app(Param1,Param2,app.WQ_Params.PAR1TYPE,app.WQ_Params.PAR2TYPE,app.WQ_Params.Sal,app.WQ_Params.Temp,app.WQ_Params.Temp,0,app.Site_Data.depth,app.WQ_Params.SiO4,app.WQ_Params.PO4,app.WQ_Params.NH4,app.WQ_Params.H2S,app.WQ_Params.pH_Scale,app.WQ_Params.K1K2,app.WQ_Params.KHSO4,app.WQ_Params.KHF,app.WQ_Params.Borate);
            app.pHEditField.Value = app.CO2SYS_Data.pHout;
            app.WQ_Params.pH = app.CO2SYS_Data.pHout;
            app.pCOinWaterAtmEditField.Value = app.CO2SYS_Data.pCO2out;
            app.WQ_Params.pCO2_Water = app.CO2SYS_Data.pCO2out;
            app.COEditField.Value = app.CO2SYS_Data.CO2out;
            app.WQ_Params.CO2 = app.CO2SYS_Data.CO2out;
            app.HCOEditField.Value = app.CO2SYS_Data.HCO3out;
            app.WQ_Params.HCO3 = app.CO2SYS_Data.HCO3out;
            app.COEditField_2.Value = app.CO2SYS_Data.CO3out;
            app.WQ_Params.CO3 = app.CO2SYS_Data.CO3out;
            app.TotalDICEditField.Value = app.CO2SYS_Data.TCO2;
            app.WQ_Params.TCO2 = app.CO2SYS_Data.TCO2;

            % Update gas saturation values
            app.Gas_Sat = Gas_Solubility_Model(app.WQ_Params.Temp, app.WQ_Params.Sal, app.Site_Data.depth, app.WQ_Params.pCO2_Air);
            app.Gas_Sat.O2_Depth = app.Gas_Sat.O2_1atm * (app.Site_Data.depth/10+1);
            app.OAirSatatDepthEditField.Value = 100 * app.OEditField.Value/app.Gas_Sat.O2_Depth;
            app.pCOAirSatatSfcEditField.Value = 100 * app.pCOinWaterAtmEditField.Value/app.Gas_Sat.pCO2_1atm;
            app.pCOAirSatatDepthEditField.Value = 100 * app.pCOinWaterAtmEditField.Value/app.Gas_Sat.pCO2_Depth;

            % Compute instantaneous and daily metabolic rates
            app.Prod_Data = produce_v301_for_app(app.WQ_Params, app.Site_Data, app.Plant_Arch, app.Photo_Params, app.Biodis_Data, app.EdEu_Data, app.E0_sfc_PAR_mubar, app.PlantTypeDropDown);

            % Populate the Plant Productivity and Metabolism Panel
            app.PumolCmleafsEditField_2.Value = app.Prod_Data.Pm;
            app.PumolCmleafsEditField.Value = app.Prod_Data.PE;
            app.PPEditField.Value = app.Prod_Data.PE / app.Prod_Data.Pm;
            app.InstantaneousPumolCmleafsEditField.Value = app.Prod_Data.Mean_Biomass_normalized_P_vs_E;
            app.InstantaneousPumolCshootsEditField.Value = app.Prod_Data.P_now_per_shoot;
            app.InstantaneousCanopyPPEditField.Value = app.Prod_Data.P_now_over_PE;
            app.InstantaneousLeafPREditField.Value = app.Prod_Data.Instantaneous_Leaf_P_to_R;
            app.InstantaneousWholePlantPREditField.Value = app.Prod_Data.Instantaneous_Shoot_P_to_R;
            app.DailyWholePlantPREditField.Value = app.Prod_Data.Daily_P_to_R;
            app.delCEditField.Value = app.Prod_Data.del13C_new;
            app.DailyHRequirementhdEditField.Value = app.Prod_Data.Daily_Hsat_Requirement;
        
            
        end

        % Value changed function: KSODissociationConstantDropDown
        function KSODissociationConstantDropDownValueChanged(app, event)
            
            % Get new KHSO4 dissociation constant
            if app.KSODissociationConstantDropDown.Value == "Dickson (PREFERRED)"
                app.WQ_Params.KHSO4 = 1;
            elseif app.KSODissociationConstantDropDown.Value == "Khoo"
                app.WQ_Params.KHSO4 = 2;
            elseif app.KSODissociationConstantDropDown.Value == "Waters & Millero"
                app.WQ_Params.KHSO4 = 3;
            end

            % Update DIC values from CO2SYS
            [Param1, Param2] = Get_CO2sys_Drivers(app.WQ_Params);   
            app.CO2SYS_Data = CO2SYS_for_app(Param1,Param2,app.WQ_Params.PAR1TYPE,app.WQ_Params.PAR2TYPE,app.WQ_Params.Sal,app.WQ_Params.Temp,app.WQ_Params.Temp,0,app.Site_Data.depth,app.WQ_Params.SiO4,app.WQ_Params.PO4,app.WQ_Params.NH4,app.WQ_Params.H2S,app.WQ_Params.pH_Scale,app.WQ_Params.K1K2,app.WQ_Params.KHSO4,app.WQ_Params.KHF,app.WQ_Params.Borate);
            app.pHEditField.Value = app.CO2SYS_Data.pHout;
            app.WQ_Params.pH = app.CO2SYS_Data.pHout;
            app.pCOinWaterAtmEditField.Value = app.CO2SYS_Data.pCO2out;
            app.WQ_Params.pCO2_Water = app.CO2SYS_Data.pCO2out;
            app.COEditField.Value = app.CO2SYS_Data.CO2out;
            app.WQ_Params.CO2 = app.CO2SYS_Data.CO2out;
            app.HCOEditField.Value = app.CO2SYS_Data.HCO3out;
            app.WQ_Params.HCO3 = app.CO2SYS_Data.HCO3out;
            app.COEditField_2.Value = app.CO2SYS_Data.CO3out;
            app.WQ_Params.CO3 = app.CO2SYS_Data.CO3out;
            app.TotalDICEditField.Value = app.CO2SYS_Data.TCO2;
            app.WQ_Params.TCO2 = app.CO2SYS_Data.TCO2;

            % Update gas saturation values
            app.Gas_Sat = Gas_Solubility_Model(app.WQ_Params.Temp, app.WQ_Params.Sal, app.Site_Data.depth, app.WQ_Params.pCO2_Air);
            app.Gas_Sat.O2_Depth = app.Gas_Sat.O2_1atm * (app.Site_Data.depth/10+1);
            app.OAirSatatDepthEditField.Value = 100 * app.OEditField.Value/app.Gas_Sat.O2_Depth;
            app.pCOAirSatatSfcEditField.Value = 100 * app.pCOinWaterAtmEditField.Value/app.Gas_Sat.pCO2_1atm;
            app.pCOAirSatatDepthEditField.Value = 100 * app.pCOinWaterAtmEditField.Value/app.Gas_Sat.pCO2_Depth;

            % Compute instantaneous and daily metabolic rates
            app.Prod_Data = produce_v301_for_app(app.WQ_Params, app.Site_Data, app.Plant_Arch, app.Photo_Params, app.Biodis_Data, app.EdEu_Data, app.E0_sfc_PAR_mubar, app.PlantTypeDropDown);

            % Populate the Plant Productivity and Metabolism Panel
            app.PumolCmleafsEditField_2.Value = app.Prod_Data.Pm;
            app.PumolCmleafsEditField.Value = app.Prod_Data.PE;
            app.PPEditField.Value = app.Prod_Data.PE / app.Prod_Data.Pm;
            app.InstantaneousPumolCmleafsEditField.Value = app.Prod_Data.Mean_Biomass_normalized_P_vs_E;
            app.InstantaneousPumolCshootsEditField.Value = app.Prod_Data.P_now_per_shoot;
            app.InstantaneousCanopyPPEditField.Value = app.Prod_Data.P_now_over_PE;
            app.InstantaneousLeafPREditField.Value = app.Prod_Data.Instantaneous_Leaf_P_to_R;
            app.InstantaneousWholePlantPREditField.Value = app.Prod_Data.Instantaneous_Shoot_P_to_R;
            app.DailyWholePlantPREditField.Value = app.Prod_Data.Daily_P_to_R;
            app.delCEditField.Value = app.Prod_Data.del13C_new;
            app.DailyHRequirementhdEditField.Value = app.Prod_Data.Daily_Hsat_Requirement;

        end

        % Value changed function: KHFDissociationConstantDropDown
        function KHFDissociationConstantDropDownValueChanged(app, event)
            
            % Get new KHF dissociation constant
            if app.KHFDissociationConstantDropDown.Value == "Dickson & Riley 1979"
                app.WQ_Params.KHF = 1;
            elseif pp.KHFDissociationConstantDropDown.Value == "Perez & Fraga (PREFERRED)"
                app.WQ_Params.KHF = 2;
            end
              
            % Update DIC values from CO2SYS
            [Param1, Param2] = Get_CO2sys_Drivers(app.WQ_Params);   
            app.CO2SYS_Data = CO2SYS_for_app(Param1,Param2,app.WQ_Params.PAR1TYPE,app.WQ_Params.PAR2TYPE,app.WQ_Params.Sal,app.WQ_Params.Temp,app.WQ_Params.Temp,0,app.Site_Data.depth,app.WQ_Params.SiO4,app.WQ_Params.PO4,app.WQ_Params.NH4,app.WQ_Params.H2S,app.WQ_Params.pH_Scale,app.WQ_Params.K1K2,app.WQ_Params.KHSO4,app.WQ_Params.KHF,app.WQ_Params.Borate);
            app.pHEditField.Value = app.CO2SYS_Data.pHout;
            app.WQ_Params.pH = app.CO2SYS_Data.pHout;
            app.pCOinWaterAtmEditField.Value = app.CO2SYS_Data.pCO2out;
            app.WQ_Params.pCO2_Water = app.CO2SYS_Data.pCO2out;
            app.COEditField.Value = app.CO2SYS_Data.CO2out;
            app.WQ_Params.CO2 = app.CO2SYS_Data.CO2out;
            app.HCOEditField.Value = app.CO2SYS_Data.HCO3out;
            app.WQ_Params.HCO3 = app.CO2SYS_Data.HCO3out;
            app.COEditField_2.Value = app.CO2SYS_Data.CO3out;
            app.WQ_Params.CO3 = app.CO2SYS_Data.CO3out;
            app.TotalDICEditField.Value = app.CO2SYS_Data.TCO2;
            app.WQ_Params.TCO2 = app.CO2SYS_Data.TCO2;

            % Update gas saturation values
            app.Gas_Sat = Gas_Solubility_Model(app.WQ_Params.Temp, app.WQ_Params.Sal, app.Site_Data.depth, app.WQ_Params.pCO2_Air);
            app.Gas_Sat.O2_Depth = app.Gas_Sat.O2_1atm * (app.Site_Data.depth/10+1);
            app.OAirSatatDepthEditField.Value = 100 * app.OEditField.Value/app.Gas_Sat.O2_Depth;
            app.pCOAirSatatSfcEditField.Value = 100 * app.pCOinWaterAtmEditField.Value/app.Gas_Sat.pCO2_1atm;
            app.pCOAirSatatDepthEditField.Value = 100 * app.pCOinWaterAtmEditField.Value/app.Gas_Sat.pCO2_Depth;
           
            % Compute instantaneous and daily metabolic rates
            app.Prod_Data = produce_v301_for_app(app.WQ_Params, app.Site_Data, app.Plant_Arch, app.Photo_Params, app.Biodis_Data, app.EdEu_Data, app.E0_sfc_PAR_mubar, app.PlantTypeDropDown);

            % Populate the Plant Productivity and Metabolism Panel
            app.PumolCmleafsEditField_2.Value = app.Prod_Data.Pm;
            app.PumolCmleafsEditField.Value = app.Prod_Data.PE;
            app.PPEditField.Value = app.Prod_Data.PE / app.Prod_Data.Pm;
            app.InstantaneousPumolCmleafsEditField.Value = app.Prod_Data.Mean_Biomass_normalized_P_vs_E;
            app.InstantaneousPumolCshootsEditField.Value = app.Prod_Data.P_now_per_shoot;
            app.InstantaneousCanopyPPEditField.Value = app.Prod_Data.P_now_over_PE;
            app.InstantaneousLeafPREditField.Value = app.Prod_Data.Instantaneous_Leaf_P_to_R;
            app.InstantaneousWholePlantPREditField.Value = app.Prod_Data.Instantaneous_Shoot_P_to_R;
            app.DailyWholePlantPREditField.Value = app.Prod_Data.Daily_P_to_R;
            app.delCEditField.Value = app.Prod_Data.del13C_new;
            app.DailyHRequirementhdEditField.Value = app.Prod_Data.Daily_Hsat_Requirement;


        end

        % Value changed function: BorateSalinityDropDown
        function BorateSalinityDropDownValueChanged(app, event)
            
            % Get new borate:salinity ratio
            if app.BorateSalinityDropDown.Value == "Uppstrom 1979"
                app.WQ_Params.Borate = 1;
            elseif app.BorateSalinityDropDown.Value == "Lee 2010"
                app.WQ_Params.Borate = 2;
            end

            % Update DIC values from CO2SYS
            [Param1, Param2] = Get_CO2sys_Drivers(app.WQ_Params);   
            app.CO2SYS_Data = CO2SYS_for_app(Param1,Param2,app.WQ_Params.PAR1TYPE,app.WQ_Params.PAR2TYPE,app.WQ_Params.Sal,app.WQ_Params.Temp,app.WQ_Params.Temp,0,app.Site_Data.depth,app.WQ_Params.SiO4,app.WQ_Params.PO4,app.WQ_Params.NH4,app.WQ_Params.H2S,app.WQ_Params.pH_Scale,app.WQ_Params.K1K2,app.WQ_Params.KHSO4,app.WQ_Params.KHF,app.WQ_Params.Borate);
            app.pHEditField.Value = app.CO2SYS_Data.pHout;
            app.WQ_Params.pH = app.CO2SYS_Data.pHout;
            app.pCOinWaterAtmEditField.Value = app.CO2SYS_Data.pCO2out;
            app.WQ_Params.pCO2_Water = app.CO2SYS_Data.pCO2out;
            app.COEditField.Value = app.CO2SYS_Data.CO2out;
            app.WQ_Params.CO2 = app.CO2SYS_Data.CO2out;
            app.HCOEditField.Value = app.CO2SYS_Data.HCO3out;
            app.WQ_Params.HCO3 = app.CO2SYS_Data.HCO3out;
            app.COEditField_2.Value = app.CO2SYS_Data.CO3out;
            app.WQ_Params.CO3 = app.CO2SYS_Data.CO3out;
            app.TotalDICEditField.Value = app.CO2SYS_Data.TCO2;
            app.WQ_Params.TCO2 = app.CO2SYS_Data.TCO2;

            % Update gas saturation values
            app.Gas_Sat = Gas_Solubility_Model(app.WQ_Params.Temp, app.WQ_Params.Sal, app.Site_Data.depth, app.WQ_Params.pCO2_Air);
            app.Gas_Sat.O2_Depth = app.Gas_Sat.O2_1atm * (app.Site_Data.depth/10+1);
            app.OAirSatatDepthEditField.Value = 100 * app.OEditField.Value/app.Gas_Sat.O2_Depth;
            app.pCOAirSatatSfcEditField.Value = 100 * app.pCOinWaterAtmEditField.Value/app.Gas_Sat.pCO2_1atm;
            app.pCOAirSatatDepthEditField.Value = 100 * app.pCOinWaterAtmEditField.Value/app.Gas_Sat.pCO2_Depth;

            % Compute instantaneous and daily metabolic rates
            app.Prod_Data = produce_v301_for_app(app.WQ_Params, app.Site_Data, app.Plant_Arch, app.Photo_Params, app.Biodis_Data, app.EdEu_Data, app.E0_sfc_PAR_mubar, app.PlantTypeDropDown);

            % Populate the Plant Productivity and Metabolism Panel
            app.PumolCmleafsEditField_2.Value = app.Prod_Data.Pm;
            app.PumolCmleafsEditField.Value = app.Prod_Data.PE;
            app.PPEditField.Value = app.Prod_Data.PE / app.Prod_Data.Pm;
            app.InstantaneousPumolCmleafsEditField.Value = app.Prod_Data.Mean_Biomass_normalized_P_vs_E;
            app.InstantaneousPumolCshootsEditField.Value = app.Prod_Data.P_now_per_shoot;
            app.InstantaneousCanopyPPEditField.Value = app.Prod_Data.P_now_over_PE;
            app.InstantaneousLeafPREditField.Value = app.Prod_Data.Instantaneous_Leaf_P_to_R;
            app.InstantaneousWholePlantPREditField.Value = app.Prod_Data.Instantaneous_Shoot_P_to_R;
            app.DailyWholePlantPREditField.Value = app.Prod_Data.Daily_P_to_R;
            app.delCEditField.Value = app.Prod_Data.del13C_new;
            app.DailyHRequirementhdEditField.Value = app.Prod_Data.Daily_Hsat_Requirement;

        end

        % Value changed function: AlkalinityEditField
        function AlkalinityEditFieldValueChanged(app, event)
            app.WQ_Params.Alk = app.AlkalinityEditField.Value;

              % Update DIC values
            [Param1, Param2] = Get_CO2sys_Drivers(app.WQ_Params);   
            app.CO2SYS_Data = CO2SYS_for_app(Param1,Param2,app.WQ_Params.PAR1TYPE,app.WQ_Params.PAR2TYPE,app.WQ_Params.Sal,app.WQ_Params.Temp,app.WQ_Params.Temp,0,app.Site_Data.depth,app.WQ_Params.SiO4,app.WQ_Params.PO4,app.WQ_Params.NH4,app.WQ_Params.H2S,app.WQ_Params.pH_Scale,app.WQ_Params.K1K2,app.WQ_Params.KHSO4,app.WQ_Params.KHF,app.WQ_Params.Borate);
            app.pHEditField.Value = app.CO2SYS_Data.pHout;
            app.WQ_Params.pH = app.CO2SYS_Data.pHout;
            app.pCOinWaterAtmEditField.Value = app.CO2SYS_Data.pCO2out;
            app.WQ_Params.pCO2_Water = app.CO2SYS_Data.pCO2out;
            app.COEditField.Value = app.CO2SYS_Data.CO2out;
            app.WQ_Params.CO2 = app.CO2SYS_Data.CO2out;
            app.HCOEditField.Value = app.CO2SYS_Data.HCO3out;
            app.WQ_Params.HCO3 = app.CO2SYS_Data.HCO3out;
            app.COEditField_2.Value = app.CO2SYS_Data.CO3out;
            app.WQ_Params.CO3 = app.CO2SYS_Data.CO3out;
            app.TotalDICEditField.Value = app.CO2SYS_Data.TCO2;
            app.WQ_Params.TCO2 = app.CO2SYS_Data.TCO2;

            % Update gas saturation values
            app.Gas_Sat = Gas_Solubility_Model(app.WQ_Params.Temp, app.WQ_Params.Sal, app.Site_Data.depth, app.WQ_Params.pCO2_Air);
            app.Gas_Sat.O2_Depth = app.Gas_Sat.O2_1atm * (app.Site_Data.depth/10+1);
            app.OAirSatatDepthEditField.Value = 100 * app.OEditField.Value/app.Gas_Sat.O2_Depth;
            app.pCOAirSatatSfcEditField.Value = 100 * app.pCOinWaterAtmEditField.Value/app.Gas_Sat.pCO2_1atm;
            app.pCOAirSatatDepthEditField.Value = 100 * app.pCOinWaterAtmEditField.Value/app.Gas_Sat.pCO2_Depth;

            % Compute instantaneous and daily metabolic rates
            app.Prod_Data = produce_v301_for_app(app.WQ_Params, app.Site_Data, app.Plant_Arch, app.Photo_Params, app.Biodis_Data, app.EdEu_Data, app.E0_sfc_PAR_mubar, app.PlantTypeDropDown);

            % Populate the Plant Productivity and Metabolism Panel
            app.PumolCmleafsEditField_2.Value = app.Prod_Data.Pm;
            app.PumolCmleafsEditField.Value = app.Prod_Data.PE;
            app.PPEditField.Value = app.Prod_Data.PE / app.Prod_Data.Pm;
            app.InstantaneousPumolCmleafsEditField.Value = app.Prod_Data.Mean_Biomass_normalized_P_vs_E;
            app.InstantaneousPumolCshootsEditField.Value = app.Prod_Data.P_now_per_shoot;
            app.InstantaneousCanopyPPEditField.Value = app.Prod_Data.P_now_over_PE;
            app.InstantaneousLeafPREditField.Value = app.Prod_Data.Instantaneous_Leaf_P_to_R;
            app.InstantaneousWholePlantPREditField.Value = app.Prod_Data.Instantaneous_Shoot_P_to_R;
            app.DailyWholePlantPREditField.Value = app.Prod_Data.Daily_P_to_R;
            app.delCEditField.Value = app.Prod_Data.del13C_new;
            app.DailyHRequirementhdEditField.Value = app.Prod_Data.Daily_Hsat_Requirement;

            
        end

        % Value changed function: OEditField
        function OEditFieldValueChanged(app, event)
            app.WQ_Params.O2_percent_old = app.WQ_Params.O2_percent;
            app.WQ_Params.O2_uM = app.OEditField.Value;
            app.OAirSatatSfcEditField.Value = 100*app.WQ_Params.O2_uM/app.Gas_Sat.O2_1atm;
            app.OAirSatatDepthEditField.Value = 100*app.WQ_Params.O2_uM/app.Gas_Sat.O2_Depth;
                       
        end

        % Value changed function: OAirSatatSfcEditField
        function OAirSatatSfcEditFieldValueChanged(app, event)
            app.WQ_Params.O2_uM = app.WQ_Params.O2_uM * (app.OAirSatatSfcEditField.Value/app.WQ_Params.O2_percent);
            app.OEditField.Value = app.WQ_Params.O2_uM;
            app.WQ_Params.O2_percent = app.OAirSatatSfcEditField.Value;
            app.OAirSatatDepthEditField.Value = 100*app.WQ_Params.O2_uM/app.Gas_Sat.O2_Depth;

            % Compute instantaneous and daily metabolic rates
            app.Prod_Data = produce_v301_for_app(app.WQ_Params, app.Site_Data, app.Plant_Arch, app.Photo_Params, app.Biodis_Data, app.EdEu_Data, app.E0_sfc_PAR_mubar, app.PlantTypeDropDown);

            % Populate the Plant Productivity and Metabolism Panel
            app.PumolCmleafsEditField_2.Value = app.Prod_Data.Pm;
            app.PumolCmleafsEditField.Value = app.Prod_Data.PE;
            app.PPEditField.Value = app.Prod_Data.PE / app.Prod_Data.Pm;
            app.InstantaneousPumolCmleafsEditField.Value = app.Prod_Data.Mean_Biomass_normalized_P_vs_E;
            app.InstantaneousPumolCshootsEditField.Value = app.Prod_Data.P_now_per_shoot;
            app.InstantaneousCanopyPPEditField.Value = app.Prod_Data.P_now_over_PE;
            app.InstantaneousLeafPREditField.Value = app.Prod_Data.Instantaneous_Leaf_P_to_R;
            app.InstantaneousWholePlantPREditField.Value = app.Prod_Data.Instantaneous_Shoot_P_to_R;
            app.DailyWholePlantPREditField.Value = app.Prod_Data.Daily_P_to_R;
            app.delCEditField.Value = app.Prod_Data.del13C_new;
            app.DailyHRequirementhdEditField.Value = app.Prod_Data.Daily_Hsat_Requirement;

        end

        % Value changed function: OAirSatatDepthEditField
        function OAirSatatDepthEditFieldValueChanged(app, event)
            value = app.OAirSatatDepthEditField.Value;
            
        end

        % Value changed function: SiOEditField
        function SiOEditFieldValueChanged(app, event)
            app.WQ_Params.SiO4 = app.SiOEditField.Value;

               % Update DIC values
            [Param1, Param2] = Get_CO2sys_Drivers(app.WQ_Params);   
            app.CO2SYS_Data = CO2SYS_for_app(Param1,Param2,app.WQ_Params.PAR1TYPE,app.WQ_Params.PAR2TYPE,app.WQ_Params.Sal,app.WQ_Params.Temp,app.WQ_Params.Temp,0,app.Site_Data.depth,app.WQ_Params.SiO4,app.WQ_Params.PO4,app.WQ_Params.NH4,app.WQ_Params.H2S,app.WQ_Params.pH_Scale,app.WQ_Params.K1K2,app.WQ_Params.KHSO4,app.WQ_Params.KHF,app.WQ_Params.Borate);
            app.pHEditField.Value = app.CO2SYS_Data.pHout;
            app.WQ_Params.pH = app.CO2SYS_Data.pHout;
            app.pCOinWaterAtmEditField.Value = app.CO2SYS_Data.pCO2out;
            app.WQ_Params.pCO2_Water = app.CO2SYS_Data.pCO2out;
            app.COEditField.Value = app.CO2SYS_Data.CO2out;
            app.WQ_Params.CO2 = app.CO2SYS_Data.CO2out;
            app.HCOEditField.Value = app.CO2SYS_Data.HCO3out;
            app.WQ_Params.HCO3 = app.CO2SYS_Data.HCO3out;
            app.COEditField_2.Value = app.CO2SYS_Data.CO3out;
            app.WQ_Params.CO3 = app.CO2SYS_Data.CO3out;
            app.TotalDICEditField.Value = app.CO2SYS_Data.TCO2;
            app.WQ_Params.TCO2 = app.CO2SYS_Data.TCO2;

            % Update gas saturation values
            app.Gas_Sat = Gas_Solubility_Model(app.WQ_Params.Temp, app.WQ_Params.Sal, app.Site_Data.depth, app.WQ_Params.pCO2_Air);
            app.Gas_Sat.O2_Depth = app.Gas_Sat.O2_1atm * (app.Site_Data.depth/10+1);
            app.OAirSatatDepthEditField.Value = 100 * app.OEditField.Value/app.Gas_Sat.O2_Depth;
            app.pCOAirSatatSfcEditField.Value = 100 * app.pCOinWaterAtmEditField.Value/app.Gas_Sat.pCO2_1atm;
            app.pCOAirSatatDepthEditField.Value = 100 * app.pCOinWaterAtmEditField.Value/app.Gas_Sat.pCO2_Depth;

            % Compute instantaneous and daily metabolic rates
            app.Prod_Data = produce_v301_for_app(app.WQ_Params, app.Site_Data, app.Plant_Arch, app.Photo_Params, app.Biodis_Data, app.EdEu_Data, app.E0_sfc_PAR_mubar, app.PlantTypeDropDown);

            % Populate the Plant Productivity and Metabolism Panel
            app.PumolCmleafsEditField_2.Value = app.Prod_Data.Pm;
            app.PumolCmleafsEditField.Value = app.Prod_Data.PE;
            app.PPEditField.Value = app.Prod_Data.PE / app.Prod_Data.Pm;
            app.InstantaneousPumolCmleafsEditField.Value = app.Prod_Data.Mean_Biomass_normalized_P_vs_E;
            app.InstantaneousPumolCshootsEditField.Value = app.Prod_Data.P_now_per_shoot;
            app.InstantaneousCanopyPPEditField.Value = app.Prod_Data.P_now_over_PE;
            app.InstantaneousLeafPREditField.Value = app.Prod_Data.Instantaneous_Leaf_P_to_R;
            app.InstantaneousWholePlantPREditField.Value = app.Prod_Data.Instantaneous_Shoot_P_to_R;
            app.DailyWholePlantPREditField.Value = app.Prod_Data.Daily_P_to_R;
            app.delCEditField.Value = app.Prod_Data.del13C_new;
            app.DailyHRequirementhdEditField.Value = app.Prod_Data.Daily_Hsat_Requirement;
        
            
        end

        % Value changed function: POEditField
        function POEditFieldValueChanged(app, event)
            app.WQ_Params.PO4 = app.POEditField.Value;

               % Update DIC values
            [Param1, Param2] = Get_CO2sys_Drivers(app.WQ_Params);   
            app.CO2SYS_Data = CO2SYS_for_app(Param1,Param2,app.WQ_Params.PAR1TYPE,app.WQ_Params.PAR2TYPE,app.WQ_Params.Sal,app.WQ_Params.Temp,app.WQ_Params.Temp,0,app.Site_Data.depth,app.WQ_Params.SiO4,app.WQ_Params.PO4,app.WQ_Params.NH4,app.WQ_Params.H2S,app.WQ_Params.pH_Scale,app.WQ_Params.K1K2,app.WQ_Params.KHSO4,app.WQ_Params.KHF,app.WQ_Params.Borate);
            app.pHEditField.Value = app.CO2SYS_Data.pHout;
            app.WQ_Params.pH = app.CO2SYS_Data.pHout;
            app.pCOinWaterAtmEditField.Value = app.CO2SYS_Data.pCO2out;
            app.WQ_Params.pCO2_Water = app.CO2SYS_Data.pCO2out;
            app.COEditField.Value = app.CO2SYS_Data.CO2out;
            app.WQ_Params.CO2 = app.CO2SYS_Data.CO2out;
            app.HCOEditField.Value = app.CO2SYS_Data.HCO3out;
            app.WQ_Params.HCO3 = app.CO2SYS_Data.HCO3out;
            app.COEditField_2.Value = app.CO2SYS_Data.CO3out;
            app.WQ_Params.CO3 = app.CO2SYS_Data.CO3out;
            app.TotalDICEditField.Value = app.CO2SYS_Data.TCO2;
            app.WQ_Params.TCO2 = app.CO2SYS_Data.TCO2;

            % Update gas saturation values
            app.Gas_Sat = Gas_Solubility_Model(app.WQ_Params.Temp, app.WQ_Params.Sal, app.Site_Data.depth, app.WQ_Params.pCO2_Air);
            app.Gas_Sat.O2_Depth = app.Gas_Sat.O2_1atm * (app.Site_Data.depth/10+1);
            app.OAirSatatDepthEditField.Value = 100 * app.OEditField.Value/app.Gas_Sat.O2_Depth;
            app.pCOAirSatatSfcEditField.Value = 100 * app.pCOinWaterAtmEditField.Value/app.Gas_Sat.pCO2_1atm;
            app.pCOAirSatatDepthEditField.Value = 100 * app.pCOinWaterAtmEditField.Value/app.Gas_Sat.pCO2_Depth;

            % Compute instantaneous and daily metabolic rates
            app.Prod_Data = produce_v301_for_app(app.WQ_Params, app.Site_Data, app.Plant_Arch, app.Photo_Params, app.Biodis_Data, app.EdEu_Data, app.E0_sfc_PAR_mubar, app.PlantTypeDropDown);

            % Populate the Plant Productivity and Metabolism Panel
            app.PumolCmleafsEditField_2.Value = app.Prod_Data.Pm;
            app.PumolCmleafsEditField.Value = app.Prod_Data.PE;
            app.PPEditField.Value = app.Prod_Data.PE / app.Prod_Data.Pm;
            app.InstantaneousPumolCmleafsEditField.Value = app.Prod_Data.Mean_Biomass_normalized_P_vs_E;
            app.InstantaneousPumolCshootsEditField.Value = app.Prod_Data.P_now_per_shoot;
            app.InstantaneousCanopyPPEditField.Value = app.Prod_Data.P_now_over_PE;
            app.InstantaneousLeafPREditField.Value = app.Prod_Data.Instantaneous_Leaf_P_to_R;
            app.InstantaneousWholePlantPREditField.Value = app.Prod_Data.Instantaneous_Shoot_P_to_R;
            app.DailyWholePlantPREditField.Value = app.Prod_Data.Daily_P_to_R;
            app.delCEditField.Value = app.Prod_Data.del13C_new;
            app.DailyHRequirementhdEditField.Value = app.Prod_Data.Daily_Hsat_Requirement;
        

        end

        % Value changed function: NHEditField
        function NHEditFieldValueChanged(app, event)
            app.WQ_Params.NH4 = app.NHEditField.Value;

               % Update DIC values
            [Param1, Param2] = Get_CO2sys_Drivers(app.WQ_Params);   
            app.CO2SYS_Data = CO2SYS_for_app(Param1,Param2,app.WQ_Params.PAR1TYPE,app.WQ_Params.PAR2TYPE,app.WQ_Params.Sal,app.WQ_Params.Temp,app.WQ_Params.Temp,0,app.Site_Data.depth,app.WQ_Params.SiO4,app.WQ_Params.PO4,app.WQ_Params.NH4,app.WQ_Params.H2S,app.WQ_Params.pH_Scale,app.WQ_Params.K1K2,app.WQ_Params.KHSO4,app.WQ_Params.KHF,app.WQ_Params.Borate);
            app.pHEditField.Value = app.CO2SYS_Data.pHout;
            app.WQ_Params.pH = app.CO2SYS_Data.pHout;
            app.pCOinWaterAtmEditField.Value = app.CO2SYS_Data.pCO2out;
            app.WQ_Params.pCO2_Water = app.CO2SYS_Data.pCO2out;
            app.COEditField.Value = app.CO2SYS_Data.CO2out;
            app.WQ_Params.CO2 = app.CO2SYS_Data.CO2out;
            app.HCOEditField.Value = app.CO2SYS_Data.HCO3out;
            app.WQ_Params.HCO3 = app.CO2SYS_Data.HCO3out;
            app.COEditField_2.Value = app.CO2SYS_Data.CO3out;
            app.WQ_Params.CO3 = app.CO2SYS_Data.CO3out;
            app.TotalDICEditField.Value = app.CO2SYS_Data.TCO2;
            app.WQ_Params.TCO2 = app.CO2SYS_Data.TCO2;

            % Update gas saturation values
            app.Gas_Sat = Gas_Solubility_Model(app.WQ_Params.Temp, app.WQ_Params.Sal, app.Site_Data.depth, app.WQ_Params.pCO2_Air);
            app.Gas_Sat.O2_Depth = app.Gas_Sat.O2_1atm * (app.Site_Data.depth/10+1);
            app.OAirSatatDepthEditField.Value = 100 * app.OEditField.Value/app.Gas_Sat.O2_Depth;
            app.pCOAirSatatSfcEditField.Value = 100 * app.pCOinWaterAtmEditField.Value/app.Gas_Sat.pCO2_1atm;
            app.pCOAirSatatDepthEditField.Value = 100 * app.pCOinWaterAtmEditField.Value/app.Gas_Sat.pCO2_Depth;

            % Compute instantaneous and daily metabolic rates
            app.Prod_Data = produce_v301_for_app(app.WQ_Params, app.Site_Data, app.Plant_Arch, app.Photo_Params, app.Biodis_Data, app.EdEu_Data, app.E0_sfc_PAR_mubar, app.PlantTypeDropDown);

            % Populate the Plant Productivity and Metabolism Panel
            app.PumolCmleafsEditField_2.Value = app.Prod_Data.Pm;
            app.PumolCmleafsEditField.Value = app.Prod_Data.PE;
            app.PPEditField.Value = app.Prod_Data.PE / app.Prod_Data.Pm;
            app.InstantaneousPumolCmleafsEditField.Value = app.Prod_Data.Mean_Biomass_normalized_P_vs_E;
            app.InstantaneousPumolCshootsEditField.Value = app.Prod_Data.P_now_per_shoot;
            app.InstantaneousCanopyPPEditField.Value = app.Prod_Data.P_now_over_PE;
            app.InstantaneousLeafPREditField.Value = app.Prod_Data.Instantaneous_Leaf_P_to_R;
            app.InstantaneousWholePlantPREditField.Value = app.Prod_Data.Instantaneous_Shoot_P_to_R;
            app.DailyWholePlantPREditField.Value = app.Prod_Data.Daily_P_to_R;
            app.delCEditField.Value = app.Prod_Data.del13C_new;
            app.DailyHRequirementhdEditField.Value = app.Prod_Data.Daily_Hsat_Requirement;
       

        end

        % Value changed function: NOEditField
        function NOEditFieldValueChanged(app, event)
            app.WQ_Params.NO3 = app.NOEditField.Value;

            % Update DIC values
            [Param1, Param2] = Get_CO2sys_Drivers(app.WQ_Params);   
            app.CO2SYS_Data = CO2SYS_for_app(Param1,Param2,app.WQ_Params.PAR1TYPE,app.WQ_Params.PAR2TYPE,app.WQ_Params.Sal,app.WQ_Params.Temp,app.WQ_Params.Temp,0,app.Site_Data.depth,app.WQ_Params.SiO4,app.WQ_Params.PO4,app.WQ_Params.NH4,app.WQ_Params.H2S,app.WQ_Params.pH_Scale,app.WQ_Params.K1K2,app.WQ_Params.KHSO4,app.WQ_Params.KHF,app.WQ_Params.Borate);
            app.pHEditField.Value = app.CO2SYS_Data.pHout;
            app.WQ_Params.pH = app.CO2SYS_Data.pHout;
            app.pCOinWaterAtmEditField.Value = app.CO2SYS_Data.pCO2out;
            app.WQ_Params.pCO2_Water = app.CO2SYS_Data.pCO2out;
            app.COEditField.Value = app.CO2SYS_Data.CO2out;
            app.WQ_Params.CO2 = app.CO2SYS_Data.CO2out;
            app.HCOEditField.Value = app.CO2SYS_Data.HCO3out;
            app.WQ_Params.HCO3 = app.CO2SYS_Data.HCO3out;
            app.COEditField_2.Value = app.CO2SYS_Data.CO3out;
            app.WQ_Params.CO3 = app.CO2SYS_Data.CO3out;
            app.TotalDICEditField.Value = app.CO2SYS_Data.TCO2;
            app.WQ_Params.TCO2 = app.CO2SYS_Data.TCO2;

            % Update gas saturation values
            app.Gas_Sat = Gas_Solubility_Model(app.WQ_Params.Temp, app.WQ_Params.Sal, app.Site_Data.depth, app.WQ_Params.pCO2_Air);
            app.Gas_Sat.O2_Depth = app.Gas_Sat.O2_1atm * (app.Site_Data.depth/10+1);
            app.OAirSatatDepthEditField.Value = 100 * app.OEditField.Value/app.Gas_Sat.O2_Depth;
            app.pCOAirSatatSfcEditField.Value = 100 * app.pCOinWaterAtmEditField.Value/app.Gas_Sat.pCO2_1atm;
            app.pCOAirSatatDepthEditField.Value = 100 * app.pCOinWaterAtmEditField.Value/app.Gas_Sat.pCO2_Depth;

            % Compute instantaneous and daily metabolic rates
            app.Prod_Data = produce_v301_for_app(app.WQ_Params, app.Site_Data, app.Plant_Arch, app.Photo_Params, app.Biodis_Data, app.EdEu_Data, app.E0_sfc_PAR_mubar, app.PlantTypeDropDown);

            % Populate the Plant Productivity and Metabolism Panel
            app.PumolCmleafsEditField_2.Value = app.Prod_Data.Pm;
            app.PumolCmleafsEditField.Value = app.Prod_Data.PE;
            app.PPEditField.Value = app.Prod_Data.PE / app.Prod_Data.Pm;
            app.InstantaneousPumolCmleafsEditField.Value = app.Prod_Data.Mean_Biomass_normalized_P_vs_E;
            app.InstantaneousPumolCshootsEditField.Value = app.Prod_Data.P_now_per_shoot;
            app.InstantaneousCanopyPPEditField.Value = app.Prod_Data.P_now_over_PE;
            app.InstantaneousLeafPREditField.Value = app.Prod_Data.Instantaneous_Leaf_P_to_R;
            app.InstantaneousWholePlantPREditField.Value = app.Prod_Data.Instantaneous_Shoot_P_to_R;
            app.DailyWholePlantPREditField.Value = app.Prod_Data.Daily_P_to_R;
            app.delCEditField.Value = app.Prod_Data.del13C_new;
            app.DailyHRequirementhdEditField.Value = app.Prod_Data.Daily_Hsat_Requirement;
   

        end

        % Value changed function: HSEditField
        function HSEditFieldValueChanged(app, event)
            app.WQ_Params.H2S = app.HSEditField.Value;

               % Update DIC values
            [Param1, Param2] = Get_CO2sys_Drivers(app.WQ_Params);   
            app.CO2SYS_Data = CO2SYS_for_app(Param1,Param2,app.WQ_Params.PAR1TYPE,app.WQ_Params.PAR2TYPE,app.WQ_Params.Sal,app.WQ_Params.Temp,app.WQ_Params.Temp,0,app.Site_Data.depth,app.WQ_Params.SiO4,app.WQ_Params.PO4,app.WQ_Params.NH4,app.WQ_Params.H2S,app.WQ_Params.pH_Scale,app.WQ_Params.K1K2,app.WQ_Params.KHSO4,app.WQ_Params.KHF,app.WQ_Params.Borate);
            app.pHEditField.Value = app.CO2SYS_Data.pHout;
            app.WQ_Params.pH = app.CO2SYS_Data.pHout;
            app.pCOinWaterAtmEditField.Value = app.CO2SYS_Data.pCO2out;
            app.WQ_Params.pCO2_Water = app.CO2SYS_Data.pCO2out;
            app.COEditField.Value = app.CO2SYS_Data.CO2out;
            app.WQ_Params.CO2 = app.CO2SYS_Data.CO2out;
            app.HCOEditField.Value = app.CO2SYS_Data.HCO3out;
            app.WQ_Params.HCO3 = app.CO2SYS_Data.HCO3out;
            app.COEditField_2.Value = app.CO2SYS_Data.CO3out;
            app.WQ_Params.CO3 = app.CO2SYS_Data.CO3out;
            app.TotalDICEditField.Value = app.CO2SYS_Data.TCO2;
            app.WQ_Params.TCO2 = app.CO2SYS_Data.TCO2;

            % Update gas saturation values
            app.Gas_Sat = Gas_Solubility_Model(app.WQ_Params.Temp, app.WQ_Params.Sal, app.Site_Data.depth, app.WQ_Params.pCO2_Air);
            app.Gas_Sat.O2_Depth = app.Gas_Sat.O2_1atm * (app.Site_Data.depth/10+1);
            app.OAirSatatDepthEditField.Value = 100 * app.OEditField.Value/app.Gas_Sat.O2_Depth;
            app.pCOAirSatatSfcEditField.Value = 100 * app.pCOinWaterAtmEditField.Value/app.Gas_Sat.pCO2_1atm;
            app.pCOAirSatatDepthEditField.Value = 100 * app.pCOinWaterAtmEditField.Value/app.Gas_Sat.pCO2_Depth;

            % Compute instantaneous and daily metabolic rates
            app.Prod_Data = produce_v301_for_app(app.WQ_Params, app.Site_Data, app.Plant_Arch, app.Photo_Params, app.Biodis_Data, app.EdEu_Data, app.E0_sfc_PAR_mubar, app.PlantTypeDropDown);

            % Populate the Plant Productivity and Metabolism Panel
            app.PumolCmleafsEditField_2.Value = app.Prod_Data.Pm;
            app.PumolCmleafsEditField.Value = app.Prod_Data.PE;
            app.PPEditField.Value = app.Prod_Data.PE / app.Prod_Data.Pm;
            app.InstantaneousPumolCmleafsEditField.Value = app.Prod_Data.Mean_Biomass_normalized_P_vs_E;
            app.InstantaneousPumolCshootsEditField.Value = app.Prod_Data.P_now_per_shoot;
            app.InstantaneousCanopyPPEditField.Value = app.Prod_Data.P_now_over_PE;
            app.InstantaneousLeafPREditField.Value = app.Prod_Data.Instantaneous_Leaf_P_to_R;
            app.InstantaneousWholePlantPREditField.Value = app.Prod_Data.Instantaneous_Shoot_P_to_R;
            app.DailyWholePlantPREditField.Value = app.Prod_Data.Daily_P_to_R;
            app.delCEditField.Value = app.Prod_Data.del13C_new;
            app.DailyHRequirementhdEditField.Value = app.Prod_Data.Daily_Hsat_Requirement;
     

        end

        % Value changed function: pCOinAirAtmEditField
        function pCOinAirAtmEditFieldValueChanged(app, event)
            app.WQ_Params.PCO2_Air = app.pCOinAirAtmEditField.Value;

               % Update DIC values
            [Param1, Param2] = Get_CO2sys_Drivers(app.WQ_Params);   
            app.CO2SYS_Data = CO2SYS_for_app(Param1,Param2,app.WQ_Params.PAR1TYPE,app.WQ_Params.PAR2TYPE,app.WQ_Params.Sal,app.WQ_Params.Temp,app.WQ_Params.Temp,0,app.Site_Data.depth,app.WQ_Params.SiO4,app.WQ_Params.PO4,app.WQ_Params.NH4,app.WQ_Params.H2S,app.WQ_Params.pH_Scale,app.WQ_Params.K1K2,app.WQ_Params.KHSO4,app.WQ_Params.KHF,app.WQ_Params.Borate);
            app.pHEditField.Value = app.CO2SYS_Data.pHout;
            app.WQ_Params.pH = app.CO2SYS_Data.pHout;
            app.pCOinWaterAtmEditField.Value = app.CO2SYS_Data.pCO2out;
            app.WQ_Params.pCO2_Water = app.CO2SYS_Data.pCO2out;
            app.COEditField.Value = app.CO2SYS_Data.CO2out;
            app.WQ_Params.CO2 = app.CO2SYS_Data.CO2out;
            app.HCOEditField.Value = app.CO2SYS_Data.HCO3out;
            app.WQ_Params.HCO3 = app.CO2SYS_Data.HCO3out;
            app.COEditField_2.Value = app.CO2SYS_Data.CO3out;
            app.WQ_Params.CO3 = app.CO2SYS_Data.CO3out;
            app.TotalDICEditField.Value = app.CO2SYS_Data.TCO2;
            app.WQ_Params.TCO2 = app.CO2SYS_Data.TCO2;

            % Update gas saturation values
            app.Gas_Sat = Gas_Solubility_Model(app.WQ_Params.Temp, app.WQ_Params.Sal, app.Site_Data.depth, app.WQ_Params.pCO2_Air);
            app.Gas_Sat.O2_Depth = app.Gas_Sat.O2_1atm * (app.Site_Data.depth/10+1);
            app.OAirSatatDepthEditField.Value = 100 * app.OEditField.Value/app.Gas_Sat.O2_Depth;
            app.pCOAirSatatSfcEditField.Value = 100 * app.pCOinWaterAtmEditField.Value/app.Gas_Sat.pCO2_1atm;
            app.pCOAirSatatDepthEditField.Value = 100 * app.pCOinWaterAtmEditField.Value/app.Gas_Sat.pCO2_Depth;

            % Compute instantaneous and daily metabolic rates
            app.Prod_Data = produce_v301_for_app(app.WQ_Params, app.Site_Data, app.Plant_Arch, app.Photo_Params, app.Biodis_Data, app.EdEu_Data, app.E0_sfc_PAR_mubar, app.PlantTypeDropDown);

            % Populate the Plant Productivity and Metabolism Panel
            app.PumolCmleafsEditField_2.Value = app.Prod_Data.Pm;
            app.PumolCmleafsEditField.Value = app.Prod_Data.PE;
            app.PPEditField.Value = app.Prod_Data.PE / app.Prod_Data.Pm;
            app.InstantaneousPumolCmleafsEditField.Value = app.Prod_Data.Mean_Biomass_normalized_P_vs_E;
            app.InstantaneousPumolCshootsEditField.Value = app.Prod_Data.P_now_per_shoot;
            app.InstantaneousCanopyPPEditField.Value = app.Prod_Data.P_now_over_PE;
            app.InstantaneousLeafPREditField.Value = app.Prod_Data.Instantaneous_Leaf_P_to_R;
            app.InstantaneousWholePlantPREditField.Value = app.Prod_Data.Instantaneous_Shoot_P_to_R;
            app.DailyWholePlantPREditField.Value = app.Prod_Data.Daily_P_to_R;
            app.delCEditField.Value = app.Prod_Data.del13C_new;
            app.DailyHRequirementhdEditField.Value = app.Prod_Data.Daily_Hsat_Requirement;
        

        end

        % Value changed function: pCOinWaterAtmEditField
        function pCOinWaterAtmEditFieldValueChanged(app, event)
            app.WQ_Params.pCO2_Water = app.pCOinWaterAtmEditField.Value;

               % Update DIC values
            [Param1, Param2] = Get_CO2sys_Drivers(app.WQ_Params);   
            app.CO2SYS_Data = CO2SYS_for_app(Param1,Param2,app.WQ_Params.PAR1TYPE,app.WQ_Params.PAR2TYPE,app.WQ_Params.Sal,app.WQ_Params.Temp,app.WQ_Params.Temp,0,app.Site_Data.depth,app.WQ_Params.SiO4,app.WQ_Params.PO4,app.WQ_Params.NH4,app.WQ_Params.H2S,app.WQ_Params.pH_Scale,app.WQ_Params.K1K2,app.WQ_Params.KHSO4,app.WQ_Params.KHF,app.WQ_Params.Borate);
            app.pHEditField.Value = app.CO2SYS_Data.pHout;
            app.WQ_Params.pH = app.CO2SYS_Data.pHout;
            app.pCOinWaterAtmEditField.Value = app.CO2SYS_Data.pCO2out;
            app.WQ_Params.pCO2_Water = app.CO2SYS_Data.pCO2out;
            app.COEditField.Value = app.CO2SYS_Data.CO2out;
            app.WQ_Params.CO2 = app.CO2SYS_Data.CO2out;
            app.HCOEditField.Value = app.CO2SYS_Data.HCO3out;
            app.WQ_Params.HCO3 = app.CO2SYS_Data.HCO3out;
            app.COEditField_2.Value = app.CO2SYS_Data.CO3out;
            app.WQ_Params.CO3 = app.CO2SYS_Data.CO3out;
            app.TotalDICEditField.Value = app.CO2SYS_Data.TCO2;
            app.WQ_Params.TCO2 = app.CO2SYS_Data.TCO2;

            % Update gas saturation values
            app.Gas_Sat = Gas_Solubility_Model(app.WQ_Params.Temp, app.WQ_Params.Sal, app.Site_Data.depth, app.WQ_Params.pCO2_Air);
            app.Gas_Sat.O2_Depth = app.Gas_Sat.O2_1atm * (app.Site_Data.depth/10+1);
            app.OAirSatatDepthEditField.Value = 100 * app.OEditField.Value/app.Gas_Sat.O2_Depth;
            app.pCOAirSatatSfcEditField.Value = 100 * app.pCOinWaterAtmEditField.Value/app.Gas_Sat.pCO2_1atm;
            app.pCOAirSatatDepthEditField.Value = 100 * app.pCOinWaterAtmEditField.Value/app.Gas_Sat.pCO2_Depth;

            % Compute instantaneous and daily metabolic rates
            app.Prod_Data = produce_v301_for_app(app.WQ_Params, app.Site_Data, app.Plant_Arch, app.Photo_Params, app.Biodis_Data, app.EdEu_Data, app.E0_sfc_PAR_mubar, app.PlantTypeDropDown);

            % Populate the Plant Productivity and Metabolism Panel
            app.PumolCmleafsEditField_2.Value = app.Prod_Data.Pm;
            app.PumolCmleafsEditField.Value = app.Prod_Data.PE;
            app.PPEditField.Value = app.Prod_Data.PE / app.Prod_Data.Pm;
            app.InstantaneousPumolCmleafsEditField.Value = app.Prod_Data.Mean_Biomass_normalized_P_vs_E;
            app.InstantaneousPumolCshootsEditField.Value = app.Prod_Data.P_now_per_shoot;
            app.InstantaneousCanopyPPEditField.Value = app.Prod_Data.P_now_over_PE;
            app.InstantaneousLeafPREditField.Value = app.Prod_Data.Instantaneous_Leaf_P_to_R;
            app.InstantaneousWholePlantPREditField.Value = app.Prod_Data.Instantaneous_Shoot_P_to_R;
            app.DailyWholePlantPREditField.Value = app.Prod_Data.Daily_P_to_R;
            app.delCEditField.Value = app.Prod_Data.del13C_new;
            app.DailyHRequirementhdEditField.Value = app.Prod_Data.Daily_Hsat_Requirement;
            

        end

        % Value changed function: pCOAirSatatSfcEditField
        function pCOAirSatatSfcEditFieldValueChanged(app, event)
            app.pCOAirSatatSfcEditField.Value = 188*app.WQ_Params.CO2/app.Gas_Sat.pCO2_1atm;
            
        end

        % Value changed function: pCOAirSatatDepthEditField
        function pCOAirSatatDepthEditFieldValueChanged(app, event)
            app.pCOAirSatatDepthEditField.Value = 100*app.WQ_Params.CO2/app.Gas_Sat.CO2_Depth;
            
        end

        % Value changed function: COEditField
        function COEditFieldValueChanged(app, event)
            app.WQ_Params.CO2 = app.COEditField.Value;
            
        end

        % Value changed function: HCOEditField
        function HCOEditFieldValueChanged(app, event)
            app.WQ_Params.HCO3 = app.HCOEditField.Value;
            
        end

        % Value changed function: COEditField_2
        function COEditField_2ValueChanged(app, event)
            app.WQ_Params.CO3 = app.COEditField_2.Value;
            
        end

        % Value changed function: TotalDICEditField
        function TotalDICEditFieldValueChanged(app, event)
            app.WQ_Params.TCO2 = app.TotalDICEditField.Value;

               % Update DIC values
            [Param1, Param2] = Get_CO2sys_Drivers(app.WQ_Params);   
            app.CO2SYS_Data = CO2SYS_for_app(Param1,Param2,app.WQ_Params.PAR1TYPE,app.WQ_Params.PAR2TYPE,app.WQ_Params.Sal,app.WQ_Params.Temp,app.WQ_Params.Temp,0,app.Site_Data.depth,app.WQ_Params.SiO4,app.WQ_Params.PO4,app.WQ_Params.NH4,app.WQ_Params.H2S,app.WQ_Params.pH_Scale,app.WQ_Params.K1K2,app.WQ_Params.KHSO4,app.WQ_Params.KHF,app.WQ_Params.Borate);
            app.pHEditField.Value = app.CO2SYS_Data.pHout;
            app.WQ_Params.pH = app.CO2SYS_Data.pHout;
            app.pCOinWaterAtmEditField.Value = app.CO2SYS_Data.pCO2out;
            app.WQ_Params.pCO2_Water = app.CO2SYS_Data.pCO2out;
            app.COEditField.Value = app.CO2SYS_Data.CO2out;
            app.WQ_Params.CO2 = app.CO2SYS_Data.CO2out;
            app.HCOEditField.Value = app.CO2SYS_Data.HCO3out;
            app.WQ_Params.HCO3 = app.CO2SYS_Data.HCO3out;
            app.COEditField_2.Value = app.CO2SYS_Data.CO3out;
            app.WQ_Params.CO3 = app.CO2SYS_Data.CO3out;
            app.TotalDICEditField.Value = app.CO2SYS_Data.TCO2;
            app.WQ_Params.TCO2 = app.CO2SYS_Data.TCO2;

            % Update gas saturation values
            app.Gas_Sat = Gas_Solubility_Model(app.WQ_Params.Temp, app.WQ_Params.Sal, app.Site_Data.depth, app.WQ_Params.pCO2_Air);
            app.Gas_Sat.O2_Depth = app.Gas_Sat.O2_1atm * (app.Site_Data.depth/10+1);
            app.OAirSatatDepthEditField.Value = 100 * app.OEditField.Value/app.Gas_Sat.O2_Depth;
            app.pCOAirSatatSfcEditField.Value = 100 * app.pCOinWaterAtmEditField.Value/app.Gas_Sat.pCO2_1atm;
            app.pCOAirSatatDepthEditField.Value = 100 * app.pCOinWaterAtmEditField.Value/app.Gas_Sat.pCO2_Depth;

            % Compute instantaneous and daily metabolic rates
            app.Prod_Data = produce_v301_for_app(app.WQ_Params, app.Site_Data, app.Plant_Arch, app.Photo_Params, app.Biodis_Data, app.EdEu_Data, app.E0_sfc_PAR_mubar, app.PlantTypeDropDown);

            % Populate the Plant Productivity and Metabolism Panel
            app.PumolCmleafsEditField_2.Value = app.Prod_Data.Pm;
            app.PumolCmleafsEditField.Value = app.Prod_Data.PE;
            app.PPEditField.Value = app.Prod_Data.PE / app.Prod_Data.Pm;
            app.InstantaneousPumolCmleafsEditField.Value = app.Prod_Data.Mean_Biomass_normalized_P_vs_E;
            app.InstantaneousPumolCshootsEditField.Value = app.Prod_Data.P_now_per_shoot;
            app.InstantaneousCanopyPPEditField.Value = app.Prod_Data.P_now_over_PE;
            app.InstantaneousLeafPREditField.Value = app.Prod_Data.Instantaneous_Leaf_P_to_R;
            app.InstantaneousWholePlantPREditField.Value = app.Prod_Data.Instantaneous_Shoot_P_to_R;
            app.DailyWholePlantPREditField.Value = app.Prod_Data.Daily_P_to_R;
            app.delCEditField.Value = app.Prod_Data.del13C_new;
            app.DailyHRequirementhdEditField.Value = app.Prod_Data.Daily_Hsat_Requirement;
       

        end

        % Value changed function: CanopyAnglefromverticalEditField
        function CanopyAnglefromverticalEditFieldValueChanged(app, event)
            col = 1;
            if app.PlantTypeDropDown.Value == "Zostera"
                app.Site_Data.lop = 'zostera';
                row = 1;
            elseif app.PlantTypeDropDown.Value == "Thalassia"
                app.Site_Data.lop = 'thalassia';
                row = 2;
            elseif app.PlantTypeDropDown.Value == "Syringodium"
                app.Site_Data.op = 'syringodium';
                row = 3;
            elseif app.PlantTypeDropDown.Value == "Phyllospadix"
                app.Site_Data.lop = 'phyllospadix';
                row = 4;
            elseif app.PlantTypeDropDown.Value == "Posidonia"
                app.Site_Data.lop = 'posidonia';
                row = 5;
            else
                app.Site_Data.lop = 'ruppia';
                row = 6;
            end 
            
            app.CanopyAnglefromverticalEditField.Value = app.Plant_Arch.beta0(row,col);
            
        end

        % Value changed function: DailyPhotoperiodhEditField
        function DailyPhotoperiodhEditFieldValueChanged(app, event)
            app.DailyPhotoperiodhEditField.Value = app.E0_sfc_PAR_mubar.daylen;
            
        end

        % Value changed function: PumolCmleafsEditField
        function PumolCmleafsEditFieldValueChanged(app, event)
            app.PumolCmleafsEditField.Value = app.Prod_Data.PE;
            
        end

        % Value changed function: PumolCmleafsEditField_2
        function PumolCmleafsEditField_2ValueChanged(app, event)
            app.PumolCmleafsEditField_2.Value = app.Prod_Data.Pm;
            
        end

        % Value changed function: PPEditField
        function PPEditFieldValueChanged(app, event)
            app.PPEditField.Value = app.Prod_Data.PE / app.Prod_Data.Pm;
            
        end

        % Value changed function: InstantaneousPumolCmleafsEditField
        function InstantaneousPumolCmleafsEditFieldValueChanged2(app, event)
            app.InstantaneousPumolCmleafsEditField.Value = app.Prod_Data.Mean_Biomass_normalized_P_vs_E;
            
        end

        % Callback function
        function InstantaneousPumolCmleafsEditFieldValueChanged(app, event)
           app.InstantaneousPumolCmleafsEditField.Value = app.Prod_Data.Sum_Area_normalized_P_vs_E;
            
        end

        % Value changed function: InstantaneousWholePlantPREditField
        function InstantaneousWholePlantPREditFieldValueChanged(app, event)
            app.InstantaneousWholePlantPREditField.Value = app.Prod_Data.Instantaneous_Leaf_P_to_R;
            
        end

        % Value changed function: DailyWholePlantPREditField
        function DailyWholePlantPREditFieldValueChanged(app, event)
            app.DailyWholePlantPREditField.Value = app.Prod_Data.Daily_P_to_R;
            
        end

        % Value changed function: delCEditField
        function delCEditFieldValueChanged(app, event)
            app.delCEditField.Value = app.Prod_Data.del13C_new;
            
        end

        % Value changed function: InstantaneousLeafPREditField
        function InstantaneousLeafPREditFieldValueChanged(app, event)
            app.InstantaneousLeafPREditField.Value = app.Prod_Data.Instantaneous_Leaf_P_to_R;
            
        end

        % Value changed function: InstantaneousCanopyPPEditField
        function InstantaneousCanopyPPEditFieldValueChanged(app, event)
           app.IntantaneousLeafPPEditField.Value = app.Prod_Data.Sum_Area_normalized_P_vs_E/app.Prod_Data.PE;       
        end

        % Value changed function: DailyHRequirementhdEditField
        function DailyHRequirementhdEditFieldValueChanged(app, event)
            app.DailyHRequirementhdEditField.Value = app.Prod_Data.Daily_Hsat_Requirement;
            
        end

        % Button pushed function: SaveResultsofThisRunButton
        function SaveResultsofThisRunButtonPushed(app, event)
            
            % Change button color to red inticating file being written
            app.SaveResultsofThisRunButton.BackgroundColor = 'r';

            % Get name of Results File
            [app.outfile, app.outpath] = uiputfile('*.xlsx','Save Results in Excel file:');
            if isequal(app.outfile,0)   || isequal(app.outpath,0)
                app.SaveResultsofThisRunButton.BackgroundColor = 'w';
                return
            else
                app.new_results_file = [app.outpath app.outfile];
                f = waitbar(0,'Please wait...writing Site Data');

                % Write the Site Data to a separate sheet
                writetable(app.Site_Data_Header, app.new_results_file, 'WriteVariableNames', 0, 'Sheet','Site Data', 'Range', 'A1:G9'); 
                writetable(app.Site_Data, app.new_results_file, 'Sheet','Site Data', 'Range', 'A11');

                close(f);
                f = waitbar(0.1, 'Please wait...writing biomass data');

                % Write vertical biomass distribution to a separate sheet
                    % first the header info
                writematrix('Realized Canopy Height (m)', app.new_results_file, 'Sheet', 'Biomass Distribution', 'Range', 'A1');
                writematrix(app.Biodis_Data.canopy_ht, app.new_results_file, 'Sheet', 'Biomass Distribution', 'Range', 'B1');
                writematrix('Shoot Density (shoots m^-2)', app.new_results_file, 'Sheet', 'Biomass Distribution', 'Range', 'A2');
                writematrix(app.ShootDensityshootspermEditField.Value, app.new_results_file, 'Sheet', 'Biomass Distribution', 'Range', 'B2');
                writematrix('Shoot Size (m^2 leaf shoot^-1)', app.new_results_file, 'Sheet', 'Biomass Distribution', 'Range', 'A3');
                writematrix(app.ShootLeafAreamLeafshootEditField.Value, app.new_results_file, 'Sheet', 'Biomass Distribution', 'Range', 'B3');
                writematrix('Leaf Area Index (m^2 leaf m^-2 seafloor)', app.new_results_file, 'Sheet', 'Biomass Distribution', 'Range', 'A4');
                writematrix(app.Biodis_Data.sumlai, app.new_results_file, 'Sheet', 'Biomass Distribution', 'Range', 'B4');
                writematrix('Horizontally Projected LAI (m^2 leaf m^-2 seafloor)', app.new_results_file, 'Sheet', 'Biomass Distribution', 'Range', 'A5');
                writematrix(app.Biodis_Data.sumlap, app.new_results_file, 'Sheet', 'Biomass Distribution', 'Range', 'B5');
                writematrix('Vertical Biomass Distribution', app.new_results_file, 'Sheet', 'Biomass Distribution', 'Range', 'A9');
                writematrix('Depth (m)', app.new_results_file, 'Sheet', 'Biomass Distribution', 'Range', 'A10');
                writematrix('LAI', app.new_results_file, 'Sheet', 'Biomass Distribution', 'Range', 'B10');
                writematrix('LAP', app.new_results_file, 'Sheet', 'Biomass Distribution', 'Range', 'C10'); 
                writematrix('Canopy Bending Angle (radians)', app.new_results_file, 'Sheet', 'Biomass Distribution', 'Range', 'D10'); 
                    % now the data
                biomas = horzcat(flipud(app.Site_Data.depth - app.Biodis_Data.ht), flipud(app.Biodis_Data.lai), flipud(app.Biodis_Data.lap), flipud(app.Biodis_Data.beta));
                writematrix(biomas, app.new_results_file, 'Sheet', 'Biomass Distribution', 'Range', 'A11');
    
                close(f);
                f = waitbar(0.2, 'Please wait...writing productivity data');
                
                % Write productivity data to a separate sheet
                        % Biomass and carbon content
                writematrix('Leaf Biomass (g shoot^-1)', app.new_results_file, 'Sheet', 'Plant Productivity','Range','A1');
                writematrix(app.Prod_Data.leaf_biomass_per_shoot, app.new_results_file, 'Sheet', 'Plant Productivity','Range','B1');
                writematrix('Leaf Carbon (g shoot^-1)', app.new_results_file, 'Sheet', 'Plant Productivity','Range','D1');
                writematrix(app.Prod_Data.leaf_carbon_per_shoot, app.new_results_file, 'Sheet', 'Plant Productivity','Range','E1');
                writematrix('Rhiz Biomass (g shoot^-1)', app.new_results_file, 'Sheet', 'Plant Productivity','Range','A2');
                writematrix(app.Prod_Data.rhiz_biomass_per_shoot, app.new_results_file, 'Sheet', 'Plant Productivity','Range','B2');
                writematrix('Rhiz Carbon (g shoot^-1)', app.new_results_file, 'Sheet', 'Plant Productivity','Range','D2');
                writematrix(app.Prod_Data.rhiz_carbon_per_shoot, app.new_results_file, 'Sheet', 'Plant Productivity','Range','E2');
                writematrix('Root Biomass (g shoot^-1)', app.new_results_file, 'Sheet', 'Plant Productivity','Range','A3');
                writematrix(app.Prod_Data.root_biomass_per_shoot, app.new_results_file, 'Sheet', 'Plant Productivity','Range','B3');
                writematrix('Rhiz Carbon (g shoot^-1)', app.new_results_file, 'Sheet', 'Plant Productivity','Range','D3');
                writematrix(app.Prod_Data.root_carbon_per_shoot, app.new_results_file, 'Sheet', 'Plant Productivity','Range','E3');
                        % Photosynthesis rates
                writematrix('Pm (\mumol m^-2 s^-1)', app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'A5');
                writematrix(app.Prod_Data.Pm, app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'B5');
                writematrix('P_E per leaf (\mumol m^-2 s^-1)', app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'A6');
                writematrix(app.Prod_Data.PE, app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'B6');
                writematrix('Mean canopy P_E (\mumol m^-2 s^-1)', app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'A7');
                writematrix(app.Prod_Data.Mean_Biomass_normalized_P_vs_E, app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'B7');
                writematrix('P_E per shoot (\mumol shoot^-1 s^-1)', app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'A8');
                writematrix(app.Prod_Data.P_now_per_shoot, app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'B8');
                writematrix('Instantaneous leaf P/PE', app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'A9');
                writematrix(app.Prod_Data.P_now_over_PE, app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'B9');
                        % Photosynthesis by substrate - CO2 & HCO3-
                writematrix('Pf_CO2 (\mumol m^-2 s^-1)', app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'A10');
                writematrix(app.Prod_Data.Pf_CO2, app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'B10');
                writematrix('Pf_HCO3- (\mumol m^-2 s^-1)', app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'A11');
                writematrix(app.Prod_Data.Pf_HCO3, app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'B11');
                writematrix('PE_CO2 (\mumol m^-2 s^-1)', app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'A12');
                writematrix(app.Prod_Data.PE_CO2, app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'B12');
                writematrix('PE_HCO3- (\mumol m^-2 s^-1)', app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'A13');
                writematrix(app.Prod_Data.PE_HCO3, app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'B13');
                        % Instantaneous respiration rates
                writematrix('Inst. Leaf R (\mumol shoot^-1 s^-1)', app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'A14');
                writematrix(app.Prod_Data.Leaf_Resp_per_shoot, app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'B14');
                writematrix('Inst. Rhiz R (\mumol shoot^-1 s^-1)', app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'A15');
                writematrix(app.Prod_Data.Rhiz_Resp_per_shoot, app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'B15');
                writematrix('Inst. Root R (\mumol shoot^-1 s^-1)', app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'A16');
                writematrix(app.Prod_Data.Root_Resp_per_shoot, app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'B16');
                writematrix('Inst. Whole Plant R (\mumol shoot^-1 s^-1)', app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'A17');
                writematrix(app.Prod_Data.Shoot_Resp, app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'B17');
                        % Daily respiration rates
                writematrix('Daily Leaf R (\mumol shoot^-1 day^-1)', app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'A18');
                writematrix(app.Prod_Data.Leaf_Daily_R, app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'B18');
                writematrix('Daily Rhiz R (\mumol shoot^-1 day^-1)', app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'A19');
                writematrix(app.Prod_Data.Rhiz_Daily_R, app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'B19');
                writematrix('Daily Root R (\mumol shoot^-1 day^-1)', app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'A20');
                writematrix(app.Prod_Data.Root_Daily_R, app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'B20');
                writematrix('Daily Whole Plant R (\mumol shoot^-1 day^-1)', app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'A21');
                writematrix(app.Prod_Data.Plant_Daily_R, app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'B21');
    
                        % P:R Ratios
                writematrix('Instantaneous Leaf P:R', app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'A22');
                writematrix(app.Prod_Data.Instantaneous_Leaf_P_to_R, app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'B22');
                writematrix('Instantaneous Shoot P:R', app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'A23');
                writematrix(app.Prod_Data.Instantaneous_Shoot_P_to_R, app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'B23');
                writematrix('Daily Shoot P:R', app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'A24');
                writematrix(app.Prod_Data.Daily_P_to_R, app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'B24');
    
                        % d13C value
                writematrix('\delta\rm^{13}C (per mil)', app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'A25');
                writematrix(app.Prod_Data.del13C_new, app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'B25');
    
                        % Daily Hsat Value
                writematrix('Daily H_{sat} Value (h d^{-1})', app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'A26');
                writematrix(app.Prod_Data.Daily_Hsat_Requirement, app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'B26');
    
                        % Vertical distributions of productivity and light absorption
                writematrix('Depth Resolved Production Values', app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'A29')        
                writematrix('Depth (m)', app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'A30' )        
                writematrix(flipud(app.Site_Data.depth - app.Biodis_Data.ht), app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'A31:A1031' )
                writematrix('Incident PAR (\mumol m^-2 s^-1)', app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'B30');
                writematrix(flipud(app.EdEu_Data.eow), app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'B31:B1031');
                writematrix('\Piz (\mumol m^-2 leaf s^-1)', app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'C30');
                for i = 1:1000
                    if isnan(app.Prod_Data.piz_per_leaf(i,1))
                        app.Prod_Data.piz_per_leaf(i,1) = 0;
                        app.Prod_Data.Biomass_normalized_P_vs_E(i,1) = 0;
                        app.Prod_Data.Daily_P_per_shoot(i,1) = 0;
                    end
                end
                writematrix(flipud(app.Prod_Data.piz_per_leaf), app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'C31:C1031');
                writematrix('Inst. PE (\mumol m^-2 leaf s^-1)', app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'D30');
                writematrix(flipud(app.Prod_Data.Biomass_normalized_P_vs_E), app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'D31:D1031');
                writematrix('Daily PE (\mumol m^-2 leaf s^-1)', app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'E30');
                writematrix(flipud(app.Prod_Data.Daily_Biomass_normalized_P), app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'E31:E1031');
                writematrix('Daily PE (\mumol m^-2 shoot s^-1)', app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'F30');
                writematrix(flipud(app.Prod_Data.Daily_P_per_shoot), app.new_results_file, 'Sheet', 'Plant Productivity', 'Range', 'F31:F1031');
                
                close(f);
                f = waitbar(0.4, 'Please wait...writing surface irradiance and reflectance spectra');
    
                % Write surface irradance and reflectance data to a separate sheet
                writematrix('All irradiance values = W m^-2 nm^-1', app.new_results_file, 'Sheet', 'Surface Irradiance Spectra', 'Range', 'B7');
                writetable(app.E0_sfc_spectra, app.new_results_file, 'Sheet', 'Surface Irradiance Spectra', 'Range', 'A9');
    
                close(f)
                f = waitbar(0.5, 'Please wait...writing IOP and AOP spectra');    
    
                % Write IOP and AOP spectra to a separate sheet
                writematrix('Chl a (mg m^-3)', app.new_results_file, 'Sheet', 'IOP and AOP Spectra', 'Range', 'A1')
                writematrix(app.WQ_Params.CHL_A, app.new_results_file, 'Sheet', 'IOP and AOP Spectra', 'Range', 'B1')
                writematrix('TSM (mg L^-1)', app.new_results_file, 'Sheet', 'IOP and AOP Spectra', 'Range', 'A2')
                writematrix(app.WQ_Params.CHL_A, app.new_results_file, 'Sheet', 'IOP and AOP Spectra', 'Range', 'B2')
                writematrix('A_g(440) (m^-1)', app.new_results_file, 'Sheet', 'IOP and AOP Spectra', 'Range', 'A3')
                writematrix(app.WQ_Params.AG_440, app.new_results_file, 'Sheet', 'IOP and AOP Spectra', 'Range', 'B3')
                
                writematrix('Units of all IOP and AOP spectra are per m (m^-1) ', app.new_results_file, 'Sheet', 'IOP and AOP Spectra', 'Range', 'A8')
                writetable(app.abKd_Spectra, app.new_results_file, 'Sheet', 'IOP and AOP Spectra', 'Range', 'A10');
    
                close(f)
                f = waitbar(0.6, 'Please wait...writing \itR\rm_{rs} and \itR\rm_canopy spectra');
    
                % Write Rrs R_canopy spectra to a separate sheet
                if app.Biodis_Data.sumlai == 0
                    Reflectance_out = horzcat(app.IOP_Spectra.WL, transpose(app.EdEu_Data.Rrs));      % Combine separate matrices into one for writing but don't include canopy reflectance
                else    
                    Reflectance_out = horzcat(app.IOP_Spectra.WL, transpose(app.EdEu_Data.Rrs), transpose(app.EdEu_Data.can_refl(app.Biodis_Data.n_flow_reduced_canopy_layers,:)));      % Combine separate matrices into one for writing
                end
                writematrix('Wavelength (nm)', app.new_results_file, 'Sheet', 'Rrs and R_canopy Spectra', 'Range', 'A9');
                writematrix('Rrs (sr^-1)', app.new_results_file, 'Sheet', 'Rrs and R_canopy Spectra', 'Range', 'B9');
                if app.Biodis_Data.sumlai == 0
                    writematrix('No Biomass, LAI = 0', app.new_results_file, 'Sheet', 'Rrs and R_canopy Spectra', 'Range', 'C9');
                else
                    writematrix('R_canopy (rel)', app.new_results_file, 'Sheet', 'Rrs and R_canopy Spectra', 'Range', 'C9');
                end
                writematrix(Reflectance_out, app.new_results_file, 'Sheet', 'Rrs and R_canopy Spectra', 'Range', 'A10');
    
                close(f)
                f = waitbar(0.7, 'Please wait...writing \itE\rm_d spectra');
    
                % Write Ed spectra to a separate sheet
                writematrix('Downwelling Irradiance Spectra (W m^2 nm^-1)', app.new_results_file, 'Sheet', 'Ed Spectra', 'Range', 'B9');
                writematrix('Wavelength (nm)', app.new_results_file, 'Sheet', 'Ed Spectra', 'Range', 'F9');
                writematrix('Depth (m)', app.new_results_file, 'Sheet', 'Ed Spectra', 'Range', 'A10');
                writematrix(transpose(app.IOP_Spectra.WL), app.new_results_file, 'Sheet', 'Ed Spectra', 'Range', 'B10:KP10');
                writematrix(flipud(app.Site_Data.depth - app.Biodis_Data.ht), app.new_results_file, 'Sheet', 'Ed Spectra', 'Range', 'A11:A1011' )
                writematrix(flipud(app.EdEu_Data.edw), app.new_results_file, 'Sheet', 'Ed Spectra', 'Range', 'B11');
    
                close(f)
                f = waitbar(0.8, 'Please wait...writing \itE\rm_u spectra');
    
                % Write Eu spectra to a separate sheet
                writematrix('Upwelling Irradiance Spectra (W m^2 nm^-1)', app.new_results_file, 'Sheet', 'Eu Spectra', 'Range', 'B9');
                writematrix('Wavelength (nm)', app.new_results_file, 'Sheet', 'Eu Spectra', 'Range', 'F9');
                writematrix('Depth (m)', app.new_results_file, 'Sheet', 'Eu Spectra', 'Range', 'A10');
                writematrix(transpose(app.IOP_Spectra.WL), app.new_results_file, 'Sheet', 'Eu Spectra', 'Range', 'B10:KP10');
                writematrix(flipud(app.Site_Data.depth - app.Biodis_Data.ht), app.new_results_file, 'Sheet', 'Eu Spectra', 'Range', 'A11:A1011' )
                writematrix(flipud(app.EdEu_Data.euw), app.new_results_file, 'Sheet', 'Eu Spectra', 'Range', 'B11');
    
                close(f)
                f = waitbar(1.0, 'File saved');
    
                %pause(1)
                close(f)
    
    
                % Re-set button color to white
                app.SaveResultsofThisRunButton.BackgroundColor = 'w';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.GrassLight_3_11 = uifigure('Visible', 'off');
            app.GrassLight_3_11.Name = 'GrassLight 3.11';
            app.GrassLight_3_11.Icon = fullfile(pathToMLAPP, 'GrassLight_Icon.jpg');
            app.FileMenu = uimenu(app.GrassLight_3_11);
            app.FileMenu.Text = 'File';
            app.LoadNewSetupFileMenu = uimenu(app.FileMenu);
            app.LoadNewSetupFileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadNewSetupFileMenuSelected, true);
            app.LoadNewSetupFileMenu.Text = 'Load New Setup File';
            app.SaveNewSetupFileMenu = uimenu(app.FileMenu);
            app.SaveNewSetupFileMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveNewSetupFileMenuSelected, true);
            app.SaveNewSetupFileMenu.Text = 'Save New Setup File';
            app.AboutGrassLightMenu = uimenu(app.FileMenu);
            app.AboutGrassLightMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutGrassLightMenuSelected, true);
            app.AboutGrassLightMenu.Text = 'About GrassLight';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.RunMenu = uimenu(app.GrassLight_3_11);
            app.RunMenu.Text = 'Run';
            app.OptimizeDensityMenu = uimenu(app.RunMenu);
            app.OptimizeDensityMenu.MenuSelectedFcn = createCallbackFcn(app, @OptimizeDensityMenuSelected, true);
            app.OptimizeDensityMenu.Text = 'Optimize Density';
            app.TimeSeriesMenu = uimenu(app.RunMenu);
            app.TimeSeriesMenu.MenuSelectedFcn = createCallbackFcn(app, @TimeSeriesMenuSelected, true);
            app.TimeSeriesMenu.Text = 'Time Series';
            app.PlotMenu = uimenu(app.GrassLight_3_11);
            app.PlotMenu.Text = 'Plot';
            app.SurfaceIrradianceandReflectanceSpectraMenu = uimenu(app.PlotMenu);
            app.SurfaceIrradianceandReflectanceSpectraMenu.MenuSelectedFcn = createCallbackFcn(app, @SurfaceIrradianceandReflectanceSpectraMenuSelected, true);
            app.SurfaceIrradianceandReflectanceSpectraMenu.Text = 'Surface Irradiance and Reflectance Spectra';
            app.SubmarineIrradianceandReflectanceSpectraMenu = uimenu(app.PlotMenu);
            app.SubmarineIrradianceandReflectanceSpectraMenu.MenuSelectedFcn = createCallbackFcn(app, @SubmarineIrradianceandReflectanceSpectraMenuSelected, true);
            app.SubmarineIrradianceandReflectanceSpectraMenu.Text = 'Submarine Irradiance and Reflectance Spectra';
            app.WaterColumnOpticalPropertiesMenu = uimenu(app.PlotMenu);
            app.WaterColumnOpticalPropertiesMenu.MenuSelectedFcn = createCallbackFcn(app, @WaterColumnOpticalPropertiesMenuSelected, true);
            app.WaterColumnOpticalPropertiesMenu.Text = 'Water Column Optical Properties';
            app.LeafOpticalPropertiesMenu = uimenu(app.PlotMenu);
            app.LeafOpticalPropertiesMenu.MenuSelectedFcn = createCallbackFcn(app, @LeafOpticalPropertiesMenuSelected, true);
            app.LeafOpticalPropertiesMenu.Text = 'Leaf Optical Properties';
            app.SubstrateReflectanceSpectrumMenu = uimenu(app.PlotMenu);
            app.SubstrateReflectanceSpectrumMenu.MenuSelectedFcn = createCallbackFcn(app, @SubstrateReflectanceSpectrumMenuSelected, true);
            app.SubstrateReflectanceSpectrumMenu.Text = 'Substrate Reflectance Spectrum';
            app.VerticalBiomassDistributionMenu = uimenu(app.PlotMenu);
            app.VerticalBiomassDistributionMenu.MenuSelectedFcn = createCallbackFcn(app, @VerticalBiomassDistributionMenuSelected, true);
            app.VerticalBiomassDistributionMenu.Text = 'Vertical Biomass Distribution';
            app.CanopyProductionPlotsMenu = uimenu(app.PlotMenu);
            app.CanopyProductionPlotsMenu.MenuSelectedFcn = createCallbackFcn(app, @CanopyProductionPlotsMenuSelected, true);
            app.CanopyProductionPlotsMenu.Text = 'Canopy Production Plots';
            app.CIEColorSimulationsofCanopyRandRrsMenu = uimenu(app.PlotMenu);
            app.CIEColorSimulationsofCanopyRandRrsMenu.MenuSelectedFcn = createCallbackFcn(app, @CIEColorSimulationsofCanopyRandRrsMenuSelected, true);
            app.CIEColorSimulationsofCanopyRandRrsMenu.Text = 'CIE Color Simulations of Canopy R and Rrs';
            app.ClosePlotsMenu = uimenu(app.PlotMenu);
            app.ClosePlotsMenu.MenuSelectedFcn = createCallbackFcn(app, @ClosePlotsMenuSelected, true);
            app.ClosePlotsMenu.Text = 'Close Plots';
            app.GridLayout = uigridlayout(app.GrassLight_3_11);
            app.PlantMorphologyOpticalPropertiesPanel = uipanel(app.GridLayout);
            app.PlantMorphologyOpticalPropertiesPanel.Title = 'Plant Morphology & Optical Properties';
            app.MaxHeightmEditFieldLabel = uilabel(app.PlantMorphologyOpticalPropertiesPanel);
            app.MaxHeightmEditFieldLabel.Text = 'Max Height (m)';
            app.LAIEditFieldLabel = uilabel(app.PlantMorphologyOpticalPropertiesPanel);
            app.LAIEditFieldLabel.Text = 'LAI';
            app.ShootRhizomeRootEditFieldLabel = uilabel(app.PlantMorphologyOpticalPropertiesPanel);
            app.ShootRhizomeRootEditFieldLabel.Text = 'Shoot/(Rhizome+Root)';
            app.RhizRootEditFieldLabel = uilabel(app.PlantMorphologyOpticalPropertiesPanel);
            app.RhizRootEditFieldLabel.Text = 'Rhiz/Root';
            app.EpiphyteLoadmgcmEditFieldLabel = uilabel(app.PlantMorphologyOpticalPropertiesPanel);
            app.EpiphyteLoadmgcmEditFieldLabel.Text = {'Epiphyte Load'; '(mg cm  )'};
            app.Label_5 = uilabel(app.PlantMorphologyOpticalPropertiesPanel);
            app.Label_5.Text = '-2';
            app.Label_2 = uilabel(app.PlantMorphologyOpticalPropertiesPanel);
            app.Label_2.Text = '2';
            app.Label_3 = uilabel(app.PlantMorphologyOpticalPropertiesPanel);
            app.Label_3.Text = '-1';
            app.ShootLeafAreamLeafshootLabel = uilabel(app.PlantMorphologyOpticalPropertiesPanel);
            app.ShootLeafAreamLeafshootLabel.Text = {'Shoot Leaf Area'; '(m  Leaf shoot  )'};
            app.ShootDensitypermLabel = uilabel(app.PlantMorphologyOpticalPropertiesPanel);
            app.ShootDensitypermLabel.Text = {'Shoot Density '; '(shoots per m  )'};
            app.Label = uilabel(app.PlantMorphologyOpticalPropertiesPanel);
            app.Label.Text = '2';
            app.EpiphyteLoadmgcmEditField = uieditfield(app.PlantMorphologyOpticalPropertiesPanel, 'numeric');
            app.EpiphyteLoadmgcmEditField.Limits = [0 Inf];
            app.EpiphyteLoadmgcmEditField.ValueDisplayFormat = '%11.2g';
            app.EpiphyteLoadmgcmEditField.ValueChangedFcn = createCallbackFcn(app, @EpiphyteLoadmgcmEditFieldValueChanged, true);
            app.RhizRootEditField = uieditfield(app.PlantMorphologyOpticalPropertiesPanel, 'numeric');
            app.RhizRootEditField.Limits = [0 Inf];
            app.RhizRootEditField.ValueDisplayFormat = '%11.2g';
            app.RhizRootEditField.ValueChangedFcn = createCallbackFcn(app, @RhizRootEditFieldValueChanged, true);
            app.ShootRhizomeRootEditField = uieditfield(app.PlantMorphologyOpticalPropertiesPanel, 'numeric');
            app.ShootRhizomeRootEditField.Limits = [0 Inf];
            app.ShootRhizomeRootEditField.ValueDisplayFormat = '%11.2g';
            app.ShootRhizomeRootEditField.ValueChangedFcn = createCallbackFcn(app, @ShootRhizomeRootEditFieldValueChanged, true);
            app.LAIEditField = uieditfield(app.PlantMorphologyOpticalPropertiesPanel, 'numeric');
            app.LAIEditField.Limits = [0 Inf];
            app.LAIEditField.ValueDisplayFormat = '%6.2f';
            app.LAIEditField.ValueChangedFcn = createCallbackFcn(app, @LAIEditFieldValueChanged, true);
            app.ShootDensityshootspermEditField = uieditfield(app.PlantMorphologyOpticalPropertiesPanel, 'numeric');
            app.ShootDensityshootspermEditField.Limits = [0 Inf];
            app.ShootDensityshootspermEditField.RoundFractionalValues = 'on';
            app.ShootDensityshootspermEditField.ValueDisplayFormat = '%.0f';
            app.ShootDensityshootspermEditField.ValueChangedFcn = createCallbackFcn(app, @ShootDensityshootspermEditFieldValueChanged, true);
            app.ShootLeafAreamLeafshootEditField = uieditfield(app.PlantMorphologyOpticalPropertiesPanel, 'numeric');
            app.ShootLeafAreamLeafshootEditField.Limits = [0 Inf];
            app.ShootLeafAreamLeafshootEditField.ValueDisplayFormat = '%11.6g';
            app.ShootLeafAreamLeafshootEditField.ValueChangedFcn = createCallbackFcn(app, @ShootLeafAreamLeafshootEditFieldValueChanged, true);
            app.MaxHeightmEditField = uieditfield(app.PlantMorphologyOpticalPropertiesPanel, 'numeric');
            app.MaxHeightmEditField.Limits = [0 Inf];
            app.MaxHeightmEditField.ValueChangedFcn = createCallbackFcn(app, @MaxHeightmEditFieldValueChanged, true);
            app.PlantTypeDropDownLabel = uilabel(app.PlantMorphologyOpticalPropertiesPanel);
            app.PlantTypeDropDownLabel.Text = 'Plant Type';
            app.PlantTypeDropDown = uidropdown(app.PlantMorphologyOpticalPropertiesPanel);
            app.PlantTypeDropDown.Items = {'Zostera', 'Thalassia', 'Syringodium', 'Phyllospadix', 'Posidonia', 'Ruppia'};
            app.PlantTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PlantTypeDropDownValueChanged, true);
            app.PlantTypeDropDown.Value = 'Zostera';
            app.WaterColumnOpticalConstituentsPanel = uipanel(app.GridLayout);
            app.WaterColumnOpticalConstituentsPanel.Title = 'Water Column Optical Constituents';
            app.ChlamgmEditFieldLabel = uilabel(app.WaterColumnOpticalConstituentsPanel);
            app.ChlamgmEditFieldLabel.Text = 'Chl a (mg m  )';
            app.TSMmgLEditFieldLabel = uilabel(app.WaterColumnOpticalConstituentsPanel);
            app.TSMmgLEditFieldLabel.Text = 'TSM (mg L  )';
            app.a440EditFieldLabel = uilabel(app.WaterColumnOpticalConstituentsPanel);
            app.a440EditFieldLabel.Text = 'a  (440)';
            app.Label_6 = uilabel(app.WaterColumnOpticalConstituentsPanel);
            app.Label_6.Text = '-3';
            app.Label_7 = uilabel(app.WaterColumnOpticalConstituentsPanel);
            app.Label_7.Text = '-1';
            app.gLabel = uilabel(app.WaterColumnOpticalConstituentsPanel);
            app.gLabel.Text = 'g';
            app.a440EditField = uieditfield(app.WaterColumnOpticalConstituentsPanel, 'numeric');
            app.a440EditField.Limits = [0 Inf];
            app.a440EditField.ValueChangedFcn = createCallbackFcn(app, @a440EditFieldValueChanged, true);
            app.TSMmgLEditField = uieditfield(app.WaterColumnOpticalConstituentsPanel, 'numeric');
            app.TSMmgLEditField.Limits = [0 Inf];
            app.TSMmgLEditField.ValueDisplayFormat = '%11.2g';
            app.TSMmgLEditField.ValueChangedFcn = createCallbackFcn(app, @TSMmgLEditFieldValueChanged, true);
            app.ChlamgmEditField = uieditfield(app.WaterColumnOpticalConstituentsPanel, 'numeric');
            app.ChlamgmEditField.Limits = [0 Inf];
            app.ChlamgmEditField.ValueDisplayFormat = '%11.2g';
            app.ChlamgmEditField.ValueChangedFcn = createCallbackFcn(app, @ChlamgmEditFieldValueChanged, true);
            app.Image = uiimage(app.WaterColumnOpticalConstituentsPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'GrassLight_Icon.jpg');
            app.InitialWaterChemistryPanel = uipanel(app.GridLayout);
            app.InitialWaterChemistryPanel.Title = 'Initial Water Chemistry';
            app.TemperatureCEditFieldLabel = uilabel(app.InitialWaterChemistryPanel);
            app.TemperatureCEditFieldLabel.Text = 'Temperature (°C)';
            app.OEditFieldLabel = uilabel(app.InitialWaterChemistryPanel);
            app.OEditFieldLabel.Text = 'O  ';
            app.OAirSatatSfcEditFieldLabel = uilabel(app.InitialWaterChemistryPanel);
            app.OAirSatatSfcEditFieldLabel.Text = 'O  (% Air Sat at Sfc)';
            app.POEditFieldLabel = uilabel(app.InitialWaterChemistryPanel);
            app.POEditFieldLabel.Text = 'PO  ';
            app.pCOinWaterAtmEditFieldLabel = uilabel(app.InitialWaterChemistryPanel);
            app.pCOinWaterAtmEditFieldLabel.Text = 'pCO  in Water (µAtm)  ';
            app.HCOEditFieldLabel = uilabel(app.InitialWaterChemistryPanel);
            app.HCOEditFieldLabel.Text = 'HCO  ';
            app.SalinitypptorPSS78Label = uilabel(app.InitialWaterChemistryPanel);
            app.SalinitypptorPSS78Label.Text = 'Salinity (ppt or PSS-78)';
            app.AlkalinityEditFieldLabel = uilabel(app.InitialWaterChemistryPanel);
            app.AlkalinityEditFieldLabel.Text = 'Alkalinity ';
            app.pHScaleDropDownLabel = uilabel(app.InitialWaterChemistryPanel);
            app.pHScaleDropDownLabel.Text = 'pH Scale';
            app.COMLabel = uilabel(app.InitialWaterChemistryPanel);
            app.COMLabel.Text = 'CO   ';
            app.TotalDICEditFieldLabel = uilabel(app.InitialWaterChemistryPanel);
            app.TotalDICEditFieldLabel.Text = 'Total DIC ';
            app.Label_10 = uilabel(app.InitialWaterChemistryPanel);
            app.Label_10.Text = '2';
            app.Label_12 = uilabel(app.InitialWaterChemistryPanel);
            app.Label_12.Text = '2';
            app.Label_13 = uilabel(app.InitialWaterChemistryPanel);
            app.Label_13.Text = '2';
            app.Label_15 = uilabel(app.InitialWaterChemistryPanel);
            app.Label_15.Text = '4';
            app.Label_16 = uilabel(app.InitialWaterChemistryPanel);
            app.Label_16.Text = '-3';
            app.Label_18 = uilabel(app.InitialWaterChemistryPanel);
            app.Label_18.Text = '3';
            app.Label_19 = uilabel(app.InitialWaterChemistryPanel);
            app.Label_19.Text = '-';
            app.Label_20 = uilabel(app.InitialWaterChemistryPanel);
            app.Label_20.Text = '3';
            app.Label_21 = uilabel(app.InitialWaterChemistryPanel);
            app.Label_21.Text = '-2';
            app.HSMLabel = uilabel(app.InitialWaterChemistryPanel);
            app.HSMLabel.Text = 'H S ';
            app.Label_22 = uilabel(app.InitialWaterChemistryPanel);
            app.Label_22.Text = '2';
            app.Label_17 = uilabel(app.InitialWaterChemistryPanel);
            app.Label_17.Text = '4';
            app.SiOEditFieldLabel = uilabel(app.InitialWaterChemistryPanel);
            app.SiOEditFieldLabel.Text = 'SiO  ';
            app.Label_11 = uilabel(app.InitialWaterChemistryPanel);
            app.Label_11.Text = '3';
            app.Label_14 = uilabel(app.InitialWaterChemistryPanel);
            app.Label_14.Text = '-';
            app.NOEditFieldLabel = uilabel(app.InitialWaterChemistryPanel);
            app.NOEditFieldLabel.Text = 'NO  ';
            app.NHEditFieldLabel = uilabel(app.InitialWaterChemistryPanel);
            app.NHEditFieldLabel.Text = 'NH  ';
            app.Label_23 = uilabel(app.InitialWaterChemistryPanel);
            app.Label_23.Text = '4';
            app.Label_24 = uilabel(app.InitialWaterChemistryPanel);
            app.Label_24.Text = '+';
            app.Label_26 = uilabel(app.InitialWaterChemistryPanel);
            app.Label_26.Text = '2';
            app.Label_27 = uilabel(app.InitialWaterChemistryPanel);
            app.Label_27.Text = '2';
            app.Label_28 = uilabel(app.InitialWaterChemistryPanel);
            app.Label_28.Text = '-1';
            app.Label_29 = uilabel(app.InitialWaterChemistryPanel);
            app.Label_29.Text = '2';
            app.Label_30 = uilabel(app.InitialWaterChemistryPanel);
            app.Label_30.Text = '2';
            app.Label_31 = uilabel(app.InitialWaterChemistryPanel);
            app.Label_31.Text = '2';
            app.pHEditFieldLabel = uilabel(app.InitialWaterChemistryPanel);
            app.pHEditFieldLabel.Text = 'pH';
            app.TotalDICEditField = uieditfield(app.InitialWaterChemistryPanel, 'numeric');
            app.TotalDICEditField.Limits = [0 Inf];
            app.TotalDICEditField.ValueDisplayFormat = '%11.0f';
            app.TotalDICEditField.ValueChangedFcn = createCallbackFcn(app, @TotalDICEditFieldValueChanged, true);
            app.COEditField_2 = uieditfield(app.InitialWaterChemistryPanel, 'numeric');
            app.COEditField_2.Limits = [0 Inf];
            app.COEditField_2.ValueDisplayFormat = '%11.1f';
            app.COEditField_2.ValueChangedFcn = createCallbackFcn(app, @COEditField_2ValueChanged, true);
            app.COEditField_2.Editable = 'off';
            app.HCOEditField = uieditfield(app.InitialWaterChemistryPanel, 'numeric');
            app.HCOEditField.Limits = [0 Inf];
            app.HCOEditField.ValueDisplayFormat = '%11.0f';
            app.HCOEditField.ValueChangedFcn = createCallbackFcn(app, @HCOEditFieldValueChanged, true);
            app.HCOEditField.Editable = 'off';
            app.COEditFieldLabel = uilabel(app.InitialWaterChemistryPanel);
            app.COEditFieldLabel.Text = 'CO  ';
            app.COEditField = uieditfield(app.InitialWaterChemistryPanel, 'numeric');
            app.COEditField.Limits = [0 Inf];
            app.COEditField.ValueDisplayFormat = '%11.1f';
            app.COEditField.ValueChangedFcn = createCallbackFcn(app, @COEditFieldValueChanged, true);
            app.COEditField.Editable = 'off';
            app.pCOAirSatatDepthEditField = uieditfield(app.InitialWaterChemistryPanel, 'numeric');
            app.pCOAirSatatDepthEditField.Limits = [0 Inf];
            app.pCOAirSatatDepthEditField.ValueDisplayFormat = '%11.0f';
            app.pCOAirSatatDepthEditField.ValueChangedFcn = createCallbackFcn(app, @pCOAirSatatDepthEditFieldValueChanged, true);
            app.pCOAirSatatDepthEditField.Editable = 'off';
            app.pCOAirSatatSfcEditField = uieditfield(app.InitialWaterChemistryPanel, 'numeric');
            app.pCOAirSatatSfcEditField.Limits = [0 Inf];
            app.pCOAirSatatSfcEditField.ValueDisplayFormat = '%11.0f';
            app.pCOAirSatatSfcEditField.ValueChangedFcn = createCallbackFcn(app, @pCOAirSatatSfcEditFieldValueChanged, true);
            app.pCOAirSatatSfcEditField.Editable = 'off';
            app.pCOinWaterAtmEditField = uieditfield(app.InitialWaterChemistryPanel, 'numeric');
            app.pCOinWaterAtmEditField.Limits = [0 Inf];
            app.pCOinWaterAtmEditField.ValueDisplayFormat = '%11.0f';
            app.pCOinWaterAtmEditField.ValueChangedFcn = createCallbackFcn(app, @pCOinWaterAtmEditFieldValueChanged, true);
            app.pCOinAirAtmEditField = uieditfield(app.InitialWaterChemistryPanel, 'numeric');
            app.pCOinAirAtmEditField.Limits = [0 Inf];
            app.pCOinAirAtmEditField.ValueDisplayFormat = '%11.0f';
            app.pCOinAirAtmEditField.ValueChangedFcn = createCallbackFcn(app, @pCOinAirAtmEditFieldValueChanged, true);
            app.HSEditField = uieditfield(app.InitialWaterChemistryPanel, 'numeric');
            app.HSEditField.Limits = [0 Inf];
            app.HSEditField.ValueDisplayFormat = '%11.1f';
            app.HSEditField.ValueChangedFcn = createCallbackFcn(app, @HSEditFieldValueChanged, true);
            app.NOEditField = uieditfield(app.InitialWaterChemistryPanel, 'numeric');
            app.NOEditField.Limits = [0 Inf];
            app.NOEditField.ValueDisplayFormat = '%11.1f';
            app.NOEditField.ValueChangedFcn = createCallbackFcn(app, @NOEditFieldValueChanged, true);
            app.NHEditField = uieditfield(app.InitialWaterChemistryPanel, 'numeric');
            app.NHEditField.Limits = [0 Inf];
            app.NHEditField.ValueDisplayFormat = '%11.1f';
            app.NHEditField.ValueChangedFcn = createCallbackFcn(app, @NHEditFieldValueChanged, true);
            app.POEditField = uieditfield(app.InitialWaterChemistryPanel, 'numeric');
            app.POEditField.Limits = [0 Inf];
            app.POEditField.ValueDisplayFormat = '%11.1f';
            app.POEditField.ValueChangedFcn = createCallbackFcn(app, @POEditFieldValueChanged, true);
            app.SiOEditField = uieditfield(app.InitialWaterChemistryPanel, 'numeric');
            app.SiOEditField.Limits = [0 Inf];
            app.SiOEditField.ValueDisplayFormat = '%11.1f';
            app.SiOEditField.ValueChangedFcn = createCallbackFcn(app, @SiOEditFieldValueChanged, true);
            app.OAirSatatDepthEditField = uieditfield(app.InitialWaterChemistryPanel, 'numeric');
            app.OAirSatatDepthEditField.Limits = [0 Inf];
            app.OAirSatatDepthEditField.ValueDisplayFormat = '%11.0f';
            app.OAirSatatDepthEditField.ValueChangedFcn = createCallbackFcn(app, @OAirSatatDepthEditFieldValueChanged, true);
            app.OAirSatatDepthEditField.Editable = 'off';
            app.OAirSatatSfcEditField = uieditfield(app.InitialWaterChemistryPanel, 'numeric');
            app.OAirSatatSfcEditField.Limits = [0 Inf];
            app.OAirSatatSfcEditField.ValueDisplayFormat = '%11.0f';
            app.OAirSatatSfcEditField.ValueChangedFcn = createCallbackFcn(app, @OAirSatatSfcEditFieldValueChanged, true);
            app.OEditField = uieditfield(app.InitialWaterChemistryPanel, 'numeric');
            app.OEditField.Limits = [0 Inf];
            app.OEditField.ValueDisplayFormat = '%11.0f';
            app.OEditField.ValueChangedFcn = createCallbackFcn(app, @OEditFieldValueChanged, true);
            app.AlkalinityEditField = uieditfield(app.InitialWaterChemistryPanel, 'numeric');
            app.AlkalinityEditField.Limits = [0 Inf];
            app.AlkalinityEditField.ValueDisplayFormat = '%11.0f';
            app.AlkalinityEditField.ValueChangedFcn = createCallbackFcn(app, @AlkalinityEditFieldValueChanged, true);
            app.AllconcentrationsmolKgSWunlessotherwisespecifiedLabel = uilabel(app.InitialWaterChemistryPanel);
            app.AllconcentrationsmolKgSWunlessotherwisespecifiedLabel.Text = {'All concentrations µmol Kg  SW'; 'unless otherwise specified'};
            app.BorateSalinityDropDown = uidropdown(app.InitialWaterChemistryPanel);
            app.BorateSalinityDropDown.Items = {'Uppstrom 1979', 'Lee 2010'};
            app.BorateSalinityDropDown.ValueChangedFcn = createCallbackFcn(app, @BorateSalinityDropDownValueChanged, true);
            app.BorateSalinityDropDown.Value = 'Uppstrom 1979';
            app.KHFDissociationConstantDropDown = uidropdown(app.InitialWaterChemistryPanel);
            app.KHFDissociationConstantDropDown.Items = {'Dickson & Riley 1979', 'Perez & Fraga (PREFERRED)'};
            app.KHFDissociationConstantDropDown.ValueChangedFcn = createCallbackFcn(app, @KHFDissociationConstantDropDownValueChanged, true);
            app.KHFDissociationConstantDropDown.Value = 'Perez & Fraga (PREFERRED)';
            app.KSODissociationConstantDropDown = uidropdown(app.InitialWaterChemistryPanel);
            app.KSODissociationConstantDropDown.Items = {'Dickson (PREFERRED)', 'Khoo', 'Waters & Millero'};
            app.KSODissociationConstantDropDown.ValueChangedFcn = createCallbackFcn(app, @KSODissociationConstantDropDownValueChanged, true);
            app.KSODissociationConstantDropDown.Value = 'Dickson (PREFERRED)';
            app.K1K2ConstantsDropDown = uidropdown(app.InitialWaterChemistryPanel);
            app.K1K2ConstantsDropDown.Items = {'Roy 1993', 'Goyet & Poisson 1989', 'Hansson 1973 refit by Dickson & Millero 1987', 'Mehrbach et al. 1973 refit by Dickson & Millero 1987', 'Hansson & Mehrbach refit by Dickson & Millero 1987', 'GEOSECS NBS Constants, K1, K2 from Mehrbach et al. 1973', 'Peng et al. NBS constants; K1 K2 from Mehrbach et al. 1973', 'Freshwater (Sal = 0); K1 K2 from Millero 1979', 'Cai and Wang 1998', 'Lueker et al 2000', 'Mojica Prieto & Millero 2002', 'Millero et al. 2002', 'Millero et al. 2006', 'Millero 2010', 'Waters et al. 2014', 'Sulpis et al 2020', 'Schockman & Byrne 2021'};
            app.K1K2ConstantsDropDown.ValueChangedFcn = createCallbackFcn(app, @K1K2ConstantsDropDownValueChanged, true);
            app.K1K2ConstantsDropDown.Value = 'Cai and Wang 1998';
            app.pHScaleDropDown = uidropdown(app.InitialWaterChemistryPanel);
            app.pHScaleDropDown.Items = {'Total', 'Seawater', 'Free', 'NBS'};
            app.pHScaleDropDown.ValueChangedFcn = createCallbackFcn(app, @pHScaleDropDownValueChanged, true);
            app.pHScaleDropDown.Value = 'NBS';
            app.pHEditField = uieditfield(app.InitialWaterChemistryPanel, 'numeric');
            app.pHEditField.Limits = [1 14];
            app.pHEditField.ValueDisplayFormat = '%11.2g';
            app.pHEditField.ValueChangedFcn = createCallbackFcn(app, @pHEditFieldValueChanged, true);
            app.pHEditField.Value = 1;
            app.SalinitypptorPSS78EditField = uieditfield(app.InitialWaterChemistryPanel, 'numeric');
            app.SalinitypptorPSS78EditField.Limits = [0 Inf];
            app.SalinitypptorPSS78EditField.ValueDisplayFormat = '%11.2g';
            app.SalinitypptorPSS78EditField.ValueChangedFcn = createCallbackFcn(app, @SalinitypptorPSS78EditFieldValueChanged, true);
            app.TemperatureCEditField = uieditfield(app.InitialWaterChemistryPanel, 'numeric');
            app.TemperatureCEditField.Limits = [-4 100];
            app.TemperatureCEditField.ValueDisplayFormat = '%11.2g';
            app.TemperatureCEditField.ValueChangedFcn = createCallbackFcn(app, @TemperatureCEditFieldValueChanged, true);
            app.OAirSatatDepthEditFieldLabel = uilabel(app.InitialWaterChemistryPanel);
            app.OAirSatatDepthEditFieldLabel.Text = 'O  (% Air Sat at Depth)';
            app.pCOinAirAtmEditFieldLabel = uilabel(app.InitialWaterChemistryPanel);
            app.pCOinAirAtmEditFieldLabel.Text = 'pCO  in Air (µAtm)';
            app.pCOAirSatatSfcEditFieldLabel = uilabel(app.InitialWaterChemistryPanel);
            app.pCOAirSatatSfcEditFieldLabel.Text = 'pCO  (% Air Sat at Sfc)';
            app.pCOAirSatatDepthEditFieldLabel = uilabel(app.InitialWaterChemistryPanel);
            app.pCOAirSatatDepthEditFieldLabel.Text = 'pCO  (%Air Sat at Depth)';
            app.K1K2ConstantsDropDownLabel = uilabel(app.InitialWaterChemistryPanel);
            app.K1K2ConstantsDropDownLabel.Text = 'K1, K2 Constants';
            app.KSODissociationConstantDropDownLabel = uilabel(app.InitialWaterChemistryPanel);
            app.KSODissociationConstantDropDownLabel.Text = 'KSO  Dissociation Constant';
            app.Label_32 = uilabel(app.InitialWaterChemistryPanel);
            app.Label_32.Text = '4';
            app.KHFDissociationConstantDropDownLabel = uilabel(app.InitialWaterChemistryPanel);
            app.KHFDissociationConstantDropDownLabel.Text = 'KHF Dissociation Constant';
            app.BorateSalinityDropDownLabel = uilabel(app.InitialWaterChemistryPanel);
            app.BorateSalinityDropDownLabel.Text = 'Borate:Salinity';
            app.CO2SYSInputPrametersDropDownLabel = uilabel(app.InitialWaterChemistryPanel);
            app.CO2SYSInputPrametersDropDownLabel.Text = 'CO2SYS Input Prameters';
            app.CO2SYSInputPrametersDropDown = uidropdown(app.InitialWaterChemistryPanel);
            app.CO2SYSInputPrametersDropDown.Items = {'Alk & Total DIC', 'Alk & pH', 'Alk & pCO2', 'Total DIC & pH', 'Total DIC & pCO2', 'pH & pCO2'};
            app.CO2SYSInputPrametersDropDown.ValueChangedFcn = createCallbackFcn(app, @CO2SYSInputPrametersDropDownValueChanged, true);
            app.CO2SYSInputPrametersDropDown.Value = 'Alk & pH';
            app.PlantProductivityandMetabolismPanel = uipanel(app.GridLayout);
            app.PlantProductivityandMetabolismPanel.Title = 'Plant Productivity and Metabolism';
            app.InstantaneousWholePlantPREditField_2Label = uilabel(app.PlantProductivityandMetabolismPanel);
            app.InstantaneousWholePlantPREditField_2Label.Text = 'Instantaneous Whole Plant P/R';
            app.DailyWholePlantPREditField_2Label = uilabel(app.PlantProductivityandMetabolismPanel);
            app.DailyWholePlantPREditField_2Label.Text = 'Daily Whole Plant P/R';
            app.delCEditFieldLabel = uilabel(app.PlantProductivityandMetabolismPanel);
            app.delCEditFieldLabel.Text = 'del   C';
            app.ELabel = uilabel(app.PlantProductivityandMetabolismPanel);
            app.ELabel.Text = 'E';
            app.Label_33 = uilabel(app.PlantProductivityandMetabolismPanel);
            app.Label_33.Text = '-2';
            app.Label_34 = uilabel(app.PlantProductivityandMetabolismPanel);
            app.Label_34.Text = '-1';
            app.Label_37 = uilabel(app.PlantProductivityandMetabolismPanel);
            app.Label_37.Text = '13';
            app.InstantaneousLeafPREditFieldLabel = uilabel(app.PlantProductivityandMetabolismPanel);
            app.InstantaneousLeafPREditFieldLabel.Text = 'Instantaneous Leaf P/R';
            app.ELabel_2 = uilabel(app.PlantProductivityandMetabolismPanel);
            app.ELabel_2.Text = 'E';
            app.SaveResultsofThisRunButton = uibutton(app.PlantProductivityandMetabolismPanel, 'push');
            app.SaveResultsofThisRunButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsofThisRunButtonPushed, true);
            app.SaveResultsofThisRunButton.Text = 'Save Results of This Run';
            app.delCEditField = uieditfield(app.PlantProductivityandMetabolismPanel, 'numeric');
            app.delCEditField.ValueDisplayFormat = '%11.2f';
            app.delCEditField.ValueChangedFcn = createCallbackFcn(app, @delCEditFieldValueChanged, true);
            app.delCEditField.Editable = 'off';
            app.DailyWholePlantPREditField = uieditfield(app.PlantProductivityandMetabolismPanel, 'numeric');
            app.DailyWholePlantPREditField.ValueChangedFcn = createCallbackFcn(app, @DailyWholePlantPREditFieldValueChanged, true);
            app.DailyWholePlantPREditField.Editable = 'off';
            app.InstantaneousWholePlantPREditField = uieditfield(app.PlantProductivityandMetabolismPanel, 'numeric');
            app.InstantaneousWholePlantPREditField.ValueChangedFcn = createCallbackFcn(app, @InstantaneousWholePlantPREditFieldValueChanged, true);
            app.InstantaneousWholePlantPREditField.Editable = 'off';
            app.InstantaneousLeafPREditField = uieditfield(app.PlantProductivityandMetabolismPanel, 'numeric');
            app.InstantaneousLeafPREditField.ValueChangedFcn = createCallbackFcn(app, @InstantaneousLeafPREditFieldValueChanged, true);
            app.InstantaneousLeafPREditField.Editable = 'off';
            app.InstantaneousCanopyPPEditField = uieditfield(app.PlantProductivityandMetabolismPanel, 'numeric');
            app.InstantaneousCanopyPPEditField.ValueChangedFcn = createCallbackFcn(app, @InstantaneousCanopyPPEditFieldValueChanged, true);
            app.InstantaneousCanopyPPEditField.Editable = 'off';
            app.InstantaneousPumolCshootsLabel = uilabel(app.PlantProductivityandMetabolismPanel);
            app.InstantaneousPumolCshootsLabel.Text = 'Instantaneous P (umol C shoot  s )';
            app.InstantaneousPumolCshootsEditField = uieditfield(app.PlantProductivityandMetabolismPanel, 'numeric');
            app.InstantaneousPumolCshootsEditField.Editable = 'off';
            app.InstantaneousCanopyPPEditFieldLabel = uilabel(app.PlantProductivityandMetabolismPanel);
            app.InstantaneousCanopyPPEditFieldLabel.Text = 'Instantaneous Canopy P/P';
            app.InstantaneousPumolCmleafsEditFieldLabel = uilabel(app.PlantProductivityandMetabolismPanel);
            app.InstantaneousPumolCmleafsEditFieldLabel.Text = 'Instantaneous P (umol C m  leaf  s )';
            app.InstantaneousPumolCmleafsEditField = uieditfield(app.PlantProductivityandMetabolismPanel, 'numeric');
            app.InstantaneousPumolCmleafsEditField.ValueChangedFcn = createCallbackFcn(app, @InstantaneousPumolCmleafsEditFieldValueChanged2, true);
            app.InstantaneousPumolCmleafsEditField.Editable = 'off';
            app.PumolCmleafsLabel = uilabel(app.PlantProductivityandMetabolismPanel);
            app.PumolCmleafsLabel.Text = 'P  (umol C m   leaf  s  )';
            app.PumolCmleafsEditField = uieditfield(app.PlantProductivityandMetabolismPanel, 'numeric');
            app.PumolCmleafsEditField.ValueDisplayFormat = '%11.2g';
            app.PumolCmleafsEditField.ValueChangedFcn = createCallbackFcn(app, @PumolCmleafsEditFieldValueChanged, true);
            app.PumolCmleafsEditField.Editable = 'off';
            app.Label_42 = uilabel(app.PlantProductivityandMetabolismPanel);
            app.Label_42.Text = '-2';
            app.Label_43 = uilabel(app.PlantProductivityandMetabolismPanel);
            app.Label_43.Text = '-1';
            app.Label_45 = uilabel(app.PlantProductivityandMetabolismPanel);
            app.Label_45.Text = '-1';
            app.DailyPhotoperiodhEditFieldLabel = uilabel(app.PlantProductivityandMetabolismPanel);
            app.DailyPhotoperiodhEditFieldLabel.Text = 'Daily Photoperiod (h)';
            app.DailyPhotoperiodhEditField = uieditfield(app.PlantProductivityandMetabolismPanel, 'numeric');
            app.DailyPhotoperiodhEditField.Limits = [0 24];
            app.DailyPhotoperiodhEditField.ValueChangedFcn = createCallbackFcn(app, @DailyPhotoperiodhEditFieldValueChanged, true);
            app.DailyPhotoperiodhEditField.Editable = 'off';
            app.CanopyAnglefromverticalEditFieldLabel = uilabel(app.PlantProductivityandMetabolismPanel);
            app.CanopyAnglefromverticalEditFieldLabel.Text = 'Canopy Angle (° from vertical)';
            app.CanopyAnglefromverticalEditField = uieditfield(app.PlantProductivityandMetabolismPanel, 'numeric');
            app.CanopyAnglefromverticalEditField.ValueChangedFcn = createCallbackFcn(app, @CanopyAnglefromverticalEditFieldValueChanged, true);
            app.CanopyAnglefromverticalEditField.Editable = 'off';
            app.DailyHRequirementhdEditFieldLabel = uilabel(app.PlantProductivityandMetabolismPanel);
            app.DailyHRequirementhdEditFieldLabel.Text = 'Daily H     Requirement (h d  )';
            app.DailyHRequirementhdEditField = uieditfield(app.PlantProductivityandMetabolismPanel, 'numeric');
            app.DailyHRequirementhdEditField.Limits = [0 Inf];
            app.DailyHRequirementhdEditField.ValueChangedFcn = createCallbackFcn(app, @DailyHRequirementhdEditFieldValueChanged, true);
            app.DailyHRequirementhdEditField.Editable = 'off';
            app.Label_46 = uilabel(app.PlantProductivityandMetabolismPanel);
            app.satLabel = uilabel(app.PlantProductivityandMetabolismPanel);
            app.satLabel.Text = 'sat';
            app.Label_47 = uilabel(app.PlantProductivityandMetabolismPanel);
            app.Label_47.Text = '-1';
            app.PPEditFieldLabel = uilabel(app.PlantProductivityandMetabolismPanel);
            app.PPEditFieldLabel.Text = 'P  /P  ';
            app.PPEditField = uieditfield(app.PlantProductivityandMetabolismPanel, 'numeric');
            app.PPEditField.ValueChangedFcn = createCallbackFcn(app, @PPEditFieldValueChanged, true);
            app.PPEditField.Editable = 'off';
            app.ELabel_3 = uilabel(app.PlantProductivityandMetabolismPanel);
            app.ELabel_3.Text = 'E';
            app.mLabel_2 = uilabel(app.PlantProductivityandMetabolismPanel);
            app.mLabel_2.Text = 'm';
            app.PumolCmleafsEditField_2Label = uilabel(app.PlantProductivityandMetabolismPanel);
            app.PumolCmleafsEditField_2Label.Text = 'P  (umol C m  leaf  s  )';
            app.PumolCmleafsEditField_2 = uieditfield(app.PlantProductivityandMetabolismPanel, 'numeric');
            app.PumolCmleafsEditField_2.Limits = [0 Inf];
            app.PumolCmleafsEditField_2.ValueChangedFcn = createCallbackFcn(app, @PumolCmleafsEditField_2ValueChanged, true);
            app.PumolCmleafsEditField_2.Editable = 'off';
            app.Label_48 = uilabel(app.PlantProductivityandMetabolismPanel);
            app.Label_48.Text = '-1';
            app.mLabel_3 = uilabel(app.PlantProductivityandMetabolismPanel);
            app.mLabel_3.Text = 'm';
            app.Label_49 = uilabel(app.PlantProductivityandMetabolismPanel);
            app.Label_49.Text = '-2';
            app.SiteDataPanel = uipanel(app.GridLayout);
            app.SiteDataPanel.Title = 'Site Data';
            app.LatitudedecLabel = uilabel(app.SiteDataPanel);
            app.LatitudedecLabel.Text = 'Latitude (dec °)';
            app.LongitudedecEditFieldLabel = uilabel(app.SiteDataPanel);
            app.LongitudedecEditFieldLabel.Text = 'Longitude (dec °)';
            app.TimeofDay0to24hEditFieldLabel = uilabel(app.SiteDataPanel);
            app.TimeofDay0to24hEditFieldLabel.Text = 'Time of Day (0 to 24 h)';
            app.DepthmEditFieldLabel = uilabel(app.SiteDataPanel);
            app.DepthmEditFieldLabel.Text = 'Depth (m)';
            app.CurrentSpeedcmsEditFieldLabel = uilabel(app.SiteDataPanel);
            app.CurrentSpeedcmsEditFieldLabel.Text = 'Current Speed (cm s  )';
            app.SubstrateTypeDropDownLabel = uilabel(app.SiteDataPanel);
            app.SubstrateTypeDropDownLabel.Text = 'Substrate Type';
            app.DateDatePickerLabel = uilabel(app.SiteDataPanel);
            app.DateDatePickerLabel.Text = 'Date';
            app.Label_4 = uilabel(app.SiteDataPanel);
            app.Label_4.Text = '-1';
            app.SubstrateTypeDropDown = uidropdown(app.SiteDataPanel);
            app.SubstrateTypeDropDown.Items = {'Bright Carbonate', 'Dark Carbonate', 'Silica Sand', 'Silica Mud'};
            app.SubstrateTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SubstrateTypeDropDownValueChanged, true);
            app.SubstrateTypeDropDown.Value = 'Silica Sand';
            app.CurrentSpeedcmsEditField = uieditfield(app.SiteDataPanel, 'numeric');
            app.CurrentSpeedcmsEditField.Limits = [0 1000];
            app.CurrentSpeedcmsEditField.ValueChangedFcn = createCallbackFcn(app, @CurrentSpeedcmsEditFieldValueChanged, true);
            app.DepthmEditField = uieditfield(app.SiteDataPanel, 'numeric');
            app.DepthmEditField.Limits = [0.1 Inf];
            app.DepthmEditField.ValueChangedFcn = createCallbackFcn(app, @DepthmEditFieldValueChanged, true);
            app.DepthmEditField.Value = 0.1;
            app.TimeofDay0to24hEditField = uieditfield(app.SiteDataPanel, 'numeric');
            app.TimeofDay0to24hEditField.Limits = [0 24];
            app.TimeofDay0to24hEditField.ValueChangedFcn = createCallbackFcn(app, @TimeofDay0to24hEditFieldValueChanged, true);
            app.DateDatePicker = uidatepicker(app.SiteDataPanel);
            app.DateDatePicker.ValueChangedFcn = createCallbackFcn(app, @DateDatePickerValueChanged, true);
            app.LongitudedecEditField = uieditfield(app.SiteDataPanel, 'numeric');
            app.LongitudedecEditField.Limits = [-180 180];
            app.LongitudedecEditField.ValueChangedFcn = createCallbackFcn(app, @LongitudedecEditFieldValueChanged, true);
            app.LatitudedecEditField = uieditfield(app.SiteDataPanel, 'numeric');
            app.LatitudedecEditField.Limits = [-90 90];
            app.LatitudedecEditField.ValueChangedFcn = createCallbackFcn(app, @LatitudedecEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.GrassLight_3_11.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GL3_11_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GrassLight_3_11)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GrassLight_3_11)
        end
    end
end